classdef opto_analyser_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        opto_analyserUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImageIOLabel                    matlab.ui.control.Label
        NoofintervalsLabel              matlab.ui.control.Label
        ConfirmSelectionLabel           matlab.ui.control.Label
        ErrorMessagesLabel              matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        IntervalsselectedLabel          matlab.ui.control.Label
        LoadReleaseDataButton           matlab.ui.control.Button
        LoadRecoveryDataButton          matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        RESETButton                     matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ChannelselectionTab             matlab.ui.container.Tab
        Channel1Label                   matlab.ui.control.Label
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        Channel1ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_5                    matlab.ui.control.RadioButton
        MitochondriaButton              matlab.ui.control.RadioButton
        CytoplasmButton                 matlab.ui.control.RadioButton
        NucleusButton                   matlab.ui.control.RadioButton
        Channel2ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_6                    matlab.ui.control.RadioButton
        MitochondriaButton_2            matlab.ui.control.RadioButton
        CytoplasmButton_2               matlab.ui.control.RadioButton
        NucleusButton_2                 matlab.ui.control.RadioButton
        Channel3ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_7                    matlab.ui.control.RadioButton
        MitochondriaButton_3            matlab.ui.control.RadioButton
        CytoplasmButton_3               matlab.ui.control.RadioButton
        NucleusButton_3                 matlab.ui.control.RadioButton
        Channel4ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_8                    matlab.ui.control.RadioButton
        MitochondriaButton_4            matlab.ui.control.RadioButton
        CytoplasmButton_4               matlab.ui.control.RadioButton
        NucleusButton_4                 matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        Channel2Label                   matlab.ui.control.Label
        Channel3Label                   matlab.ui.control.Label
        Channel4Label                   matlab.ui.control.Label
        AcceptChannelsandCropImageButton  matlab.ui.control.Button
        AcceptCropButton                matlab.ui.control.Button
        CompartmentviewingpercentilesTab  matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        PercentageSliderLabel           matlab.ui.control.Label
        PercentageSlider                matlab.ui.control.Slider
        PercentageSlider_2Label         matlab.ui.control.Label
        PercentageSlider_2              matlab.ui.control.Slider
        NucleusLabel                    matlab.ui.control.Label
        MitochondriaLabel               matlab.ui.control.Label
        FramewindowSliderLabel          matlab.ui.control.Label
        FramewindowSlider               matlab.ui.control.Slider
        FramewindowSlider_2Label        matlab.ui.control.Label
        FramewindowSlider_2             matlab.ui.control.Slider
        UIAxes7_3                       matlab.ui.control.UIAxes
        PercentageSlider_3Label         matlab.ui.control.Label
        PercentageSlider_3              matlab.ui.control.Slider
        FramewindowSlider_3Label        matlab.ui.control.Label
        FramewindowSlider_3             matlab.ui.control.Slider
        AllcompartmentsLabel            matlab.ui.control.Label
        LoadNextIterationButton         matlab.ui.control.Button
        AcceptPercentilesButton         matlab.ui.control.Button
        CellboundarycropTab             matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes6_3                       matlab.ui.control.UIAxes
        ImageSelectionButtonGroup       matlab.ui.container.ButtonGroup
        NullButton_2                    matlab.ui.control.ToggleButton
        Image1Button                    matlab.ui.control.ToggleButton
        Image2Button                    matlab.ui.control.ToggleButton
        Image3Button                    matlab.ui.control.ToggleButton
        Image4Button                    matlab.ui.control.ToggleButton
        UIAxes6_4                       matlab.ui.control.UIAxes
        AllframesLabel                  matlab.ui.control.Label
        RegionframesLabel               matlab.ui.control.Label
        MaxintensityallchannelsLabel    matlab.ui.control.Label
        MaxintensityallchannelsLabel_2  matlab.ui.control.Label
        MaxintensityproteinchannelLabel  matlab.ui.control.Label
        MaxintensityproteinchannelLabel_2  matlab.ui.control.Label
        RoughCropButton                 matlab.ui.control.Button
        ConfirmRoughCropButton          matlab.ui.control.Button
        CellthresholdingTab             matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes8_3                       matlab.ui.control.UIAxes
        Thresholdscale01SliderLabel     matlab.ui.control.Label
        Thresholdscale01Slider          matlab.ui.control.Slider
        Thresholdscale001SliderLabel    matlab.ui.control.Label
        Thresholdscale001Slider         matlab.ui.control.Slider
        Thresholdscale0001SliderLabel   matlab.ui.control.Label
        Thresholdscale0001Slider        matlab.ui.control.Slider
        Thresholdscale00001SliderLabel  matlab.ui.control.Label
        Thresholdscale00001Slider       matlab.ui.control.Slider
        Thresholdvalue000000Label       matlab.ui.control.Label
        AcceptthresholdButton           matlab.ui.control.Button
        CompartmentthresholdingTab      matlab.ui.container.Tab
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes10_2                      matlab.ui.control.UIAxes
        UIAxes10_3                      matlab.ui.control.UIAxes
        UIAxes10_4                      matlab.ui.control.UIAxes
        UIAxes10_5                      matlab.ui.control.UIAxes
        UIAxes10_6                      matlab.ui.control.UIAxes
        Mitochondriathresholdscale01SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale01Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale0001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale0001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale00001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale00001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale01SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale01Slider   matlab.ui.control.Slider
        Nucleusthresholdscale001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale0001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale0001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale00001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale00001Slider  matlab.ui.control.Slider
        Mitochondrialthresholdvalue000000Label  matlab.ui.control.Label
        AcceptthresholdsButton          matlab.ui.control.Button
        InterpolationTab                matlab.ui.container.Tab
        InterpolatedOutputLabel         matlab.ui.control.Label
        UIAxes11_5                      matlab.ui.control.UIAxes
        UIAxes11_6                      matlab.ui.control.UIAxes
        UIAxes11_7                      matlab.ui.control.UIAxes
        UIAxes11_8                      matlab.ui.control.UIAxes
        SavedataandquitButton           matlab.ui.control.Button
        ProceedtonormalisationButton    matlab.ui.control.Button
        BackgroundsubtractionTab        matlab.ui.container.Tab
        UIAxes12                        matlab.ui.control.UIAxes
        ProteinchannelLabel             matlab.ui.control.Label
        FramenumberSliderLabel          matlab.ui.control.Label
        FramenumberSlider               matlab.ui.control.Slider
        SelectBackgroundRegionButton    matlab.ui.control.Button
        AcceptBackgroundRegionButton    matlab.ui.control.Button
        NormalisationregionsTab         matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes13_2                      matlab.ui.control.UIAxes
        ReleasestartLabel               matlab.ui.control.Label
        ReleasestartSlider              matlab.ui.control.Slider
        ReleaseendLabel                 matlab.ui.control.Label
        ReleaseendSlider                matlab.ui.control.Slider
        RecoverystartLabel              matlab.ui.control.Label
        RecoverystartSlider             matlab.ui.control.Slider
        RecoveryendLabel                matlab.ui.control.Label
        RecoveryendSlider               matlab.ui.control.Slider
        AcceptNormalisationRegionsButton  matlab.ui.control.Button
        NumberofwindowsLabel            matlab.ui.control.Label
        NumberofwindowsSlider           matlab.ui.control.Slider
        UIAxes13_3                      matlab.ui.control.UIAxes
        TimeandSSinputTab               matlab.ui.container.Tab
        UIAxes14                        matlab.ui.control.UIAxes
        SteadystateendframeSpinnerLabel  matlab.ui.control.Label
        SteadystateendframeSpinner      matlab.ui.control.Spinner
        TimeinformationButtonGroup      matlab.ui.container.ButtonGroup
        UseframesastimeButton           matlab.ui.control.ToggleButton
        LoadtimedataButton              matlab.ui.control.ToggleButton
        ConfirmButton                   matlab.ui.control.Button
        UIAxes14_2                      matlab.ui.control.UIAxes
        NumberofwindowsSlider_2Label    matlab.ui.control.Label
        NumberofwindowsSlider_2         matlab.ui.control.Slider
        NormalisationplottingTab        matlab.ui.container.Tab
        UIAxes15                        matlab.ui.control.UIAxes
        UIAxes15_2                      matlab.ui.control.UIAxes
        UIAxes15_3                      matlab.ui.control.UIAxes
        UIAxes15_4                      matlab.ui.control.UIAxes
        NormalisationButtonGroup_3      matlab.ui.container.ButtonGroup
        SinglelinearButton              matlab.ui.control.RadioButton
        DoublelinearButton              matlab.ui.control.RadioButton
        NumberofwindowsSlider_3Label    matlab.ui.control.Label
        NumberofwindowsSlider_3         matlab.ui.control.Slider
        AdjustforinflowoutflowButton    matlab.ui.control.Button
        SkipinflowoutflowProceedtomodelfitButton  matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PrenormalisationButton          matlab.ui.control.RadioButton
        PostnormalisationButton         matlab.ui.control.RadioButton
        SaveandquitButton_2             matlab.ui.control.Button
        InflowoutflowTab                matlab.ui.container.Tab
        NormalisationButtonGroup_2      matlab.ui.container.ButtonGroup
        SinglelinearButton_2            matlab.ui.control.RadioButton
        DoublelinearButton_2            matlab.ui.control.RadioButton
        NumberofwindowsSlider_4Label    matlab.ui.control.Label
        NumberofwindowsSlider_4         matlab.ui.control.Slider
        UIAxes16                        matlab.ui.control.UIAxes
        ReleaseendLabel_2               matlab.ui.control.Label
        ReleaseendSpinner               matlab.ui.control.Spinner
        RecoveryendSpinner_3Label       matlab.ui.control.Label
        RecoveryendSpinner_3            matlab.ui.control.Spinner
        SaveandquitButton               matlab.ui.control.Button
        IncludeinflowoutflowandproceedtomodelfittingButton  matlab.ui.control.Button
        ReleasesmoothingrangeLabel      matlab.ui.control.Label
        ReleasesmoothingrangeSpinner    matlab.ui.control.Spinner
        RecoverysmoothingrangeLabel     matlab.ui.control.Label
        RecoverysmoothingrangeSpinner   matlab.ui.control.Spinner
        IgnoreinflowoutflowandproceedtomodelfittingButton  matlab.ui.control.StateButton
        ReleasepaddingLabel             matlab.ui.control.Label
        ReleasepaddingSpinner           matlab.ui.control.Spinner
        RecoverypaddingSpinnerLabel     matlab.ui.control.Label
        RecoverypaddingSpinner          matlab.ui.control.Spinner
        PaddedmedianrangeSpinnerLabel   matlab.ui.control.Label
        PaddedmedianrangeSpinner        matlab.ui.control.Spinner
        ModelfittingTab                 matlab.ui.container.Tab
        IlluminatedreleaseEditFieldLabel  matlab.ui.control.Label
        IlluminatedreleaseEditField     matlab.ui.control.NumericEditField
        NonilluminatedreleaseEditFieldLabel  matlab.ui.control.Label
        NonilluminatedreleaseEditField  matlab.ui.control.NumericEditField
        RecoveryEditFieldLabel          matlab.ui.control.Label
        RecoveryEditField               matlab.ui.control.NumericEditField
        ImportEditFieldLabel            matlab.ui.control.Label
        ImportEditField                 matlab.ui.control.NumericEditField
        ExportEditFieldLabel            matlab.ui.control.Label
        ExportEditField                 matlab.ui.control.NumericEditField
        UITable2                        matlab.ui.control.Table
        FitmodelButton                  matlab.ui.control.Button
        SaveandquitButton_3             matlab.ui.control.Button
        ParameterguesstypeButtonGroup   matlab.ui.container.ButtonGroup
        GlobalguessesButton             matlab.ui.control.ToggleButton
        TableguessesButton              matlab.ui.control.ToggleButton
        ModelfittingoutputTab           matlab.ui.container.Tab
        UIAxes17                        matlab.ui.control.UIAxes
        UIAxes17_2                      matlab.ui.control.UIAxes
        UIAxes17_3                      matlab.ui.control.UIAxes
        NormalisationButtonGroup_4      matlab.ui.container.ButtonGroup
        SinglelinearButton_3            matlab.ui.control.RadioButton
        DoublelinearButton_3            matlab.ui.control.RadioButton
        InflowoutflowButtonGroup        matlab.ui.container.ButtonGroup
        ConservedButton                 matlab.ui.control.RadioButton
        NonconservedButton              matlab.ui.control.RadioButton
        NumberofwindowsLabel_2          matlab.ui.control.Label
        NumberofwindowsSlider_5         matlab.ui.control.Slider
        RightPanel                      matlab.ui.container.Panel
        OutputLabel                     matlab.ui.control.Label
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes11_2                      matlab.ui.control.UIAxes
        UIAxes11_3                      matlab.ui.control.UIAxes
        UIAxes11_4                      matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        compartment_data %Structure to store all data for all window-splits 
        
        %Release and recovery file input
        release_file
        release_file_path
        recovery_file
        recovery_file_path
        
        %Initial LSM input
        LSM_release % LSM release matrix 
        LSM_recovery %LSM recovery matrix
        total_channels %Total number of channels in the LSM
        LSM_vert_size %Vertical dimension of LSM frame
        LSM_horz_size %Horizontal dimension of LSM frame
        
        %Time info
        total_release_frames %Total number of frames in release
        total_recovery_frames %Total number of frames in recovery
        total_frames %Total number of frames 
        
        %Window info
        window_splits %Vector of user determined intervals (window splits)
        window_stage % Keeps track of which window split or interval is currently being analysed.
        window_counter %Keeps track of which window in the window split is currently being analysed.
        
        %Channel info
        nuclear_channel %Tracks which channel has the nuclear label
        mitochondrial_channel %Tracks which channel has the mitochondrial label
        protein_channel %Tracks which channel has the protein label
        
        %LSM protein data
        LSM_protein_release %All protein frames of release
        LSM_protein_recovery %All protein frames of recovery
        LSM_protein_all_frames %All protein frames of the whole movie
        
        %ROI adjusted variables
        LSM_protein_frame1 %Protein channel frame 1. Used for zoom ROI
        vertical_min_ROI %Minimum vertical value of ROI
        vertical_max_ROI %Maximum vertical value of ROI
        horizontal_min_ROI %Minimum horizontal value of ROI
        horizontal_max_ROI %Maximum horizontal value of ROI
        LSM_nucleus_release_ROI %Zoomed in nuclear frames for release
        LSM_protein_release_ROI %Zoomed in protein frames for release
        LSM_mitochondria_release_ROI %Zoomed in mitochondrial frames for release
        LSM_nuclear_recovery_ROI %Zoomed in nuclear frames for recovery
        LSM_protein_recovery_ROI %Zoomed in protein frames for release
        LSM_mitochondria_recovery_ROI %Zoomed in mitochondrial frames for release
        LSM_nuclear_movie_ROI % All frames for nuclear channel (release and recovery) at ROI
        LSM_protein_movie_ROI % All frames for protein channel (release and recovery) at ROI
        LSM_mitochondria_movie_ROI % All frames for mitochondrial channel (release and recovery) at ROI
        
        %Maximum intensity projections of ROI
        max_int_release_frames_ROI %Frame by frame maximum intensity projection of the three channels in the ROI during release
        max_int_recovery_frames_ROI %Frame by frame maximum intensity projection of the three channels in the ROI during recovery
        max_int_release_all_ROI %Maximum intensity of all frames in max_int_release_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of release.
        max_int_recovery_all_ROI %Maximum intensity of all frames in max_int_recovery_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of recovery.
        max_int_all_movie_ROI %Maximum intensity of max_int_release_all_ROI and max_int_recovery_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of the whole movie.
        max_int_protein_all_movie_ROI %Maximum intensity of LSM_protein_release_ROI and LSM_protein_recovery_ROI. I.e. this gives the maximum intensity all frames of the protein channel.
        max_int_all_frames_ROI %Max intensity over all channels for each frame in the movie

        
        %Window splitting
        window_start_points %Start point for each window in the window split for the next iteration
        window_mid_points %Mid points for each window in the window split for the next iteration
        window_max_nucleus_ROI %Maximum intensity projection of nuclear channel over all frames in that window
        window_max_mitochondria_ROI %Maximum intensity projection of mitochondrial channel over all frames in that window
        window_max_protein_ROI %Maximum intensity projection of protein channel over all frames in that window
        window_max_all_channels_ROI %Maximum intensity projection of all channels over all frames in that window
        
        %Percentile allocation
        whole_cell_percentile %Percentile of intensity for the hybrid channel of maximum intensity of protein, mitochondria and nucleus combined for the given window-split
        nuclear_percentile %Percentile of intensity for the nuclear channel for the given window-split 
        mitochondria_percentile %Percentile of intensity for the mitochondrial channel for the given window-split 
        whole_cell_window_percentile_projection %Percentile projection of all frames in each window of the whole cell. Produces an array with z dimension equal to number of windows in that window split
        nucleus_window_percentile_projection %Percentile projection of all frames in each window of the nucleus. Produces an array with z dimension equal to number of windows in that window split
        mitochondria_window_percentile_projection
        whole_cell_window_index %Index of window in window-split that is currently being displayed for whole cell image. Index used for image display purposes only.
        nuclear_window_index %Index of window in window-split that is currently being displayed for nuclear image. Index used for image display purposes only.
        mitochondria_window_index %Index of window in window-split that is currently being displayed for mitochondrial image. Index used for image display purposes only.

        %Binary arrays
        rough_cell_boundary_window % Rough cell boundary for each window of the window-split, generated via user cropping.
        whole_cell_binary_window %Thresholded binary image for the whole cell for each window
        mitochondria_binary_window %Thresholded binary image for the mitochondria for each window
        nucleus_binary_window %Thresholded binary image for the nucleus for each window
        
        %Threshold values
        whole_cell_threshold %Overall threshold for whole cell in that window
        whole_cell_threshold_0_1 %Threshold contribution for 0.1 level slider for whole cell in that window
        whole_cell_threshold_0_01 %Threshold contribution for 0.01 level slider for whole cell in that window
        whole_cell_threshold_0_001 %Threshold contribution for 0.001 level slider for whole cell in that window
        whole_cell_threshold_0_0001 %Threshold contribution for 0.0001 level slider for whole cell in that window
        mitochondria_threshold %Overall threshold for mitochondria in that window
        mitochondria_threshold_0_1 %Threshold contribution for 0.1 level slider for mitochondria in that window
        mitochondria_threshold_0_01 %Threshold contribution for 0.01 level slider for mitochondria in that window
        mitochondria_threshold_0_001 %Threshold contribution for 0.001 level slider for mitochondria in that window
        mitochondria_threshold_0_0001 %Threshold contribution for 0.0001 level slider for mitochondria in that window
        nucleus_threshold %Overall threshold for mitochondria in that window
        nucleus_threshold_0_1 %Threshold contribution for 0.1 level slider for nucleus in that window
        nucleus_threshold_0_01 %Threshold contribution for 0.01 level slider for nucleus in that window
        nucleus_threshold_0_001 %Threshold contribution for 0.001 level slider for nucleus in that window
        nucleus_threshold_0_0001 %Threshold contribution for 0.0001 level slider for nucleus in that window
        whole_cell_thresholds %All threshold levels for whole cell for each windowin current window-split
        mitochondria_thresholds %Mitochondria threshold levels for whole cell for each windowin current window-split
        nucleus_thresholds %Nucleus threshold levels for whole cell for each windowin current window-split
        
        %Compartment populations versus time
        population_cytoplasm %Cytoplasmic total intensity versus time for current window-split
        population_mitochondria %Mitochondrial total intensity versus time for current window-split
        population_nucleus %Nuclear total intensity versus time for current window-split
        population_total %Whole cell total intensity versus time for current window-split
        historical_population_cytoplasm %Cytoplasmic total intensity versus time for all previously completed window-splits
        historical_population_mitochondria %Mitochondrial total intensity versus time for all previously completed window-splits
        historical_population_nucleus %Nuclear total intensity versus time for all previously completed window-splits
        historical_population_total %Whole cell total intensity versus time for all previously completed window-splits
        
        %Segmentation Data table properties
        segmentation_table_input %Input data for table
        segmentation_table_background_colour %Store colourmap for each window in each window-split so row colours in table match plot colours
        
        %Interpolated data
        rough_cell_boundary_interpolated %Rough cell boundary interpolated over all frames from rough_cell_boundary_window
        whole_cell_thresholds_interp %Interpolated threshold levels for whole cell for every frame from whole_cell_thresholds
        mitochondria_thresholds_interp %Interpolated threshold levels for mitochondria for every frame from mitochondria_thresholds
        nucleus_thresholds_interp %Interpolated threshold levels for nucleus for every frame from nucleus_thresholds
        whole_cell_interpolated_region_bw %Interpolated frame by frame whole cell boundary
        nucleus_interpolated_region_bw %Interpolated frame by frame nucleus boundary
        mitochondria_interpolated_region_bw %Interpolated frame by frame mitochondria boundary
        
        
        %Interpolation of percentile intensity projections from windows to
        %all frames. Done by taking a moving-percentile of number of frames
        %equal to window length and the percentile the relevant percentile
        %for that channel for every frame in movie.
        whole_cell_windows_interp % Interpolated percentile intensity projections for every frame for the whole cell.  
        nucleus_windows_interp % Interpolated percentile intensity projections for every frame for the nucleus.
        mitochondria_windows_interp % Interpolated percentile intensity projections for every frame for the mitochondria.
        
        %Error coding
        app_stage % Only allows app functionality to be implemented in the correct order
        input_flag % flags whether files are both of lsm type (=1) or not (=0).
        error_counter %Keeps track of number of simultaneous errors
        cropping_stage %There are two cropping stages.
        % cropping_stage==1 corresponds to zooming into a ROI
        % cropping_stage==2 corresponds to rough cropping around the cell
        %cropping_stage == 3 corresponds to selecting a background region
        zoom_ROI_created_flag %Flag for creation of zoom ROI
        rough_cell_boundary_created %Flags whether the rough cell boundary has been created using the roipoly command
        whole_cell_threshold_slider_flag %Flags that the threshold slider for the whole cell has been adjusted by the user
        mitochondria_threshold_slider_flag %Flags that the threshold slider for the mitochondria has been adjusted by the user
        nucleus_threshold_slider_flag %Flags that the threshold slider for the nucleus has been adjusted by the user
        whole_cell_slider_percentile_flag %Flags that the percentile slider for the whole cell has been adjusted by the user
        nuclear_slider_percentile_flag %Flags that the percentile slider for the nucleus has been adjusted by the user
        mitochondria_slider_percentile_flag %Flags that the percentile slider for the mitochondria has been adjusted by the user
        background_ROI_created_flag %Flags whether the background region has been created
        release_start_flag %flag to say that normalisation slider has been moved
        release_end_flag %flag to say that normalisation slider has been moved
        recovery_start_flag %flag to say that normalisation slider has been moved
        recovery_end_flag %flag to say that normalisation slider has been moved
        
        
        %Normalisation
        background_location %Indices of background pixels
        background_mean % Mean value of background
        intensity_plot_window % The window-split selected for viewing intensities for bleach linear fitting
        release_linear_start % Start of linear change in total intensity in release phase
        release_linear_end % End of linear change in total intensity in release phase
        recovery_linear_start % Start of linear change in total intensity in recovery phase
        recovery_linear_end % End of linear change in total intensity in recovery phase
        intensity_plot_window_ss % The window-split selected for viewing intensities for ss fitting
        steady_state_end %Final frame of steady-state prior to release
        time %Store time data either as frames or loaded from spreadsheet
        normalisation_plot_windows %Output from given number of windows for normalisation
        smooth_release_end %Point at which non conserved release ends
        smooth_recovery_end %Point at which non conserved recovery ends
        release_smooth_range %Smoothing window using Loess for non-conserved release
        recovery_smooth_range %Smoothing window using Loess for non-conserved recovery
        release_padding %Number of frames of padding either side of smoothed release curve
        recovery_padding %Number of frames of padding either side of smoothed recovery curve
        padded_median_range %Number of time points that the average value will be calclated over for padding
        non_conserved_intensity_plot_windows %Output from given number of windows for non-conserved intensity smoothing
        nonconserved_outflow_time %time period where nonconserved outflow has been smoothed
        nonconserved_outflow_smooth %smoothed nonconserved outflow 
        nonconserved_inflow_time %time period where nonconserved inflow has been smoothed
        nonconserved_inflow_smooth  %smoothed nonconserved inflow
        nonconserved_inflow_outflow_flag % flag to say that nonconserved inflow/outflow has been analysed
        
       %Model Fitting Data table properties
        model_fitting_table_input %Input data for model fitting table
        model_fitting_table_background_colour %Colormap of model fitting success
        model_fitting_flag %Says whether model fitting has been carried out once. If so then it reads guess values from table
        k_rel_illum_guess %Guess vector for illuminated release in model fitting. Vector allows different guess for different run
        k_rel_ss_guess %Guess vector for steady state release in model fitting. Vector allows different guess for different run
        k_rec_guess %Guess vector for recovery in model fitting. Vector allows different guess for different run
        k_imp_guess %Guess vector for import in model fitting. Vector allows different guess for different run
        k_exp_guess %Guess vector for export in model fitting. Vector allows different guess for different run
        model_fitting_plot_windows %Output from given number of windows for model fitting
    end
    
    methods (Access = private)
        
        function stage_error_counter = stage_error(app,current_stage,required_stage,cropping_stage)
        
            stage_error_counter = 0;
            if cropping_stage == 1
                info_label = 'Error Messages';
                lsm_type_error = 'Error. Complete square crop in new image window.';
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';
            elseif cropping_stage == 2
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete rough crop around cell in ' newline 'new image window.'];
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';
            elseif cropping_stage == 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete background region selection' newline 'in new image window.'];
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';    
            else
                if current_stage == 1 && required_stage ~= 1
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete I/O correctly.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 2 && required_stage ~= 2
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete channel allocation and select' newline '"Accept Channels and Crop Image" in' newline 'Channel selection tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 3 && required_stage ~= 3
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Accept crop" in Channel selection tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 4 && required_stage ~= 4
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Load Next Iteration" in Compartment viewing percentiles tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
%                 elseif current_stage == 5 && required_stage ~= 5
%                     info_label = 'Error Messages';
%                     lsm_type_error = ['Error. Select desired percentiles for each channel' newline 'in Compartment viewing percentiles tab.'];
%                     stage_error_counter = 1;
%                     info_label = [info_label newline '(1)' lsm_type_error];
%                     app.ErrorMessagesLabel.Text = info_label;
%                     app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 5 && required_stage ~= 5
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Select best image and carry out rough' newline 'cropping in Cell boundary crop tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 6 && required_stage ~= 6
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Choose cell threshold and select "Accept'  newline 'threshold" in Cell thresholding tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 7 && required_stage ~= 7
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Choose mitochondria and nuclear' newline 'thresholds and select "Accept'  newline 'thresholds" in Compartment thresholding tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 8 && required_stage ~= 8
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Segmentation completed.' newline 'Either save and quit or proceed to' newline 'normalisation in Interpolation tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 9 && required_stage ~= 9
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. In background subtraction stage.' newline 'Select a background region' newline ' in Background subtraction tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 10 && required_stage ~= 10
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Select temporal regions to' newline 'determine passive bleaching rates' newline 'in Normalisation regions tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 11 && required_stage ~= 11
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Define steady-state window' newline 'and select time data in' newline 'in Time and SS input tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 12 && required_stage ~= 12
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. View normalisation output in' newline 'Normalisation plotting tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 13 && required_stage ~= 13
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete smoothing of' newline 'non-conserved inflow/outflow' newline ' in Inflow-outflow tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 14 && required_stage ~= 14
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete model fitting in' newline 'Model fitting tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';    
                end      
        
            end

        end


        %Plotting functions
        function plot_imagesc(app,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imagesc(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end
        
        function plot_imshow(app,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end

        function intensity_plotting(app)
            cmap = jet(length(app.window_splits));
                if app.app_stage == 7
                    plot_function1 ='h=plot(app.UIAxes11,';
                    plot_function2 ='h=plot(app.UIAxes11_2,';
                    plot_function3 ='h=plot(app.UIAxes11_3,';
                    plot_function4 ='h=plot(app.UIAxes11_4,';
                    colour_function = 'set(h, {''color''}, {';
                    for J =1:app.window_stage-1
                        plot_function1 = [plot_function1,'1:app.total_frames,app.historical_population_mitochondria(:,' num2str(J) '),'];
                        plot_function2 = [plot_function2,'1:app.total_frames,app.historical_population_cytoplasm(:,' num2str(J) '),'];
                        plot_function3 = [plot_function3,'1:app.total_frames,app.historical_population_nucleus(:,' num2str(J) '),'];
                        plot_function4 = [plot_function4,'1:app.total_frames,app.historical_population_total(:,' num2str(J) '),'];
                        colour_function=[colour_function,'cmap(',num2str(J),',:);'];
                    end
                    plot_function1 = [plot_function1,'1:app.total_frames,app.population_mitochondria,''LineWidth'',2)'];
                    plot_function2 = [plot_function2,'1:app.total_frames,app.population_cytoplasm,''LineWidth'',2)'];
                    plot_function3 = [plot_function3,'1:app.total_frames,app.population_nucleus,''LineWidth'',2)'];
                    plot_function4 = [plot_function4,'1:app.total_frames,app.population_total,''LineWidth'',2)'];
                    colour_function=[colour_function,'cmap(app.window_stage,:)})'];
                    
                    min_mito=nanmin(app.population_mitochondria);
                    max_mito=nanmax(app.population_mitochondria);
                    min_cyto=nanmin(app.population_cytoplasm);
                    max_cyto=nanmax(app.population_cytoplasm);
                    min_nuc=nanmin(app.population_nucleus);
                    max_nuc=nanmax(app.population_nucleus);
                    min_total=nanmin(app.population_total);
                    max_total=nanmax(app.population_total);
                    if  app.window_stage > 1
                        min_mito=min(nanmin(app.historical_population_mitochondria(:)),min_mito);
                        max_mito=max(nanmax(app.historical_population_mitochondria(:)),max_mito);
                        min_cyto=min(nanmin(app.historical_population_cytoplasm(:)),min_cyto);
                        max_cyto=max(nanmax(app.historical_population_cytoplasm(:)),max_cyto);
                        min_nuc=min(nanmin(app.historical_population_nucleus(:)),min_nuc);
                        max_nuc=max(nanmax(app.historical_population_nucleus(:)),max_nuc);
                        min_total=min(nanmin(app.historical_population_total(:)),min_total);
                        max_total=max(nanmax(app.historical_population_total(:)),max_total);
                    end
               else
                    plot_function1 ='h=plot(app.UIAxes11_5,';
                    plot_function2 ='h=plot(app.UIAxes11_6,';
                    plot_function3 ='h=plot(app.UIAxes11_7,';
                    plot_function4 ='h=plot(app.UIAxes11_8,';
                    colour_function = 'set(h, {''color''}, {';
                    for J =1:length(app.window_splits)
                        plot_function1 = [plot_function1,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_mitochondria_population,'];
                        plot_function2 = [plot_function2,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_cytoplasm_population,'];
                        plot_function3 = [plot_function3,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_nucleus_population,'];
                        plot_function4 = [plot_function4,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_total_population,'];
                        colour_function=[colour_function,'cmap(',num2str(J),',:);'];
                        if J == 1
                            min_mito = nanmin(app.compartment_data(J).interp_mitochondria_population);
                            max_mito = nanmax(app.compartment_data(J).interp_mitochondria_population);
                            min_cyto = nanmin(app.compartment_data(J).interp_cytoplasm_population);
                            max_cyto = nanmax(app.compartment_data(J).interp_cytoplasm_population);
                            min_nuc = nanmin(app.compartment_data(J).interp_nucleus_population);
                            max_nuc = nanmax(app.compartment_data(J).interp_nucleus_population);
                            min_total = nanmin(app.compartment_data(J).interp_total_population);
                            max_total = nanmax(app.compartment_data(J).interp_total_population);
                        else
                            min_mito=min(min_mito,nanmin(app.compartment_data(J).interp_mitochondria_population));
                            max_mito=max(max_mito,nanmax(app.compartment_data(J).interp_mitochondria_population));
                            min_cyto=min(min_cyto,nanmin(app.compartment_data(J).interp_cytoplasm_population));
                            max_cyto=max(max_cyto,nanmax(app.compartment_data(J).interp_cytoplasm_population));
                            min_nuc=min(min_nuc,nanmin(app.compartment_data(J).interp_nucleus_population));
                            max_nuc=max(max_nuc,nanmax(app.compartment_data(J).interp_nucleus_population));
                            min_total=min(min_total,nanmin(app.compartment_data(J).interp_total_population));
                            max_total=max(max_total,nanmax(app.compartment_data(J).interp_total_population));
                        end
                    end
                    plot_function1 = [plot_function1,'''LineWidth'',2)'];
                    plot_function2 = [plot_function2,'''LineWidth'',2)'];
                    plot_function3 = [plot_function3,'''LineWidth'',2)'];
                    plot_function4 = [plot_function4,'''LineWidth'',2)'];
                    colour_function=[colour_function,'})'];
                end
                eval(plot_function1);
                eval(colour_function);
                eval(plot_function2);
                eval(colour_function);
                eval(plot_function3);
                eval(colour_function);
                eval(plot_function4);
                eval(colour_function);
                
                if app.app_stage == 7
                    if min_mito < max_mito
                        ylim(app.UIAxes11,[min_mito max_mito]);
                    else
                        ylim(app.UIAxes11,[0 2]);
                    end
                    if min_cyto < max_cyto
                        ylim(app.UIAxes11_2,[min_cyto max_cyto]);
                    else
                        ylim(app.UIAxes11_2,[0 2]);
                    end
                    if min_nuc < max_nuc
                        ylim(app.UIAxes11_3,[min_nuc max_nuc]); 
                    else
                        ylim(app.UIAxes11_3,[0 2]);
                    end
                    if min_total < max_total
                        ylim(app.UIAxes11_4,[min_total max_total]); 
                    else
                        ylim(app.UIAxes11_4,[0 2]); 
                    end
                    
                else
                    if min_mito < max_mito
                        ylim(app.UIAxes11_5,[min_mito max_mito]);
                    else
                        ylim(app.UIAxes11_5,[0 2]);
                    end
                    if min_cyto < max_cyto
                        ylim(app.UIAxes11_6,[min_cyto max_cyto]);
                    else
                        ylim(app.UIAxes11_6,[0 2]);
                    end
                    if min_nuc < max_nuc
                        ylim(app.UIAxes11_7,[min_nuc max_nuc]); 
                    else
                        ylim(app.UIAxes11_7,[0 2]);
                    end
                    if min_total < max_total
                        ylim(app.UIAxes11_8,[min_total max_total]); 
                    else
                        ylim(app.UIAxes11_8,[0 2]); 
                    end
                end
                xlim(app.UIAxes11,[0 app.total_frames])
                xlim(app.UIAxes11_2,[0 app.total_frames])
                xlim(app.UIAxes11_3,[0 app.total_frames])
                xlim(app.UIAxes11_4,[0 app.total_frames])
                xlim(app.UIAxes11_5,[0 app.total_frames])
                xlim(app.UIAxes11_6,[0 app.total_frames])
                xlim(app.UIAxes11_7,[0 app.total_frames])
                xlim(app.UIAxes11_8,[0 app.total_frames])
                
                app.UIAxes11.XTickMode = 'auto';
                app.UIAxes11_2.XTickMode = 'auto';
                app.UIAxes11_3.XTickMode = 'auto';
                app.UIAxes11_4.XTickMode = 'auto';
                app.UIAxes11_5.XTickMode = 'auto';
                app.UIAxes11_6.XTickMode = 'auto';
                app.UIAxes11_7.XTickMode = 'auto';
                app.UIAxes11_8.XTickMode = 'auto';
        end
        
        function app = linear_bleach_region_plotting(app)
            delete( findobj(app.UIAxes13, 'type', 'line') )
            delete( findobj(app.UIAxes13_2, 'type', 'line') )
            delete( findobj(app.UIAxes13_3, 'type', 'line') )
            ValueArray = {'-','-',':',':'}';
            ColorArray = {[0,0,0],[0.5,0.5,0.5],[0,1,1],[1,0,1]}';
            app.release_linear_start = app.ReleasestartSlider.Value;
            app.release_linear_end = app.ReleaseendSlider.Value;
            app.recovery_linear_start = app.RecoverystartSlider.Value;
            app.recovery_linear_end = app.RecoveryendSlider.Value;
            linear_release_frames = app.release_linear_start:app.release_linear_end;
            linear_release_total = app.compartment_data(app.intensity_plot_window).interp_total_population(app.release_linear_start:app.release_linear_end);           
            linear_release_mito = app.compartment_data(app.intensity_plot_window).interp_mitochondria_population(app.release_linear_start:app.release_linear_end); 
            linear_release_cytoplasm = app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population(app.release_linear_start:app.release_linear_end);
            linear_recovery_frames = app.recovery_linear_start:app.recovery_linear_end;
            linear_recovery_total = app.compartment_data(app.intensity_plot_window).interp_total_population(app.recovery_linear_start:app.recovery_linear_end);           
            linear_recovery_mito = app.compartment_data(app.intensity_plot_window).interp_mitochondria_population(app.recovery_linear_start:app.recovery_linear_end); 
            linear_recovery_cytoplasm = app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population(app.recovery_linear_start:app.recovery_linear_end);
            h=plot(app.UIAxes13_3,1:app.total_frames,app.compartment_data(app.intensity_plot_window).interp_total_population,1:app.total_release_frames,app.compartment_data(app.intensity_plot_window).interp_total_population(1:app.total_release_frames),linear_release_frames,linear_release_total,linear_recovery_frames,linear_recovery_total,'LineWidth',2);
            set(h,{'color'},ColorArray);
            set(h,{'LineStyle'},ValueArray);           
            h=plot(app.UIAxes13,1:app.total_frames,app.compartment_data(app.intensity_plot_window).interp_mitochondria_population,1:app.total_release_frames,app.compartment_data(app.intensity_plot_window).interp_mitochondria_population(1:app.total_release_frames),linear_release_frames,linear_release_mito,linear_recovery_frames,linear_recovery_mito,'LineWidth',2);
            set(h,{'color'},ColorArray);
            set(h,{'LineStyle'},ValueArray);
            h=plot(app.UIAxes13_2,1:app.total_frames,app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population,1:app.total_release_frames,app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population(1:app.total_release_frames),linear_release_frames,linear_release_cytoplasm,linear_recovery_frames,linear_recovery_cytoplasm,'LineWidth',2);
            set(h,{'color'},ColorArray);
            set(h,{'LineStyle'},ValueArray);
        end
        
        function app = steady_state_end_plotting(app)
            app.intensity_plot_window_ss = round(app.NumberofwindowsSlider_2.Value);
            app.steady_state_end = app.SteadystateendframeSpinner.Value;
            app.NumberofwindowsSlider_2.Value = app.intensity_plot_window_ss;
            delete( findobj(app.UIAxes14, 'type', 'line') )
            delete( findobj(app.UIAxes14_2, 'type', 'line') )
            ValueArray = {'-','-'}';
            MarkerArray = {'none','.'}';
            ColorArray = {[0,0,0],[1,0,1]}';
            h1=plot(app.UIAxes14,1:app.total_frames,app.compartment_data(app.intensity_plot_window_ss).interp_mitochondria_population,1:app.steady_state_end,app.compartment_data(app.intensity_plot_window_ss).interp_mitochondria_population(1:app.steady_state_end),'LineWidth',2,'MarkerSize',15);
            set(h1,{'color'},ColorArray);
            set(h1,{'LineStyle'},ValueArray);
            h2=plot(app.UIAxes14_2,1:app.total_frames,app.compartment_data(app.intensity_plot_window_ss).interp_cytoplasm_population,1:app.steady_state_end,app.compartment_data(app.intensity_plot_window_ss).interp_cytoplasm_population(1:app.steady_state_end),'LineWidth',2,'MarkerSize',15);
            set(h2,{'color'},ColorArray);
            set(h2,{'LineStyle'},ValueArray);
            if app.steady_state_end > 1
                MarkerArray = {'none','none'}';
            end
            set(h1,{'Marker'},MarkerArray);
            set(h2,{'Marker'},MarkerArray);
            
        end

        function app = normalisation_plotting(app)
            delete( findobj(app.UIAxes15, 'type', 'line') )
            delete( findobj(app.UIAxes15_2, 'type', 'line') )
            delete( findobj(app.UIAxes15_3, 'type', 'line') )
            delete( findobj(app.UIAxes15_4, 'type', 'line') )
            if app.PrenormalisationButton.Value == true
                mito = app.compartment_data(app.normalisation_plot_windows).mitochondria_background_subtracted;
                cyto = app.compartment_data(app.normalisation_plot_windows).cytoplasm_background_subtracted;
                nuc = app.compartment_data(app.normalisation_plot_windows).nucleus_background_subtracted;
                total = app.compartment_data(app.normalisation_plot_windows).total_background_subtracted;
                if app.SinglelinearButton.Value == true%app.SinglelinearButton_2.Value == true
                    ColorArray = {[0,0,0],[1,0,1]}';
                    h=plot(app.UIAxes15_4,app.time,total,app.time,app.compartment_data(1).single_linear_fit_total,'LineWidth',2);
                elseif app.DoublelinearButton.Value == true
                    ColorArray = {[0,0,0],[0,1,1]}';
                    h=plot(app.UIAxes15_4,app.time,total,app.time,app.compartment_data(1).double_linear_fit_total,'LineWidth',2);
                end
                set(h,{'color'},ColorArray);
            elseif app.PostnormalisationButton.Value == true
                if app.SinglelinearButton.Value == true%app.SinglelinearButton_2.Value == true
                    mito = app.compartment_data(app.normalisation_plot_windows).mitochondria_norm_single_linear;
                    cyto = app.compartment_data(app.normalisation_plot_windows).cytoplasm_norm_single_linear;
                    nuc = app.compartment_data(app.normalisation_plot_windows).nucleus_norm_single_linear;
                    total = app.compartment_data(app.normalisation_plot_windows).total_norm_single_linear;
                    plot(app.UIAxes15_4,app.time,total,'k','LineWidth',2);
                elseif app.DoublelinearButton.Value == true
                    mito = app.compartment_data(app.normalisation_plot_windows).mitochondria_norm_double_linear;
                    cyto = app.compartment_data(app.normalisation_plot_windows).cytoplasm_norm_double_linear;
                    nuc = app.compartment_data(app.normalisation_plot_windows).nucleus_norm_double_linear;
                    total = app.compartment_data(app.normalisation_plot_windows).total_norm_double_linear;
                    plot(app.UIAxes15_4,app.time,total,'k','LineWidth',2);
                end                
            end
            min_mito = nanmin(mito);
            max_mito = nanmax(mito);
            min_cyto = nanmin(cyto);
            max_cyto = nanmax(cyto);
            min_nuc = nanmin(nuc);
            max_nuc = nanmax(nuc);
            min_total = nanmin(total);
            max_total = nanmax(total);
            ten_percent_buffer=0.1*(max_mito-min_mito);
            ylim(app.UIAxes15,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_cyto-min_cyto);
            ylim(app.UIAxes15_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_nuc-min_nuc);
            ylim(app.UIAxes15_3,[min_nuc-ten_percent_buffer max_nuc+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_total-min_total);
            ylim(app.UIAxes15_4,[min_total-ten_percent_buffer max_total+ten_percent_buffer]);
            xlim(app.UIAxes15,[min(app.time) max(app.time)]);
            xlim(app.UIAxes15_2,[min(app.time) max(app.time)]);
            xlim(app.UIAxes15_3,[min(app.time) max(app.time)]);
            xlim(app.UIAxes15_4,[min(app.time) max(app.time)]);
            plot(app.UIAxes15,app.time,mito,'k','LineWidth',2);
            plot(app.UIAxes15_2,app.time,cyto,'k','LineWidth',2);
            plot(app.UIAxes15_3,app.time,nuc,'k','LineWidth',2);            
        end
        
        function app = release_recovery_non_conserved_smoothing(app)
            app.padded_median_range = app.PaddedmedianrangeSpinner.Value;
            app.non_conserved_intensity_plot_windows = round(app.NumberofwindowsSlider_4.Value);
            app.NumberofwindowsSlider_4.Value = app.non_conserved_intensity_plot_windows;
            app.smooth_release_end = app.ReleaseendSpinner.Value;
            app.release_smooth_range = app.ReleasesmoothingrangeSpinner.Value;
            app.release_padding = app.ReleasepaddingSpinner.Value;
            app.smooth_recovery_end = app.RecoveryendSpinner_3.Value;
            app.recovery_smooth_range = app.RecoverysmoothingrangeSpinner.Value;
            app.recovery_padding = app.RecoverypaddingSpinner.Value;
            mean_recovery_time_interval = mean(diff(app.time(1:app.total_recovery_frames)));
            outflow_time=app.time(app.steady_state_end:app.smooth_release_end);
            inflow_time=app.time(app.total_release_frames:app.smooth_recovery_end);
            app.nonconserved_outflow_time = outflow_time;
            app.nonconserved_inflow_time = inflow_time;
           
            
            if app.release_padding > 0
                mean_release_time_interval = mean(diff(app.time(1:app.total_release_frames)));
                release_padding_start_time = outflow_time(1)-app.release_padding*mean_release_time_interval:mean_release_time_interval:outflow_time(1)-mean_release_time_interval;
                release_padding_end_time = outflow_time(end) + mean_release_time_interval:mean_release_time_interval:outflow_time(end)+app.release_padding*mean_release_time_interval;
                outflow_time = [release_padding_start_time, outflow_time, release_padding_end_time];
            end
            if app.recovery_padding > 0
                mean_recovery_time_interval = mean(diff(app.time(1:app.total_recovery_frames)));
                recovery_padding_start_time = inflow_time(1)-app.recovery_padding*mean_recovery_time_interval:mean_recovery_time_interval:inflow_time(1)-mean_recovery_time_interval;
                recovery_padding_end_time = inflow_time(end)+mean_recovery_time_interval:mean_recovery_time_interval:inflow_time(end)+mean_recovery_time_interval*app.recovery_padding;
                inflow_time = [recovery_padding_start_time, inflow_time, recovery_padding_end_time];
            end
            ColorArray = {[0,0,0],[1,0,1],[0,1,1]}';
            if app.SinglelinearButton_2.Value == true
                outflow=app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear(app.steady_state_end:app.smooth_release_end);
                inflow=app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear(app.total_release_frames:app.smooth_recovery_end);
                median_total_ss=median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear(1:app.steady_state_end));

                
                if app.release_padding == 0
                    outflow(1)=median_total_ss;
                else
                    outflow_end_median = median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear(app.smooth_release_end:min(app.smooth_release_end+app.padded_median_range - 1,app.total_frames)));
                    outflow = [zeros(1,app.release_padding)+median_total_ss, outflow, zeros(1,app.release_padding)+outflow_end_median];
                end
                if app.recovery_padding > 0
                    inflow_start_median = median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear(max(app.total_release_frames-app.padded_median_range+1,1):app.total_release_frames));
                    inflow_end_median = median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear(app.smooth_recovery_end:min(app.smooth_recovery_end+app.padded_median_range - 1,app.total_frames)));
                    inflow = [zeros(1,app.recovery_padding)+inflow_start_median, inflow, zeros(1,app.recovery_padding) + inflow_end_median];
                end
                smooth_outflow = smoothdata(outflow,'loess',app.release_smooth_range);
                smooth_inflow = smoothdata(inflow,'loess',app.recovery_smooth_range);
                app.nonconserved_outflow_smooth = smooth_outflow(app.release_padding+1:end-app.release_padding);
                app.nonconserved_inflow_smooth = smooth_inflow(app.recovery_padding+1:end-app.recovery_padding);
                
                h1=plot(app.UIAxes16,app.time,app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear,outflow_time,smooth_outflow,inflow_time,smooth_inflow,'LineWidth',2);
            else
                median_total_ss=median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_double_linear(1:app.steady_state_end));
                outflow=app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_double_linear(app.steady_state_end:app.smooth_release_end);
                inflow=app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_double_linear(app.total_release_frames:app.smooth_recovery_end);
                
                if app.release_padding == 0
                    outflow(1)=median_total_ss;
                else
                    outflow_end_median = median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_double_linear(app.smooth_release_end:min(app.smooth_release_end+app.padded_median_range - 1,app.total_frames)));
                    outflow = [zeros(1,app.release_padding)+median_total_ss, outflow, zeros(1,app.release_padding)+outflow_end_median];
                end
                if app.recovery_padding > 0
                    inflow_start_median = median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_double_linear(max(app.total_release_frames-app.padded_median_range+1,1):app.total_release_frames));
                    inflow_end_median = median(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_double_linear(app.smooth_recovery_end:min(app.smooth_recovery_end+app.padded_median_range - 1,app.total_frames)));
                    inflow = [zeros(1,app.recovery_padding)+inflow_start_median, inflow, zeros(1,app.recovery_padding) + inflow_end_median];
                end
                smooth_outflow = smoothdata(outflow,'loess',app.release_smooth_range);
                smooth_inflow = smoothdata(inflow,'loess',app.recovery_smooth_range);
                app.nonconserved_outflow_smooth = smooth_outflow(app.release_padding+1:end-app.release_padding);
                app.nonconserved_inflow_smooth = smooth_inflow(app.recovery_padding+1:end-app.recovery_padding);
                h1=plot(app.UIAxes16,app.time,app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_double_linear,outflow_time,smooth_outflow,inflow_time,smooth_inflow,'LineWidth',2);                
            end
            set(h1,{'color'},ColorArray);
            app = error_message_reset(app);
            if length(app.nonconserved_outflow_smooth) <5
                lsm_type_error = ['Warning! Smoothed release is' newline 'under five frames long. This may' newline 'increase noise in model fitting.'];
                app.error_counter = app.error_counter +1;
                app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.ErrorMessagesLabel.BackgroundColor = 'y';
            end
            if length(app.nonconserved_inflow_smooth) <5
                lsm_type_error = ['Warning! Smoothed recovery is' newline 'under five frames long. This may' newline 'increase noise in model fitting.'];
                app.error_counter = app.error_counter +1;
                app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.ErrorMessagesLabel.BackgroundColor = 'y';
            end
            if length(app.nonconserved_outflow_smooth) > 1
                p = polyfit(1:length(app.nonconserved_outflow_smooth),app.nonconserved_outflow_smooth,1)
                if p(1)>0
                    lsm_type_error = ['Warning! Smoothed release has' newline 'a positive gradient!' newline 'Recommend adjusting release' newline 'smoothing inputs.'];
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'y';
                end
                if max(diff(app.nonconserved_outflow_smooth)) > 0 && min(diff(app.nonconserved_outflow_smooth)) < 0
                    lsm_type_error = ['Warning! Smoothed release is' newline 'non-monotonic. It may affect' newline 'model fitting. Recommend adjusting' newline 'release smoothing inputs.'];
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'y';
                end
            end
            if length(app.nonconserved_inflow_smooth) > 1
                p = polyfit(1:length(app.nonconserved_inflow_smooth),app.nonconserved_inflow_smooth,1)
                if p(1)< 0
                    lsm_type_error = ['Warning! Smoothed recovery has' newline 'a negative gradient!' newline 'Recommend adjusting recovery' newline 'smoothing inputs.'];
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'y';
                end
                if max(diff(app.nonconserved_inflow_smooth)) > 0 && min(diff(app.nonconserved_inflow_smooth)) < 0
                    lsm_type_error = ['Warning! Smoothed recovery is' newline 'non-monotonic. It may affect' newline 'model fitting. Recommend adjusting' newline 'recovery smoothing inputs.'];
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'y';
                end
        end
            
            
        end
        
        
        function app = model_fitting_plotting(app)
            delete( findobj(app.UIAxes17, 'type', 'line') )
            delete( findobj(app.UIAxes17_2, 'type', 'line') )
            delete( findobj(app.UIAxes17_3, 'type', 'line') )
            app.model_fitting_plot_windows = round(app.NumberofwindowsSlider_5.Value);
            app.NumberofwindowsSlider_5.Value = app.model_fitting_plot_windows;
         
            %app.SinglelinearButton_3.Value
            %app.ConservedButton.Value
            %app.NumberofwindowsSlider_5
            %app.nonconserved_inflow_outflow_flag
            if app.SinglelinearButton_3.Value == true
                ColorArray = {[0,0,0],[1,0,1]}';
                mito_exp = app.compartment_data(app.model_fitting_plot_windows).mitochondria_norm_single_linear;
                cyto_exp = app.compartment_data(app.model_fitting_plot_windows).cytoplasm_norm_single_linear;
                nuc_exp = app.compartment_data(app.model_fitting_plot_windows).nucleus_norm_single_linear;
                if app.ConservedButton.Value == true
                    ValueArray = {'-','-'}';
                    time_model = app.compartment_data(app.model_fitting_plot_windows).single_conserved_model_fit_time;
                    mito_model = app.compartment_data(app.model_fitting_plot_windows).single_conserved_model_fit_mitochondria;
                    cyto_model = app.compartment_data(app.model_fitting_plot_windows).single_conserved_model_fit_cytoplasm;
                    nuc_model = app.compartment_data(app.model_fitting_plot_windows).single_conserved_model_fit_nucleus;                    
                else
                    ValueArray = {'-',':'}';
                    time_model = app.compartment_data(app.model_fitting_plot_windows).single_nonconserved_model_fit_time;
                    mito_model = app.compartment_data(app.model_fitting_plot_windows).single_nonconserved_model_fit_mitochondria;
                    cyto_model = app.compartment_data(app.model_fitting_plot_windows).single_nonconserved_model_fit_cytoplasm;
                    nuc_model = app.compartment_data(app.model_fitting_plot_windows).single_nonconserved_model_fit_nucleus; 
                end
            else
                ColorArray = {[0,0,0],[0,1,1]}';
                mito_exp = app.compartment_data(app.model_fitting_plot_windows).mitochondria_norm_double_linear;
                cyto_exp = app.compartment_data(app.model_fitting_plot_windows).cytoplasm_norm_double_linear;
                nuc_exp = app.compartment_data(app.model_fitting_plot_windows).nucleus_norm_double_linear;
                if app.ConservedButton.Value == true
                    ValueArray = {'-','-'}';
                    time_model = app.compartment_data(app.model_fitting_plot_windows).double_conserved_model_fit_time;
                    mito_model = app.compartment_data(app.model_fitting_plot_windows).double_conserved_model_fit_mitochondria;
                    cyto_model = app.compartment_data(app.model_fitting_plot_windows).double_conserved_model_fit_cytoplasm;
                    nuc_model = app.compartment_data(app.model_fitting_plot_windows).double_conserved_model_fit_nucleus;                    
                else
                    ValueArray = {'-',':'}';
                    time_model = app.compartment_data(app.model_fitting_plot_windows).double_nonconserved_model_fit_time;
                    mito_model = app.compartment_data(app.model_fitting_plot_windows).double_nonconserved_model_fit_mitochondria;
                    cyto_model = app.compartment_data(app.model_fitting_plot_windows).double_nonconserved_model_fit_cytoplasm;
                    nuc_model = app.compartment_data(app.model_fitting_plot_windows).double_nonconserved_model_fit_nucleus; 
                end
            end
            
            min_mito = min(nanmin(mito_exp),nanmin(mito_model));
            max_mito = max(nanmax(mito_exp),nanmax(mito_model));
            min_cyto = min(nanmin(cyto_exp),nanmin(cyto_model));
            max_cyto = max(nanmax(cyto_exp),nanmax(cyto_model));
            min_nuc = min(nanmin(nuc_exp),nanmin(nuc_model));
            max_nuc = max(nanmax(nuc_exp),nanmax(nuc_model));
            ten_percent_buffer=0.1*(max_mito-min_mito);
            ylim(app.UIAxes17,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_cyto-min_cyto);
            ylim(app.UIAxes17_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_nuc-min_nuc);
            ylim(app.UIAxes17_3,[min_nuc-ten_percent_buffer max_nuc+ten_percent_buffer]);
            xlim(app.UIAxes17,[min(app.time) max(app.time)]);
            xlim(app.UIAxes17_2,[min(app.time) max(app.time)]);
            xlim(app.UIAxes17_3,[min(app.time) max(app.time)]);
            h1=plot(app.UIAxes17,app.time,mito_exp,time_model,mito_model,'LineWidth',2);
            h2=plot(app.UIAxes17_2,app.time,cyto_exp,time_model,cyto_model,'LineWidth',2);
            h3=plot(app.UIAxes17_3,app.time,nuc_exp,time_model,nuc_model,'LineWidth',2); 
            set(h1,{'color'},ColorArray);
            set(h1,{'LineStyle'},ValueArray);
            set(h2,{'color'},ColorArray);
            set(h2,{'LineStyle'},ValueArray);
            set(h3,{'color'},ColorArray);
            set(h3,{'LineStyle'},ValueArray);
        end


        %Thresholding functions
        
        
%         function output_cell_bw = cell_threshold_sub_function(image_input,image_threshold_input,rough_cell_input)
%            output_cell_bw=imbinarize(uint16(image_input),image_threshold_input);
%            output_cell_bw = output_cell_bw.*rough_cell_input;
%            output_cell_bw=imfill(output_cell_bw,'holes');
%            SE = strel('disk', 1, 0);
%            output_cell_bw=imopen(output_cell_bw,SE);
%            L = bwlabel(output_cell_bw);
%            LNo=length(unique(L(:)))-1;
%            if(LNo>0)
%                clear SizeI
%                SizeI=zeros(1,LNo);
%                for J=1:LNo
%                    index=find(L==J);
%                    SizeI(J)=length(index);
%                end
%                output_cell_bw=bwareaopen(output_cell_bw,max(SizeI),8);
%            end
%         end
%         
%         function output_mitochondria_bw = mitochondria_threshold_sub_function(image_input,image_threshold_input,cell_boundary_input)
%             output_mitochondria_bw = imbinarize(uint16(image_input),image_threshold_input);
%             output_mitochondria_bw=cell_boundary_input.*output_mitochondria_bw;
%         end
%         
%         function output_nucleus_bw = nucleus_threshold_sub_function(image_input,image_threshold_input,cell_boundary_input,mito_boundary_input)
%             output_nucleus_bw = imbinarize(uint16(image_input),image_threshold_input);
%             nuc_cell_interval_image_bw=cell_boundary_input.*output_nucleus_bw;
%             output_nucleus_bw=imfill(output_nucleus_bw,'holes');
%             L = bwlabel(output_nucleus_bw);
%             LNo=length(unique(L(:)))-1;
%             if LNo>0
%                 nuc_object_area = zeros(1,LNo);
%                 for J=1:LNo
%                     index_area=find(L==J);
%                     nuc_object_area(J)=length(index_area);
%                 end
%                 output_nucleus_bw=bwareaopen(output_nucleus_bw,max(nuc_object_area),8);
%             end
%             overlap_bw=output_nucleus_bw.*mito_boundary_input;
%             output_nucleus_bw(overlap_bw==1)=0;
%             L = bwlabel(output_nucleus_bw);
%             LNo=length(unique(L(:)))-1;
%             if(LNo>0)
%                 nuc_object_area = zeros(1,LNo);
%                 for J=1:LNo
%                     index=find(L==J);
%                     nuc_object_area(J)=length(index);
%                 end
%                 output_nucleus_bw=bwareaopen(output_nucleus_bw,max(nuc_object_area),8);
%             end
%             
%         end
        
        
        function [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app,whole_cell_interval_image_bw)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.whole_cell_window_percentile_projection(:,:,app.window_counter);
            
            %whole_cell_interval_image_bw = cell_threshold_sub_function(image_input,app.whole_cell_threshold,app.rough_cell_boundary_window(:,:,app.window_counter));
            
            whole_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
            whole_cell_interval_image_bw = whole_cell_interval_image_bw.*app.rough_cell_boundary_window(:,:,app.window_counter);
            whole_cell_interval_image_bw=imfill(whole_cell_interval_image_bw,'holes');
            SE = strel('disk', 1, 0);
            whole_cell_interval_image_bw=imopen(whole_cell_interval_image_bw,SE);
            L = bwlabel(whole_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                clear SizeI
                SizeI=zeros(1,LNo);
                for J=1:LNo
                    index=find(L==J);
                    SizeI(J)=length(index);
                end
                whole_cell_interval_image_bw=bwareaopen(whole_cell_interval_image_bw,max(SizeI),8);
            end
            app.whole_cell_binary_window(:,:,app.window_counter)=whole_cell_interval_image_bw;
            whole_cell_interval_image_bw_skel=bwmorph(whole_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(whole_cell_interval_image_bw_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
        end


        function [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.mitochondria_window_percentile_projection(:,:,app.window_counter);
            
%            mito_cell_interval_image_bw = mitochondria_threshold_sub_function(image_input,app.mitochondria_threshold,app.whole_cell_binary_window(:,:,app.window_counter));
            
            mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mitochondria_threshold);
            mito_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*mito_cell_interval_image_bw;
            app.mitochondria_binary_window(:,:,app.window_counter)=mito_cell_interval_image_bw;
            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
        end
        
        
        function [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.window_counter);
            
            %nuc_cell_interval_image_bw = nucleus_threshold_sub_function(image_input,app.nucleus_threshold,app.whole_cell_binary_window(:,:,app.window_counter),mito_cell_interval_image_bw);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nucleus_threshold);
            nuc_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(1,LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                nuc_object_area = zeros(1,LNo);
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nucleus_binary_window(:,:,app.window_counter)=nuc_cell_interval_image_bw;
            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;    
        end

        %Intensity extraction
        
        function app = population_intensity_extraction(app)
            if app.window_counter == app.window_splits(app.window_stage)
                current_frames = app.window_start_points(app.window_counter):app.total_frames;    
            else
                current_frames = app.window_start_points(app.window_counter):app.window_start_points(app.window_counter+1)-1;    
            end
        
            cell_object = app.whole_cell_binary_window(:,:,app.window_counter);
            nucleus_object = app.nucleus_binary_window(:,:,app.window_counter);
            mito_object = app.mitochondria_binary_window(:,:,app.window_counter);
            cytoplasm_object = max(cell_object - nucleus_object - mito_object, 0);
             
            for J = 1:length(current_frames)
                single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,current_frames(J));
                
                cytoplasm_population(current_frames(J)) = sum(single_protein_frame(cytoplasm_object > 0));
                mitochondria_population(current_frames(J)) = sum(single_protein_frame(mito_object > 0));
                nucleus_population(current_frames(J)) = sum(single_protein_frame(nucleus_object > 0));
            end
            app.population_cytoplasm(current_frames) = cytoplasm_population(current_frames);
            app.population_mitochondria(current_frames) = mitochondria_population(current_frames);
            app.population_nucleus(current_frames) = nucleus_population(current_frames);
            app.population_total(current_frames) = nucleus_population(current_frames) + mitochondria_population(current_frames) + cytoplasm_population(current_frames);
        end
        
        
        %Interpolation functions
        
        function app = interp_rough_region(app)
            if app.window_splits(app.window_stage)==1
                whole_region = app.rough_cell_boundary_window(:,:,1);
                app.rough_cell_boundary_interpolated = repmat(whole_region,[1,1,app.total_frames]);
            else
                first_region(:,:) = app.rough_cell_boundary_window(:,:,1);
                last_region(:,:) = app.rough_cell_boundary_window(:,:,end);
                start = repmat(first_region,[1,1,app.window_mid_points(1)]);
                finish = repmat(last_region,[1,1,app.total_frames-app.window_mid_points(end)+1]);
                interp_matrix = zeros([size(first_region),app.total_frames]);
                for I=1:length(app.window_mid_points)
                    interp_matrix(:,:,app.window_mid_points(I))=app.rough_cell_boundary_window(:,:,I);
                end
                for I=1:length(app.window_mid_points)-1
                   bw_r1(:,:)=app.rough_cell_boundary_window(:,:,I);
                   bw_r2(:,:)=app.rough_cell_boundary_window(:,:,I+1);
                   diff1=max(0,bw_r2-bw_r1);
                   diff2=max(0,bw_r1-bw_r2);
                   bw_dist_r1=bwdist(bw_r1);
                   bw_dist_r2=bwdist(bw_r2);
                   index1=find(diff1);
                   index2=find(diff2);
                   distance_r1=bw_dist_r1(index1);
                   distance_r1 = distance_r1/max(distance_r1);
                   distance_r1 = app.window_mid_points(I) + distance_r1*(app.window_mid_points(I+1)-app.window_mid_points(I));
                   distance_r1(distance_r1<app.window_mid_points(I)+1)=app.window_mid_points(I)+1;
                   distance_r1(distance_r1>app.window_mid_points(I+1)-1)=app.window_mid_points(I+1)-1;
                   distance_r1 = round(distance_r1);
                   bw_dist_r1 = zeros(size(bw_dist_r1));
                   bw_dist_r1(index1)=distance_r1;
                   distance_r2=bw_dist_r2(index2);
                   distance_r2 = 1-distance_r2/max(distance_r2);
                   distance_r2 = app.window_mid_points(I) + distance_r2*(app.window_mid_points(I+1)-app.window_mid_points(I));
                   distance_r2(distance_r2<app.window_mid_points(I)+1)=app.window_mid_points(I)+1;
                   distance_r2(distance_r2>app.window_mid_points(I+1)-1)=app.window_mid_points(I+1)-1;
                   distance_r2 = round(distance_r2);
                   bw_dist_r2 = zeros(size(bw_dist_r2));
                   bw_dist_r2(index2)=distance_r2;
                   
                   for J=app.window_mid_points(I)+1:app.window_mid_points(I+1)-1
                       index1 = find(bw_dist_r1 == J);
                       index2 = find(bw_dist_r2 == J);
                       input_matrix(:,:) = interp_matrix(:,:,J-1);
                       input_matrix(index1)=1;
                       input_matrix(index2)=0;
                       interp_matrix(:,:,J) = input_matrix;
                   end  
                end
                interp_matrix(:,:,1:app.window_mid_points(1))=start;
                interp_matrix(:,:,app.window_mid_points(end):end)=finish; 
                app.rough_cell_boundary_interpolated = interp_matrix;
            end
            
            
        end


        function app = interp_thresholds(app)
            if app.window_splits(app.window_stage)==1
                interp_data(1:app.total_frames) = app.whole_cell_thresholds;
                app.whole_cell_thresholds_interp = interp_data;
                
                interp_data(1:app.total_frames) = app.mitochondria_thresholds;
                app.mitochondria_thresholds_interp = interp_data;
                
                interp_data(1:app.total_frames) = app.nucleus_thresholds;
                app.nucleus_thresholds_interp = interp_data;
            else
                interp_data(1:app.window_mid_points(1))=app.whole_cell_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.whole_cell_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.whole_cell_thresholds(end);
                app.whole_cell_thresholds_interp = interp_data;
                
                interp_data(1:app.window_mid_points(1))=app.mitochondria_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.mitochondria_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.mitochondria_thresholds(end);
                app.mitochondria_thresholds_interp = interp_data;
                
                interp_data(1:app.window_mid_points(1))=app.nucleus_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.nucleus_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.nucleus_thresholds(end);
                app.nucleus_thresholds_interp = interp_data;
            end

     
        end
        
        function app = interp_intensities(app)
            moving_average_distance = round(mean(diff(app.window_start_points)));
            moving_average_distance = moving_average_distance -1;
            moving_average_distance_max = ceil(moving_average_distance/2);
            moving_average_distance_min = floor(moving_average_distance/2);
            for J=1:app.total_frames
                if J - moving_average_distance_min <= 1
                    average_cell_window = app.max_int_all_frames_ROI(:,:,1:J+moving_average_distance_max);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,1:J+moving_average_distance_max);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,1:J+moving_average_distance_max);
                elseif J + moving_average_distance_min >= app.total_frames
                    average_cell_window = app.max_int_all_frames_ROI(:,:,J-moving_average_distance_max:end);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,J-moving_average_distance_max:end);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,J-moving_average_distance_max:end);
                else
                    average_cell_window = app.max_int_all_frames_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                end
                app.whole_cell_windows_interp(:,:,J) = uint16(prctile(average_cell_window,app.whole_cell_percentile,3));
                app.nucleus_windows_interp(:,:,J) = uint16(prctile(average_nuc_window,app.nuclear_percentile,3));
                app.mitochondria_windows_interp(:,:,J) = uint16(prctile(average_mito_window,app.mitochondria_percentile,3)); 
            end
      
        end
        
       %Warnings and reset
       
       
        function app = error_message_reset(app)
                app.ErrorMessagesLabel.BackgroundColor = 'none';
                app.error_counter = 0;
                app.ErrorMessagesLabel.Text = 'Error Messages';
        end
        
        
        function app = warning_compartment_area(app,compartment,segmentation_stage,message_type)
            if message_type == 1
                info_label = 'Warning!';
                app.ErrorMessagesLabel.BackgroundColor = 'y';
                if compartment == 0
                        lsm_type_error = ['The crop selected gives a ROI area of' newline  'below 250 pixels. Only proceed if this is correct.' newline 'Otherwise re-select "Accept channels and Crop' newline 'Image" to determine ROI.'];
                elseif compartment == 1
                    if segmentation_stage == 1
                        lsm_type_error = ['The crop selected gives a cell size of' newline  'below 100 pixels. Only proceed if this is correct.' newline 'Otherwise re-select Rough Crop to outline cell.'];
                    else
                        lsm_type_error = ['The threshold selected gives a cell size of' newline  'below 100 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                    end
                elseif compartment == 2
                    lsm_type_error = ['The threshold selected gives a mitochondria size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                elseif compartment == 3 
                    lsm_type_error = ['The threshold selected gives a nucleus size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                elseif compartment == 4
                    lsm_type_error1 = ['The threshold selected gives a mitochondria size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                    lsm_type_error2 = ['The threshold selected gives a nucleus size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                elseif compartment == 5 
                    lsm_type_error = ['The crop selected gives a background size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise re-select Select background region' newline ' to outline background.'];
                end
                
            elseif message_type ==2
                info_label = 'Error Messages';
                app.ErrorMessagesLabel.BackgroundColor = 'k';
                    if compartment == 0
                        lsm_type_error = ['Error. The crop selected gives a ROI size of zero!' newline  'Re-select "Accept channels and Crop Image"' newline 'to determine ROI.'];
                    elseif compartment == 1
                        lsm_type_error = ['Error. The threshold selected gives a cell size of zero!' newline 'Adjust the threshold levels.'];
                    elseif compartment == 5 
                        lsm_type_error = ['Error. The crop selected gives a background size' newline 'of zero! Re-select "Select Background Region"' newline 'to determine background ROI.'];
                    end
            end
            if compartment ==4
                app.error_counter = app.error_counter+ 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error1];
                app.error_counter = app.error_counter+ 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error2];
                app.ErrorMessagesLabel.Text = info_label;                
            else
                app.error_counter = app.error_counter+ 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
            end


        end
  
        
        function [k_rel_ss_fitted,k_rec_fitted,k_rel_illum_fitted,k_imp_fitted,k_exp_fitted,mitochondria_ss_fitted,cytoplasm_ss_fitted,nucleus_ss_fitted] = fitted_values(app,beta)
            k_rel_ss_fitted=abs(beta(1));
            k_rec_fitted=abs(beta(2));
            k_rel_illum_fitted=abs(beta(3));
            k_imp_fitted=abs(beta(4));
            k_exp_fitted=abs(beta(5));
            mitochondria_ss_fitted=abs(beta(6));
            cytoplasm_ss_fitted=k_rel_ss_fitted*mitochondria_ss_fitted/k_rec_fitted;
            nucleus_ss_fitted=k_imp_fitted*cytoplasm_ss_fitted/k_exp_fitted;
        end
        

        function reset_function(app)
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            
            app.FrameSlider.Value = 1; 
            app.FrameSlider.Limits = [1 100];
            
            app.PercentageSlider.Value = 0;
            app.PercentageSlider_2.Value = 0;
            app.PercentageSlider_3.Value = 0;
            
            app.FramewindowSlider.Value = 1;
            app.FramewindowSlider_2.Value = 1;
            app.FramewindowSlider_3.Value = 1;
            app.FramewindowSlider_3.Limits = [1 10];
            app.FramewindowSlider.Limits = [1 10];
            app.FramewindowSlider_2.Limits = [1 10];
            
            app.FramenumberSlider.Limits = [1 100];
            
            app.ReleasestartSlider.Limits = [1 100];
            app.ReleaseendSlider.Limits = [1 100];
            app.RecoverystartSlider.Limits = [101 200];
            app.RecoveryendSlider.Limits = [101 200];
            
            app.NumberofwindowsSlider.Limits = [1 10];
            app.NumberofwindowsSlider_2.Limits = [1 10];
            app.NumberofwindowsSlider_3.Limits = [1 10];
            app.NumberofwindowsSlider_4.Limits = [1 10];
            app.NumberofwindowsSlider_5.Limits = [1 10];
            
            app.Thresholdscale01Slider.Value = 0;
            app.Thresholdscale001Slider.Value = 0;
            app.Thresholdscale0001Slider.Value = 0;
            app.Thresholdscale00001Slider.Value = 0; 
            app.Mitochondriathresholdscale01Slider.Value = 0;
            app.Mitochondriathresholdscale001Slider.Value = 0;
            app.Mitochondriathresholdscale0001Slider.Value = 0;
            app.Mitochondriathresholdscale00001Slider.Value = 0;
            app.Nucleusthresholdscale01Slider.Value = 0;
            app.Nucleusthresholdscale001Slider.Value = 0;
            app.Nucleusthresholdscale0001Slider.Value = 0;
            app.Nucleusthresholdscale00001Slider.Value = 0;

            
            app.NullButton_2.Value = true;
            app.NoneButton_5.Value = true;
            app.NoneButton_6.Value = true;
            app.NoneButton_7.Value = true;
            app.NoneButton_8.Value = true;
            app.UseframesastimeButton.Value = true;
            app.SinglelinearButton.Value = true;
            app.PrenormalisationButton.Value = true;
            app.SinglelinearButton_2.Value = true;
            
            app.NumberofwindowsSlider.Value = 1;
            app.NumberofwindowsSlider_2.Value = 1;
            app.NumberofwindowsSlider_3.Value = 1;
            app.SteadystateendframeSpinner.Value = 1;
            app.NumberofwindowsSlider_4.Value = 1;
            app.PaddedmedianrangeSpinner.Value = 5;
            app.NumberofwindowsSlider_5.Value = 1;
            
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes7_3)
            cla(app.UIAxes7)
            cla(app.UIAxes7_2)
            cla(app.UIAxes6)
            cla(app.UIAxes6_2)
            cla(app.UIAxes6_3)
            cla(app.UIAxes6_4)
            cla(app.UIAxes5)
            cla(app.UIAxes8)
            cla(app.UIAxes8_2)
            cla(app.UIAxes8_3)
            cla(app.UIAxes10)
            cla(app.UIAxes10_2)
            cla(app.UIAxes10_3)
            cla(app.UIAxes10_4)
            cla(app.UIAxes10_5)
            cla(app.UIAxes10_6)
            cla(app.UIAxes11)
            cla(app.UIAxes11_2)
            cla(app.UIAxes11_3)
            cla(app.UIAxes11_4)
            cla(app.UIAxes11_5)
            cla(app.UIAxes11_6)
            cla(app.UIAxes11_7)
            cla(app.UIAxes11_8)
            cla(app.UIAxes12)
            cla(app.UIAxes13)
            cla(app.UIAxes13_2)
            cla(app.UIAxes13_3)
            cla(app.UIAxes14)
            cla(app.UIAxes14_2)
            cla(app.UIAxes15)                        
            cla(app.UIAxes15_2)                      
            cla(app.UIAxes15_3)                      
            cla(app.UIAxes15_4)  
            cla(app.UIAxes16)
            
            app.UITable.Data = [];
            app.UITable2.Data = [];            
        end
        
        function save_function(app)
                    compartment_data = app.compartment_data;
                    total_release_frames = app.total_release_frames;
                    total_recovery_frames = app.total_recovery_frames;
                    total_frames = app.total_frames;
                    window_splits = app.window_splits;
                    nuclear_channel = app.nuclear_channel;
                    mitochondrial_channel = app.mitochondrial_channel;
                    protein_channel = app.protein_channel;
                    vertical_min_ROI = app.vertical_min_ROI;
                    vertical_max_ROI = app.vertical_max_ROI;
                    horizontal_min_ROI = app.horizontal_min_ROI;
                    horizontal_max_ROI = app.horizontal_max_ROI;
                    LSM_nucleus_release_ROI = app.LSM_nucleus_release_ROI;
                    LSM_protein_release_ROI = app.LSM_protein_release_ROI;
                    LSM_mitochondria_release_ROI = app.LSM_mitochondria_release_ROI;
                    LSM_nuclear_recovery_ROI = app.LSM_nuclear_recovery_ROI;
                    LSM_protein_recovery_ROI = app.LSM_protein_recovery_ROI;
                    LSM_mitochondria_recovery_ROI = app.LSM_mitochondria_recovery_ROI;
                    LSM_nuclear_movie_ROI = app.LSM_nuclear_movie_ROI;
                    LSM_protein_movie_ROI = app.LSM_protein_movie_ROI;
                    LSM_mitochondria_movie_ROI  = app.LSM_mitochondria_movie_ROI;
                    
                    background_mean = app.background_mean;        
                    release_linear_start = app.release_linear_start;
                    release_linear_end = app.release_linear_end;
                    recovery_linear_start = app.recovery_linear_start;
                    recovery_linear_end = app.recovery_linear_end; 
                    steady_state_end = app.steady_state_end;
                    time = app.time;
                    nonconserved_outflow_time = app.nonconserved_outflow_time;
                    nonconserved_inflow_time = app.nonconserved_inflow_time;
%                     nonconserved_outflow_smooth = app.nonconserved_outflow_smooth;
%                     nonconserved_inflow_smooth = app.nonconserved_inflow_smooth;


                    save(fullfile(pth,fnm),'compartment_data','total_release_frames','total_recovery_frames',...
                        'total_frames','window_splits','nuclear_channel','mitochondrial_channel','protein_channel',...
                        'vertical_min_ROI','vertical_max_ROI','horizontal_min_ROI','horizontal_max_ROI',...
                        'LSM_nucleus_release_ROI','LSM_protein_release_ROI','LSM_mitochondria_release_ROI',...
                        'LSM_nuclear_recovery_ROI','LSM_protein_recovery_ROI','LSM_mitochondria_recovery_ROI',...
                        'LSM_nuclear_movie_ROI','LSM_protein_movie_ROI','LSM_mitochondria_movie_ROI',...
                        'background_mean','release_linear_start','release_linear_end','recovery_linear_start',...
                        'recovery_linear_end','steady_state_end','time','nonconserved_outflow_time',...
                        'nonconserved_inflow_time','nonconserved_inflow_time')            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%             app.compartment_data = struct('window_start_points',{},'window_mid_points',{}, 'whole_cell_percentile',{},'nuclear_percentile',{},'mitochondria_percentile',{},...
%                             'whole_cell_interval_image',{},'nucleus_window_percentile_projection',{},'mitochondria_window_percentile_projection',{},'rough_cell_boundary_window',{},...
%                             'whole_cell_thresholds',{},'mitochondria_thresholds',{},'nucleus_thresholds',{},'whole_cell_binary_window',{},'mitochondria_binary_window',{},...
%                             'nucleus_binary_window',{},'rough_cell_boundary_interpolated',{},'interp_whole_cell_threshold',{},'interp_mito_threshold',{},...
%                             'interp_nucleus_threshold',{},'whole_cell_windows_interp',{},'nucleus_windows_interp',{},...
%                             'mitochondria_windows_interp',{},'whole_cell_interpolated_region_bw',{},'mitochondria_interpolated_region_bw',{},'nucleus_interpolated_region_bw',{},'nucleus_population',{},...
%                             'interp_cytoplasm_population',{},'interp_nucleus_population',{},'interp_mitochondria_population',{},'interp_total_population',{},'mitochondria_background_subtracted',{},...
%                             'cytoplasm_background_subtracted',{},'nucleus_background_subtracted',{},'total_background_subtracted',{},'single_linear_fit_total',{},...
%                             'double_linear_fit_total',{},'mitochondria_norm_single_linear',{},'cytoplasm_norm_single_linear',{},'nucleus_norm_single_linear',{},...
%                             'total_norm_single_linear',{},'mitochondria_norm_double_linear',{},'cytoplasm_norm_double_linear',{},'nucleus_norm_double_linear',{},'total_norm_double_linear',{}); 
              app.compartment_data = struct('window_start_points',{},'window_mid_points',{}, 'whole_cell_percentile',{},'nuclear_percentile',{},'mitochondria_percentile',{},...
                            'whole_cell_interval_image',{},'nucleus_window_percentile_projection',{},'mitochondria_window_percentile_projection',{},'rough_cell_boundary_window',{},...
                            'whole_cell_thresholds',{},'mitochondria_thresholds',{},'nucleus_thresholds',{},'whole_cell_binary_window',{},'mitochondria_binary_window',{},...
                            'nucleus_binary_window',{},'rough_cell_boundary_interpolated',{},'interp_whole_cell_threshold',{},'interp_mito_threshold',{},...
                            'interp_nucleus_threshold',{},'whole_cell_windows_interp',{},'nucleus_windows_interp',{},...
                            'mitochondria_windows_interp',{},'whole_cell_interpolated_region_bw',{},'mitochondria_interpolated_region_bw',{},'nucleus_interpolated_region_bw',{},'nucleus_population',{},...
                            'interp_cytoplasm_population',{},'interp_nucleus_population',{},'interp_mitochondria_population',{},'interp_total_population',{},'mitochondria_background_subtracted',{},...
                            'cytoplasm_background_subtracted',{},'nucleus_background_subtracted',{},'total_background_subtracted',{},'single_linear_fit_total',{},...
                            'double_linear_fit_total',{},'mitochondria_norm_single_linear',{},'cytoplasm_norm_single_linear',{},'nucleus_norm_single_linear',{},...
                            'total_norm_single_linear',{},'mitochondria_norm_double_linear',{},'cytoplasm_norm_double_linear',{},'nucleus_norm_double_linear',{},'total_norm_double_linear',{},...
                            'single_conserved_k_rel_ss',{},'single_conserved_k_rec',{},'single_conserved_k_rel_illum',{},'single_conserved_k_imp',{},'single_conserved_k_exp',{},...
                            'single_conserved_mitochondria_ss',{},'single_conserved_cytoplasm_ss',{},'single_conserved_nucleus_ss',{},'single_conserved_whole_cell_MSE',{},...
                            'single_conserved_model_fit_time',{},'single_conserved_model_fit_mitochondria',{},'single_conserved_model_fit_cytoplasm',{},'single_conserved_model_fit_nucleus',{},...
                            'single_conserved_model_fit_mitochondria_frames',{},'single_conserved_model_fit_cytoplasm_frames',{},'single_conserved_model_fit_nucleus_frames',{},...
                            'single_conserved_mitochondria_MSE',{},'single_conserved_cytoplasm_MSE',{},'single_conserved_nucleus_MSE',{},'double_conserved_k_rel_ss',{},...
                            'double_conserved_k_rec',{},'double_conserved_k_rel_illum',{},'double_conserved_k_imp',{},'double_conserved_k_exp',{},'double_conserved_mitochondria_ss',{},...
                            'double_conserved_cytoplasm_ss',{},'double_conserved_nucleus_ss',{},'double_conserved_whole_cell_MSE',{},'double_conserved_model_fit_time',{},...
                            'double_conserved_model_fit_mitochondria',{},'double_conserved_model_fit_cytoplasm',{},'double_conserved_model_fit_nucleus',{},...
                            'double_conserved_model_fit_mitochondria_frames',{},'double_conserved_model_fit_cytoplasm_frames',{},'double_conserved_model_fit_nucleus_frames',{},...
                            'double_conserved_mitochondria_MSE',{},'double_conserved_cytoplasm_MSE',{},'double_conserved_nucleus_MSE',{},'single_nonconserved_k_rel_ss',{},...
                            'single_nonconserved_k_rec',{},'single_nonconserved_k_rel_illum',{},'single_nonconserved_k_imp',{},'single_nonconserved_k_exp',{},...
                            'single_nonconserved_mitochondria_ss',{},'single_nonconserved_cytoplasm_ss',{},'single_nonconserved_nucleus_ss',{},'single_nonconserved_whole_cell_MSE',{},...
                            'single_nonconserved_model_fit_time',{},'single_nonconserved_model_fit_mitochondria',{},'single_nonconserved_model_fit_cytoplasm',{},...
                            'single_nonconserved_model_fit_nucleus',{},'single_nonconserved_model_fit_mitochondria_frames',{},'single_nonconserved_model_fit_cytoplasm_frames',{},...
                            'single_nonconserved_model_fit_nucleus_frames',{},'single_nonconserved_mitochondria_MSE',{},'single_nonconserved_cytoplasm_MSE',{},'single_nonconserved_nucleus_MSE',{},...
                            'double_nonconserved_k_rel_ss',{},'double_nonconserved_k_rec',{},'double_nonconserved_k_rel_illum',{},'double_nonconserved_k_imp',{},'double_nonconserved_k_exp',{},...
                            'double_nonconserved_mitochondria_ss',{},'double_nonconserved_cytoplasm_ss',{},'double_nonconserved_nucleus_ss',{},'double_nonconserved_whole_cell_MSE',{},...
                            'double_nonconserved_model_fit_time',{},'double_nonconserved_model_fit_mitochondria',{},'double_nonconserved_model_fit_cytoplasm',{},...
                            'double_nonconserved_model_fit_nucleus',{},'double_nonconserved_model_fit_mitochondria_frames',{},...
                            'double_nonconserved_model_fit_cytoplasm_frames',{},'double_nonconserved_model_fit_nucleus_frames',{},'double_nonconserved_mitochondria_MSE',{},...
                            'double_nonconserved_cytoplasm_MSE',{},'double_nonconserved_nucleus_MSE',{},'nonconserved_outflow_smooth_double',{},'nonconserved_inflow_smooth_double',{}); 
                                            
                    
            app.release_file = [];
            app.release_file_path = [];
            app.recovery_file = [];
            app.recovery_file_path = [];
            app.LSM_release = [];
            app.LSM_recovery = []; 
            app.total_channels = []; 
            app.LSM_vert_size = []; 
            app.LSM_horz_size = []; 
            app.total_release_frames = []; 
            app.total_recovery_frames = []; 
            app.total_frames = []; 
            app.window_splits = [];
            app.nuclear_channel = [];
            app.mitochondrial_channel = [];
            app.protein_channel = [];
            app.LSM_protein_release = [];
            app.LSM_protein_recovery = [];
            app.LSM_protein_all_frames = [];
            app.LSM_protein_frame1 = [];
            app.vertical_min_ROI = [];
            app.vertical_max_ROI = [];
            app.horizontal_min_ROI = [];
            app.horizontal_max_ROI = [];
            app.LSM_nucleus_release_ROI = [];
            app.LSM_protein_release_ROI = [];
            app.LSM_mitochondria_release_ROI = [];
            app.LSM_nuclear_recovery_ROI = [];
            app.LSM_protein_recovery_ROI = [];
            app.LSM_mitochondria_recovery_ROI = [];
            app.LSM_nuclear_movie_ROI = [];
            app.LSM_protein_movie_ROI = [];
            app.LSM_mitochondria_movie_ROI = [];
            app.max_int_release_frames_ROI = [];
            app.max_int_recovery_frames_ROI = [];
            app.max_int_release_all_ROI = [];
            app.max_int_recovery_all_ROI = [];
            app.max_int_all_movie_ROI = [];
            app.max_int_protein_all_movie_ROI = [];
            app.max_int_all_frames_ROI = [];
            app.window_start_points = [];
            app.window_mid_points = [];
            app.window_max_nucleus_ROI = [];
            app.window_max_mitochondria_ROI = [];
            app.window_max_protein_ROI = [];
            app.window_max_all_channels_ROI = [];
            app.whole_cell_window_percentile_projection = [];
            app.nucleus_window_percentile_projection = [];
            app.mitochondria_window_percentile_projection = [];
            app.rough_cell_boundary_window = [];
            app.whole_cell_binary_window = [];
            app.mitochondria_binary_window = [];
            app.nucleus_binary_window = [];
            app.population_cytoplasm = [];
            app.population_mitochondria = [];
            app.population_nucleus = [];
            app.population_total = [];
            app.historical_population_cytoplasm = [];
            app.historical_population_mitochondria = [];
            app.historical_population_nucleus = [];
            app.historical_population_total = [];
            app.segmentation_table_input = [];
            app.segmentation_table_background_colour = [];
            app.whole_cell_thresholds = [];
            app.mitochondria_thresholds = [];
            app.nucleus_thresholds = [];
            app.rough_cell_boundary_interpolated = [];
            app.whole_cell_thresholds_interp = [];
            app.mitochondria_thresholds_interp = [];
            app.nucleus_thresholds_interp = [];
            app.whole_cell_windows_interp = []; 
            app.nucleus_windows_interp =[];
            app.mitochondria_windows_interp =[];
            app.whole_cell_interpolated_region_bw = [];
            app.nucleus_interpolated_region_bw = [];
            app.mitochondria_interpolated_region_bw = [];
            app.background_location = [];
            app.background_mean = [];
            app.smooth_release_end = [];
            app.smooth_recovery_end = [];
            app.nonconserved_outflow_time=[]; 
            app.nonconserved_outflow_smooth =[];
            app.nonconserved_inflow_time =[];
            app.nonconserved_inflow_smooth =[];
            app.model_fitting_table_input = [];
            app.model_fitting_table_background_colour = [];
            app.k_rel_illum_guess=[]; 
            app.k_rel_ss_guess=[]; 
            app.k_rec_guess=[]; 
            app.k_imp_guess=[]; 
            app.k_exp_guess=[]; 
                            
            app.whole_cell_threshold=0;
            app.mitochondria_threshold=0;
            app.nucleus_threshold=0;
            app.whole_cell_threshold_0_1 = 0; 
            app.whole_cell_threshold_0_01 = 0; 
            app.whole_cell_threshold_0_001 = 0; 
            app.whole_cell_threshold_0_0001 = 0; 
            app.mitochondria_threshold_0_1 = 0; 
            app.mitochondria_threshold_0_01 = 0; 
            app.mitochondria_threshold_0_001 = 0; 
            app.mitochondria_threshold_0_0001 = 0;
            app.nucleus_threshold_0_1 = 0;
            app.nucleus_threshold_0_01 = 0;
            app.nucleus_threshold_0_001 =0;
            app.nucleus_threshold_0_0001=0;

            
            app.app_stage=1;
            app.input_flag = 1;
            app.error_counter = 0;
            app.cropping_stage = 0;
            app.zoom_ROI_created_flag = 0; 
            app.rough_cell_boundary_created = 0; 
            app.whole_cell_threshold_slider_flag = 0; 
            app.mitochondria_threshold_slider_flag = 0; 
            app.nucleus_threshold_slider_flag = 0; 
            app.whole_cell_slider_percentile_flag = 0; 
            app.nuclear_slider_percentile_flag = 0;
            app.mitochondria_slider_percentile_flag = 0; 
            app.background_ROI_created_flag = 0;
            app.release_start_flag = 0;
            app.release_end_flag = 0; 
            app.recovery_start_flag = 0; 
            app.recovery_end_flag = 0; 
            
            app.window_stage = 1;
            app.window_counter = 1;
            app.whole_cell_percentile=0;
            app.nuclear_percentile=0;
            app.mitochondria_percentile=0;
            
            app.FramenumberSlider.Value = 1; 
            
            app.ReleasestartSlider.Value = 1;
            app.ReleaseendSlider.Value = 100;
            app.RecoverystartSlider.Value = 101;
            app.RecoveryendSlider.Value = 200;
            
            app.intensity_plot_window = 1;
            app.release_linear_start = 1;
            app.release_linear_end = 100;
            app.recovery_linear_start = 101;
            app.recovery_linear_end = 200;
            app.intensity_plot_window_ss = 1;
            app.steady_state_end = 1;
            
            app.normalisation_plot_windows = 1;
            
            app.release_smooth_range = 1;
            app.recovery_smooth_range = 1;
            app.release_padding = 0;
            app.recovery_padding = 0;
            app.padded_median_range = 5;
            
            app.non_conserved_intensity_plot_windows = 1;
            app.nonconserved_inflow_outflow_flag = 0;
            
            app.model_fitting_flag =0;
            app.model_fitting_plot_windows = 1;

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            
            app = error_message_reset(app);

            reset_function(app);
            startupFcn(app);
            
           app.TabGroup.SelectedTab = app.ChannelselectionTab;            
        end

        % Button pushed function: LoadReleaseDataButton
        function LoadReleaseDataButtonPushed(app, event)
            [app.release_file,app.release_file_path] = uigetfile('*.lsm');
        end

        % Button pushed function: LoadRecoveryDataButton
        function LoadRecoveryDataButtonPushed(app, event)
            [app.recovery_file,app.recovery_file_path] = uigetfile('*.lsm');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
             stage_error_counter = stage_error(app,app.app_stage,1,app.cropping_stage);
            if stage_error_counter == 0
                app.input_flag = 1;
                app = error_message_reset(app);
%                 release_file=app.ReleasefileEditField.Value;
%                 recovery_file=app.RecoveryfileEditField.Value;
                if isempty(app.release_file_path) || isempty(app.release_file) || isempty(app.recovery_file_path) || isempty(app.recovery_file)% || (length(release_file) < 3) || (length(recovery_file) < 3)
%                   if (isempty(release_file)) || (isempty(release_file)) || (length(release_file) < 3) || (length(recovery_file) < 3)
                    lsm_type_error = ['Error. Release and recovery' newline 'files must be selected.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    %app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    return;
                end
                release_file=[app.release_file_path app.release_file];
                recovery_file=[app.recovery_file_path app.recovery_file];
                if(strcmp(release_file(end-2:end),'lsm')==0)||(strcmp(recovery_file(end-2:end),'lsm')==0)
                   lsm_type_error = ['Error. Image files must' newline 'be of lsm type.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   %app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if (isfile(release_file) == 0) || (isfile(recovery_file) == 0)
                    lsm_type_error = ['Error. At least one image' newline 'file has not been assigned.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    %app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
%                 if (isempty(app.CellNameEditField.Value))
%                    lsm_type_error = ['Error. Cell Name cannot be empty.' newline 'There may be additional errors.'];
%                    app.input_flag = 0;
%                    app.error_counter = app.error_counter +1;
%                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
%                    %app.ErrorMessagesLabel.Text = info_label;
%                    app.ErrorMessagesLabel.BackgroundColor = 'k';
%                    return
%                 end
%                 if (isempty(regexp(app.CellNameEditField.Value,'\W', 'once')) == 0)
%                     lsm_type_error = ['Error. Cell name cannot include' newline 'spaces or special characters.'];
%                     app.input_flag = 0;
%                     app.error_counter = app.error_counter +1;
%                     info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
%                     app.ErrorMessagesLabel.Text = info_label;
%                     app.ErrorMessagesLabel.BackgroundColor = 'k';
%                 end
                interval_string = app.TextArea.Value;
                interval_string = interval_string{1}; 
                if (isempty(interval_string)) 
                    lsm_type_error = 'Error. No. of intervals cannot be empty.';
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    %app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    return
                 end
                app.IntervalsselectedLabel.Text = 'Intervals selected: ';
                app.window_splits = [];
                print_intervals=1;
                value_split=strsplit(interval_string,',');
                for I=1:length(value_split)
                    value_num=str2double(value_split{I});
                    if(isempty(value_num))
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    elseif (mod(value_num,1) ~= 0) ||  (value_num < 0)
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        %app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    else
                        app.window_splits = [app.window_splits,value_num];    
                    end
                end
                if(isempty(app.window_splits) == 0) && (print_intervals == 1)
                    app.window_splits=unique(app.window_splits);
                    integer_string = sprintf('%.0f,' , app.window_splits);
                    integer_string = integer_string(1:end-1);% strip final comma
                    app.IntervalsselectedLabel.Text=[app.IntervalsselectedLabel.Text newline integer_string];
                end
                if exist('lsmread','file') ~=2
                    lsm_type_error = ['Error. m-file ''lsmread.m''' newline 'cannot be found.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if (app.input_flag ==1)
                    %Add code to find lsmread
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait. Loading data.','Indeterminate','on');
                    %[app.LSM_release, ~] = lsmread(app.ReleasefileEditField.Value);
                    [app.LSM_release, ~] = lsmread(release_file);
                    [app.total_release_frames,app.total_channels,~,app.LSM_vert_size,app.LSM_horz_size]=size(app.LSM_release);
                    %[app.LSM_recovery, ~] = lsmread(app.RecoveryfileEditField.Value);
                    [app.LSM_recovery, ~] = lsmread(recovery_file);
                    [app.total_recovery_frames,~,~,u_rec,v_rec]=size(app.LSM_recovery);
                    app.total_frames = app.total_release_frames + app.total_recovery_frames;
                    app.population_cytoplasm=zeros(app.total_frames,1)+NaN;
                    app.population_mitochondria=zeros(app.total_frames,1)+NaN;
                    app.population_nucleus=zeros(app.total_frames,1)+NaN;
                    app.population_total=zeros(app.total_frames,1)+NaN;
                    if app.total_channels~=3 && app.total_channels~=4
                        lsm_type_error = 'Error. No. of total_channels must be 3 or 4.';
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    elseif u_rec ~= app.LSM_vert_size || v_rec ~= app.LSM_horz_size
                        lsm_type_error = ['Error. Spatial dimensions of' newline 'release and recovery phases' newline 'should be the same.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    else
                        ch1(:, :) = app.LSM_release(1, 1, 1, :, :);
                        ch2(:, :) = app.LSM_release(1, 2, 1, :, :);
                        ch3(:, :) = app.LSM_release(1, 3, 1, :, :);
                        plot_imagesc(app,app.UIAxes,ch1)
                        plot_imagesc(app,app.UIAxes2,ch2)
                        plot_imagesc(app,app.UIAxes3,ch3)
                        if app.total_channels == 4
                            ch4(:, :) = app.LSM_release(1, 4, 1, :, :);
                            plot_imagesc(app,app.UIAxes4,ch4)
    
                        end
                        
                        if length(app.window_splits) == 1 && app.window_splits(1) == 1
                            app.NumberofwindowsSlider.Limits = [0.5 1.5];
                            app.NumberofwindowsSlider_2.Limits = [0.5 1.5];
                            app.NumberofwindowsSlider_3.Limits = [0.5 1.5];
                            app.NumberofwindowsSlider_4.Limits = [0.5 1.5];
                            app.NumberofwindowsSlider_5.Limits = [0.5 1.5];
                        else
                            app.NumberofwindowsSlider.Limits = [1 length(app.window_splits)];
                            app.NumberofwindowsSlider_2.Limits = [1 length(app.window_splits)];
                            app.NumberofwindowsSlider_3.Limits = [1 length(app.window_splits)];
                            app.NumberofwindowsSlider_4.Limits = [1 length(app.window_splits)];
                            app.NumberofwindowsSlider_5.Limits = [1 length(app.window_splits)];
                        end
         
                        
%                         slider_ticks = 1:length(app.window_splits);
                        for J = 1:length(app.window_splits)
                            slider_tick_label{J} = num2str(app.window_splits(J));
                        end
                        app.NumberofwindowsSlider.MajorTicks = 1:length(app.window_splits);
                        app.NumberofwindowsSlider.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider.MinorTicks = [];
                        app.NumberofwindowsSlider_2.MajorTicks = 1:length(app.window_splits);
                        app.NumberofwindowsSlider_2.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider_2.MinorTicks = [];
                        app.NumberofwindowsSlider_3.MajorTicks = 1:length(app.window_splits);
                        app.NumberofwindowsSlider_3.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider_3.MinorTicks = [];
                        app.NumberofwindowsSlider_4.MajorTicks = 1:length(app.window_splits);
                        app.NumberofwindowsSlider_4.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider_4.MinorTicks = [];
                        app.NumberofwindowsSlider_5.MajorTicks = 1:length(app.window_splits);
                        app.NumberofwindowsSlider_5.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider_5.MinorTicks = [];
                        
                        app.ReleasestartSlider.Limits = [1 app.total_release_frames];
                        app.ReleaseendSlider.Limits = [1 app.total_release_frames];
                        app.RecoverystartSlider.Limits = [app.total_release_frames+1 app.total_release_frames+app.total_recovery_frames];
                        app.RecoveryendSlider.Limits = [app.total_release_frames+1 app.total_release_frames+app.total_recovery_frames];
                        app.ReleasestartSlider.Value = 1;
                        app.ReleaseendSlider.Value = app.total_release_frames;
                        app.RecoverystartSlider.Value = app.total_release_frames + 1;
                        app.RecoveryendSlider.Value = app.total_release_frames+app.total_recovery_frames;
                        app.release_linear_start =1;
                        app.release_linear_end = app.total_release_frames;
                        app.recovery_linear_start = app.total_release_frames + 1;
                        app.recovery_linear_end = app.total_release_frames+app.total_recovery_frames;
                        app.FrameSlider.Limits = [1 app.total_release_frames];
                        app.app_stage=2;
                        cmap = jet(length(app.window_splits));
                        app.segmentation_table_background_colour = [];
                        for J=1:length(app.window_splits)
                            for K=1:app.window_splits(J)
                                app.segmentation_table_background_colour = [app.segmentation_table_background_colour;cmap(J,:)];
                            end
                        end
                        app = error_message_reset(app);
                        %app.ErrorMessagesLabel.BackgroundColor = 'none';
                    end
                    close(d)
                    close(f)
                end
            end           
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = uint8(min(max(round(event.Value),1),app.total_release_frames));
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                app = error_message_reset(app);
                ch1(:, :) = app.LSM_release(changingValue, 1, 1, :, :);
                ch2(:, :) = app.LSM_release(changingValue, 2, 1, :, :);
                ch3(:, :) = app.LSM_release(changingValue, 3, 1, :, :);
                plot_imagesc(app,app.UIAxes,ch1)
                plot_imagesc(app,app.UIAxes2,ch2)
                plot_imagesc(app,app.UIAxes3,ch3)
                if app.total_channels == 4
                    ch4(:, :) = app.LSM_release(changingValue, 4, 1, :, :);
                    plot_imagesc(app,app.UIAxes4,ch4)
                end
            end            
        end

        % Button pushed function: AcceptChannelsandCropImageButton
        function AcceptChannelsandCropImageButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.input_flag = 1;
%                 app.ErrorMessagesLabel.BackgroundColor = 'none';
%                 app.error_counter = 0;
%                 app.ErrorMessagesLabel.Text = 'Error Messages';
%                 info_label = app.ErrorMessagesLabel.Text;
                if (app.NoneButton_8.Value == 0) && (app.total_channels == 3) 
                   lsm_type_error = ['Error. There are only three channels.' newline 'Channel 4 should be "None".'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                total_nucleus_radio = app.NucleusButton.Value + app.NucleusButton_2.Value + app.NucleusButton_3.Value + app.NucleusButton_4.Value;
                total_mitochondria_radio = app.MitochondriaButton.Value + app.MitochondriaButton_2.Value + app.MitochondriaButton_3.Value + app.MitochondriaButton_4.Value;
                total_cytoplasm_radio = app.CytoplasmButton.Value + app.CytoplasmButton_2.Value + app.CytoplasmButton_3.Value + app.CytoplasmButton_4.Value;
                if total_cytoplasm_radio == 2
                   lsm_type_error = ['Error. The app can analyse only one protein' newline 'at a time at present'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if total_nucleus_radio ~= 1 || total_mitochondria_radio ~= 1 || (total_cytoplasm_radio ~= 1 && total_cytoplasm_radio ~= 2)
                   lsm_type_error = ['Error. There must be one allocated' newline 'protein channel, nuclear channel and' newline 'mitochondrial channel.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   %app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                
                if app.input_flag == 1
                    app = error_message_reset(app);
                    nucleus_location = [app.NucleusButton.Value, app.NucleusButton_2.Value, app.NucleusButton_3.Value, app.NucleusButton_4.Value];
                    protein_location = [app.CytoplasmButton.Value, app.CytoplasmButton_2.Value, app.CytoplasmButton_3.Value, app.CytoplasmButton_4.Value];
                    mitochondria_location = [app.MitochondriaButton.Value, app.MitochondriaButton_2.Value, app.MitochondriaButton_3.Value, app.MitochondriaButton_4.Value];
                    app.nuclear_channel = find(nucleus_location);
                    app.mitochondrial_channel = find(mitochondria_location);
                    app.protein_channel = find(protein_location);
                    LSM_protein_release=zeros(app.LSM_vert_size,app.LSM_horz_size,app.total_release_frames);
                    for I = 1:app.total_release_frames
                        LSM_protein_release(:, :, I)=app.LSM_release(I, app.protein_channel, 1, :, :);
                    end
                    app.LSM_protein_recovery=zeros(app.LSM_vert_size,app.LSM_horz_size,app.total_recovery_frames);
                    for I = 1:app.total_recovery_frames
                        app.LSM_protein_recovery(:, :, I)=app.LSM_recovery(I, app.protein_channel, 1, :, :);
                    end
                    app.LSM_protein_all_frames = cat(3, LSM_protein_release, app.LSM_protein_recovery);
                    %app.IntensityAll = sum(sum(app.LSM_protein_all_frames,1), 2);
                    app.LSM_protein_frame1(1:app.LSM_vert_size, 1:app.LSM_horz_size) = app.LSM_release(1,app.protein_channel,1,:,:);
                    
                    image_input = app.LSM_protein_frame1;
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                                        
                    crop_image = figure;
                    set(crop_image,'name','Zoom location','numbertitle','off')
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    app.cropping_stage = 1;
                    app.zoom_ROI_created_flag = 1;
                    while isvalid(crop_image)
                        h = drawrectangle;
                        while isvalid(h)
                           BW = createMask(h);
                           delete(h);    
                        end
                        
                        delete(crop_image);
                    end
                    %Draw a rectangle around image to get new image bounds
                              

                    if exist('BW','var')
                        if sum(BW(:)) < 250
                            app = warning_compartment_area(app,0,1,1);
                        end
                        index = find(BW);
                        [y, x] = ind2sub(size(BW),index);
                        max_x=max(x);
                        min_x=min(x);
                        max_y=max(y);
                        min_y=min(y);
                        y_length = max_y - min_y +1;
                        x_length = max_x - min_x +1;
                        [height,width]= size(BW);
                        if max(y_length,x_length) < min(height, width)
                            xy_diff = abs(y_length - x_length);
                            side1=floor(xy_diff/2)+xy_diff-2*floor(xy_diff/2);
                            side2=floor(xy_diff/2);
                            if x_length < y_length
                               upper_x = min(max_x+side1,width);
                               lower_x = max(min_x-side2,1);
                               if max_x+side1 > width
                                   lower_x = lower_x  - (upper_x - width);
                               end
                               if min_x-side2 < 1
                                   upper_x = upper_x + (1-min_x+side2);
                               end
                               lower_y=min_y;
                               upper_y=max_y;
                            elseif y_length < x_length
                                upper_y = min(max_y+side1,height);
                                lower_y = max(min_y-side2,1);
                                if max_y+side1 > height
                                    lower_y = lower_y  - (upper_y - height);
                                end
                                if min_y-side2 < 1
                                    upper_y = upper_y + (1-min_y+side2);
                                end
                                lower_x=min_x;
                                upper_x=max_x;
                             else
                                lower_y=min_y;
                                upper_y=max_y;
                                lower_x=min_x;
                                upper_x=max_y;
                            end
                            max_x = upper_x;
                            min_x = lower_x;
                            max_y = upper_y;
                            min_y = lower_y;
                        end
    
                        app.vertical_min_ROI = double(min_y);
                        app.vertical_max_ROI = double(max_y);
                        app.horizontal_min_ROI = double(min_x);
                        app.horizontal_max_ROI = double(max_x);
                        
                       
                        %app.app_stage = 2;
                        app.cropping_stage = 0;
                        app.zoom_ROI_created_flag = 1;
                        %app.ErrorMessagesLabel.BackgroundColor = 'none';                                                    

                   else
                        app.cropping_stage = 0;
                        app.zoom_ROI_created_flag = 0;
                    end

                end

            end
            
        end

        % Button pushed function: AcceptCropButton
        function AcceptCropButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.zoom_ROI_created_flag ==0
                    info_label = 'Error Messages';
                    lsm_type_error = [' Error. Select "Accept channels and crop image"' newline 'in Channel selection tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
             
                    if isempty(app.vertical_min_ROI) || isempty(app.vertical_max_ROI) || isempty(app.horizontal_min_ROI) || isempty(app.horizontal_max_ROI) 
                            app = warning_compartment_area(app,0,1,2);
                    else
                        %app.ErrorMessagesLabel.BackgroundColor = 'none';
                        for I=1:app.total_release_frames
                            app.LSM_nucleus_release_ROI(:,:,I) = app.LSM_release(I,app.nuclear_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_protein_release_ROI(:,:,I) = app.LSM_release(I,app.protein_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_mitochondria_release_ROI(:,:,I) = app.LSM_release(I,app.mitochondrial_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        end
                        for I=1:app.total_recovery_frames
                            app.LSM_nuclear_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.nuclear_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_protein_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.protein_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_mitochondria_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.mitochondrial_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        end
                        %Maximum of all release compartments (3d for time)
                        app.max_int_release_frames_ROI = max(max(app.LSM_nucleus_release_ROI,app.LSM_protein_release_ROI),app.LSM_mitochondria_release_ROI);
                        %Maximum of all recovery compartments (3d for time)
                        app.max_int_recovery_frames_ROI = max(max(app.LSM_nuclear_recovery_ROI,app.LSM_protein_recovery_ROI),app.LSM_mitochondria_recovery_ROI);
                        %2D maximum  of all release stages
                        app.max_int_release_all_ROI = max(app.max_int_release_frames_ROI,[],3);
                        %2D maximum of all recovery stages
                        app.max_int_recovery_all_ROI = max(app.max_int_recovery_frames_ROI,[],3);
                        %2D maximum of entire release and recovery movies
                        app.max_int_all_movie_ROI = max(app.max_int_release_all_ROI,app.max_int_recovery_all_ROI);
                        %Maximum of all compartment for every release and recovery
                        %frame
                        app.max_int_all_frames_ROI=cat(3,app.max_int_release_frames_ROI,app.max_int_recovery_frames_ROI);
                        app.max_int_protein_all_movie_ROI = max(max(app.LSM_protein_release_ROI,[],3),max(app.LSM_protein_recovery_ROI,[],3));
                        app.LSM_nuclear_movie_ROI=cat(3,app.LSM_nucleus_release_ROI,app.LSM_nuclear_recovery_ROI);
                        app.LSM_protein_movie_ROI=cat(3,app.LSM_protein_release_ROI,app.LSM_protein_recovery_ROI);
                        app.LSM_mitochondria_movie_ROI=cat(3,app.LSM_mitochondria_release_ROI,app.LSM_mitochondria_recovery_ROI);
                        
                        app.app_stage =3;
                        app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                        
                    end
                  
                end

             end            
        end

        % Button pushed function: LoadNextIterationButton
        function LoadNextIterationButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    if app.window_splits(app.window_stage) == 1
                        app.FramewindowSlider_3.Limits = [0.5 1.5];
                        app.FramewindowSlider.Limits = [0.5 1.5];
                        app.FramewindowSlider_2.Limits = [0.5 1.5];
                    else
                        app.FramewindowSlider_3.Limits = [1 app.window_splits(app.window_stage)];
                        app.FramewindowSlider.Limits = [1 app.window_splits(app.window_stage)];
                        app.FramewindowSlider_2.Limits = [1 app.window_splits(app.window_stage)];
                    end
                    app.whole_cell_slider_percentile_flag = 0;
                    app.nuclear_slider_percentile_flag = 0;
                    app.mitochondria_slider_percentile_flag = 0;
                    app.window_start_points = 1:(app.total_frames-1)/app.window_splits(app.window_stage):app.total_frames;%Start point of each region. 
                    app.window_start_points = round(app.window_start_points);
                    app.window_mid_points=1+(app.total_frames-1)/app.window_splits(app.window_stage)/2:(app.total_frames-1)/app.window_splits(app.window_stage):app.total_frames;
                    app.window_mid_points=round(app.window_mid_points);
                    app.window_max_all_channels_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.window_max_nucleus_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.window_max_mitochondria_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.window_max_protein_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    %app.MedianRegion=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
               
                    for J=1:app.window_splits(app.window_stage)
                        if J == app.window_splits(app.window_stage)
                            end_pt = app.window_start_points(J+1);
                        else
                            end_pt = app.window_start_points(J+1)-1;
                        end
                        %Median intensity for maximum intensities over time
                        %app.MedianRegion(:,:,J)=uint16(median(max(max(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),app.LSM_protein_movie_ROI(:,:,app.window_start_points(J):end_pt)),app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt)),3));
                        %Maximum intensity of nucleus in that region
                        app.window_max_nucleus_ROI(:,:,J)=max(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intesnity of cytoplasm in that region
                        app.window_max_protein_ROI(:,:,J)=max(app.LSM_protein_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intensity of mitochondria in that region
                        app.window_max_mitochondria_ROI(:,:,J)=max(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intensity of entire cell over that time region
                        app.window_max_all_channels_ROI(:,:,J)=max(app.window_max_mitochondria_ROI(:,:,J),max(app.window_max_nucleus_ROI(:,:,J),app.window_max_protein_ROI(:,:,J)));
                    end
                    app.whole_cell_window_index=min(1,length(app.window_start_points)-1);
                    app.nuclear_window_index=min(1,length(app.window_start_points)-1);
                    app.mitochondria_window_index=min(1,length(app.window_start_points)-1);
                    whole_cell_image_percentile=uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    nuc_image_percentile=uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    mito_image_percentile=uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                   
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
                    app.app_stage = 4;
                    
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end

            end               
            
        end

        % Value changed function: FramewindowSlider_3
        function FramewindowSlider_3ValueChanged(app, event)
            value = round(app.FramewindowSlider_3.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    if app.window_splits(app.window_stage) == 1
                        app.FramewindowSlider_3.Value = 1;
                        app.whole_cell_window_index = 1;
                    else
                        app.FramewindowSlider_3.Value = value;
                        app.whole_cell_window_index=max(1,min(value,length(app.window_start_points)-1));
                    end
                        
                    
                    whole_cell_image_percentile=uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.FramewindowSlider_3.Value = 1;
                    app.whole_cell_window_index = 1;
                end
            else
                app.FramewindowSlider_3.Value = 1;
                app.whole_cell_window_index = 1;

            end             
        end

        % Value changed function: FramewindowSlider
        function FramewindowSliderValueChanged(app, event)
            value = round(app.FramewindowSlider.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    if app.window_splits(app.window_stage) == 1
                        app.FramewindowSlider.Value = 1;
                        app.nuclear_window_index = 1;
                    else
                        app.FramewindowSlider.Value = value;
                        app.nuclear_window_index = max(1,min(value,length(app.window_start_points)-1));
                    end
                    nuc_image_percentile=uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.FramewindowSlider.Value = 1;
                    app.nuclear_window_index = 1;
                end
            else
                app.FramewindowSlider.Value = 1;
                app.nuclear_window_index = 1;
                
            end
        end

        % Value changed function: FramewindowSlider_2
        function FramewindowSlider_2ValueChanged(app, event)
            value = round(app.FramewindowSlider_2.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    if app.window_splits(app.window_stage) == 1
                        app.FramewindowSlider_2.Value = 1;
                        app.mitochondria_window_index = 1;
                    else
                        app.FramewindowSlider_2.Value = value;
                        app.mitochondria_window_index = max(1,min(value,length(app.window_start_points)-1));
                    end

                    mito_image_percentile=uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.FramewindowSlider_2.Value = 1;
                    app.mitochondria_window_index = 1;
                end
            else
                app.FramewindowSlider_2.Value = 1;
                app.mitochondria_window_index = 1;
               
            end               
        end

        % Value changing function: PercentageSlider_3
        function PercentageSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.whole_cell_slider_percentile_flag = 1;
                    app.whole_cell_percentile=changingValue;
                    whole_cell_image_percentile=uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
            end       
        end

        % Value changing function: PercentageSlider
        function PercentageSliderValueChanging(app, event)
           changingValue = event.Value;
           %changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.nuclear_slider_percentile_flag = 1;
                    app.nuclear_percentile=changingValue;
                    nuc_image_percentile=uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
            end                       
        end

        % Value changing function: PercentageSlider_2
        function PercentageSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.mitochondria_slider_percentile_flag = 1;
                    app.mitochondria_percentile = changingValue;
                    mito_image_percentile = uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
               else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
             
            end     
        end

        % Button pushed function: AcceptPercentilesButton
        function AcceptPercentilesButtonPushed(app, event)
            proceed_to_rough_cropping_flag = 1;
            app.error_counter = 0;
            info_label = 'Error Messages';
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.window_counter ~= 1
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if app.whole_cell_slider_percentile_flag ~= 1 & app.whole_cell_percentile == 0
                    lsm_type_error = 'Error. Select nuclear imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end                 
                if app.nuclear_slider_percentile_flag ~= 1 & app.nuclear_percentile == 0
                    lsm_type_error = 'Error. Select nuclear imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end 
                if app.mitochondria_slider_percentile_flag ~= 1 & app.mitochondria_percentile == 0
                    lsm_type_error = 'Error. Select mitochondrial imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if app.window_counter == 1 && proceed_to_rough_cropping_flag == 1
                    app = error_message_reset(app);
                    if app.window_splits(app.window_stage) ==1
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.max_int_all_movie_ROI;
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.max_int_protein_all_movie_ROI;
                    else
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                    end
                    plot_imagesc(app,app.UIAxes6,image_input1)
                    plot_imagesc(app,app.UIAxes6_2,image_input2)
                    plot_imagesc(app,app.UIAxes6_3,app.max_int_all_movie_ROI)
                    plot_imagesc(app,app.UIAxes6_4,app.max_int_protein_all_movie_ROI)
                    app.whole_cell_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.nucleus_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.mitochondria_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    for J=1:app.window_splits(app.window_stage)
                        if J == app.window_splits(app.window_stage)
                            end_pt = app.window_start_points(J+1);
                        else
                            end_pt = app.window_start_points(J+1)-1;
                        end
                        app.whole_cell_window_percentile_projection(:,:,J) = uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(J):end_pt),app.whole_cell_percentile,3));
                        app.nucleus_window_percentile_projection(:,:,J) = uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),app.nuclear_percentile,3));
                        app.mitochondria_window_percentile_projection(:,:,J) = uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt),app.mitochondria_percentile,3));
                    end
                    app.app_stage = 5;
                    app.rough_cell_boundary_created = 0;
                    app.NullButton_2.Value = true;
                    cla(app.UIAxes5);
                    app.TabGroup.SelectedTab = app.CellboundarycropTab;
                end
            end            
        end

        % Selection changed function: ImageSelectionButtonGroup
        function ImageSelectionButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ImageSelectionButtonGroup.SelectedObject;
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.Image1Button.Value == true
                    if app.window_splits(app.window_stage) ==1
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.max_int_all_movie_ROI;
                    else
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
                elseif app.Image2Button.Value == true
                    if app.window_splits(app.window_stage) ==1
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.max_int_protein_all_movie_ROI;
                    else
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
                elseif app.Image3Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.max_int_all_movie_ROI)
                elseif app.Image4Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.max_int_protein_all_movie_ROI)
                else
                    cla(app.UIAxes5)
                end
            end
        end

        % Button pushed function: RoughCropButton
        function RoughCropButtonPushed(app, event)
            %Place errors here for incorrectly timed press
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.NullButton_2.Value == true
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select best image for cropping cell outline.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
                    %app.ErrorMessagesLabel.BackgroundColor = 'none';
%                     app.error_counter = 0;
%                     app.ErrorMessagesLabel.Text = 'Error Messages';
%                     info_label = app.ErrorMessagesLabel.Text;
                    if app.Image1Button.Value == true
                        if app.window_splits(app.window_stage) ==1
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.max_int_all_movie_ROI;
                        else
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                        end                
                    elseif app.Image2Button.Value == true
                        if app.window_splits(app.window_stage) ==1
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.max_int_protein_all_movie_ROI;
                        else
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                        end
                    elseif app.Image3Button.Value == true
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) =  app.max_int_all_movie_ROI;
                    elseif app.Image4Button.Value == true
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) =  app.max_int_protein_all_movie_ROI;
                    end
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                    app.cropping_stage = 2; 
                    
                    rough_crop_image = figure;
                    set(rough_crop_image,'name','Rough crop','numbertitle','off')
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    %imagesc(image_input);
                    iptsetpref('ImshowAxesVisible','off')
                    while isvalid(rough_crop_image)
                        rough_window_bw = roipoly;
                        delete(rough_crop_image);
                    end
                    if exist('rough_window_bw','var')
                        app.rough_cell_boundary_window(:,:,app.window_counter) = rough_window_bw;
                        app.rough_cell_boundary_created = 1;
                        app.cropping_stage = 0;
                        if sum(rough_window_bw(:)) < 100
                            app = warning_compartment_area(app,1,1,1);
%                             info_label = 'Warning!';
%                             lsm_type_error = ['The crop selected gives a cell size of' newline  'below 100. Only proceed if this is correct.' newline 'Otherwise re-select Rough Crop to outline cell.'];
%                             app.error_counter = app.error_counter+ 1;
%                             %proceed_to_cropping_flag = 0;
%                             info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
%                             app.ErrorMessagesLabel.Text = info_label;
%                             app.ErrorMessagesLabel.BackgroundColor = 'y';
                        end
                    end
                end
            end
            
            
        end

        % Button pushed function: ConfirmRoughCropButton
        function ConfirmRoughCropButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.rough_cell_boundary_created == 1
                    boundary_check(:,:) = app.rough_cell_boundary_window(:,:,app.window_counter);
                    if sum(boundary_check(:)) == 0
                        app = warning_compartment_area(app,1,1,2);
%                         info_label = 'Error Messages';
%                         lsm_type_error = ['Error. The crop selected gives a cell size of zero!' newline 'Re-select Rough Crop to outline cell.'];
%                         app.error_counter = app.error_counter+ 1;
%                         %proceed_to_cropping_flag = 0;
%                         info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
%                         app.ErrorMessagesLabel.Text = info_label;
%                         app.ErrorMessagesLabel.BackgroundColor = 'k';
                    else
                        app.app_stage = 6;
                        app.whole_cell_threshold_slider_flag=0;
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.whole_cell_window_percentile_projection(:,:,app.window_counter);
                        plot_imagesc(app,app.UIAxes8,image_input)
                        whole_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
                        whole_cell_interval_image_bw = whole_cell_interval_image_bw.*app.rough_cell_boundary_window(:,:,app.window_counter);
                        whole_cell_interval_image_bw=imfill(whole_cell_interval_image_bw,'holes');
                        SE = strel('disk', 1, 0);
                        whole_cell_interval_image_bw=imopen(whole_cell_interval_image_bw,SE);
                        L = bwlabel(whole_cell_interval_image_bw);
                        LNo=length(unique(L(:)))-1;
                        if(LNo>0)
                            clear SizeI
                            SizeI=zeros(1,LNo);
                            for J=1:LNo
                                index=find(L==J);
                                SizeI(J)=length(index);
                            end
                            whole_cell_interval_image_bw=bwareaopen(whole_cell_interval_image_bw,max(SizeI),8);
                        end
                        app.whole_cell_binary_window(:,:,app.window_counter)=whole_cell_interval_image_bw;
                        plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                        whole_cell_interval_image_bw=bwmorph(whole_cell_interval_image_bw,'remove');
                        image_input=double(image_input-min(image_input(:)));
                        image_input=image_input./max(image_input(:));
                        image_input=uint16(round((2^16-1)*image_input));
                        rgbcell = ind2rgb(image_input, parula(2^16));
                        index=find(whole_cell_interval_image_bw);
                        channel=rgbcell(:,:,1);
                        channel(index)=2^16-1;
                        rgbcell(:,:,1)=channel;
                        channel=rgbcell(:,:,2);
                        channel(index)=2^16-1;
                        rgbcell(:,:,2)=channel;
                        channel=rgbcell(:,:,3);
                        channel(index)=2^16-1;
                        rgbcell(:,:,3)=channel;
                        plot_imshow(app,app.UIAxes8_3,rgbcell)
                        app.TabGroup.SelectedTab = app.CellthresholdingTab;
                    end
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select Rough Crop to outline cell.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                
            end            
        end

        % Value changed function: Thresholdscale01Slider
        function Thresholdscale01SliderValueChanged(app, event)
            value = app.Thresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_1 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end

        end

        % Value changed function: Thresholdscale001Slider
        function Thresholdscale001SliderValueChanged(app, event)
            value = app.Thresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_01 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
               event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: Thresholdscale0001Slider
        function Thresholdscale0001SliderValueChanged(app, event)
            value = app.Thresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_001 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: Thresholdscale00001Slider
        function Thresholdscale00001SliderValueChanged(app, event)
            value = app.Thresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_0001 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Button pushed function: AcceptthresholdButton
        function AcceptthresholdButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.whole_cell_threshold_slider_flag == 1 || app.window_stage >1 || app.window_counter >1
                    boundary_check(:,:) = app.whole_cell_binary_window(:,:,app.window_counter);
                    if sum(boundary_check(:)) == 0
                        app = warning_compartment_area(app,1,2,2);
% 
%                         info_label = 'Error Messages';
%                         lsm_type_error = ['Error. The threshold selected gives a cell size of zero!' newline 'Re-select the threshold to outline cell.'];
%                         app.error_counter = app.error_counter+ 1;
%                         %proceed_to_cropping_flag = 0;
%                         info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
%                         app.ErrorMessagesLabel.Text = info_label;
%                         app.ErrorMessagesLabel.BackgroundColor = 'k';
                    else
                        app.app_stage = 7;
                        app.whole_cell_thresholds(app.window_counter) = app.whole_cell_threshold;
                        app.mitochondria_threshold_slider_flag=0;
                        app.nucleus_threshold_slider_flag=0;
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.mitochondria_window_percentile_projection(:,:,app.window_counter);
                        plot_imagesc(app,app.UIAxes10,image_input)
                        mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mitochondria_threshold);
                        mito_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*mito_cell_interval_image_bw;
                        app.mitochondria_binary_window(:,:,app.window_counter)=mito_cell_interval_image_bw;
                        plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                        mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
                        image_input=double(image_input-min(image_input(:)));
                        image_input=image_input./max(image_input(:));
                        image_input=uint16(round((2^16-1)*image_input));
                        rgbcell = ind2rgb(image_input, parula(2^16));
                        index=find(mito_cell_interval_image_skel);
                        channel=rgbcell(:,:,1);
                        channel(index)=2^16-1;
                        rgbcell(:,:,1)=channel;
                        channel=rgbcell(:,:,2);
                        channel(index)=2^16-1;
                        rgbcell(:,:,2)=channel;
                        channel=rgbcell(:,:,3);
                        channel(index)=2^16-1;
                        rgbcell(:,:,3)=channel;
                        plot_imshow(app,app.UIAxes10_3,rgbcell)
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.window_counter);
                        plot_imagesc(app,app.UIAxes10_4,image_input)
                        nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nucleus_threshold);
                        nuc_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*nuc_cell_interval_image_bw;
                        nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
                        clear nuc_object_area
                        L = bwlabel(nuc_cell_interval_image_bw);
                        LNo=length(unique(L(:)))-1;
                        if LNo>0
                            nuc_object_area = zeros(1,LNo);
                            for J=1:LNo
                                index_area=find(L==J);
                                nuc_object_area(J)=length(index_area);
                            end
                            nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
                        end
                        mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                        mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                        overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
                        nuc_cell_interval_image_bw(overlap_bw==1)=0;
                        clear nuc_object_area
                        L = bwlabel(nuc_cell_interval_image_bw);
                        LNo=length(unique(L(:)))-1;
                        if(LNo>0)
                            for J=1:LNo
                                index=find(L==J);
                                nuc_object_area(J)=length(index);
                            end
                            nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
                        end
                        app.nucleus_binary_window(:,:,app.window_counter)=nuc_cell_interval_image_bw;
                        plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                        nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
                        image_input=double(image_input-min(image_input(:)));
                        image_input=image_input./max(image_input(:));
                        image_input=uint16(round((2^16-1)*image_input));
                        rgbcell = ind2rgb(image_input, parula(2^16));
                        index=find(nuc_cell_interval_image_skel);
                        channel=rgbcell(:,:,1);
                        channel(index)=2^16-1;
                        rgbcell(:,:,1)=channel;
                        channel=rgbcell(:,:,2);
                        channel(index)=2^16-1;
                        rgbcell(:,:,2)=channel;
                        channel=rgbcell(:,:,3);
                        channel(index)=2^16-1;
                        rgbcell(:,:,3)=channel;
                        plot_imshow(app,app.UIAxes10_6,rgbcell)
                        
                        if app.window_stage >1 || app.window_counter >1
                            app = population_intensity_extraction(app);
                            intensity_plotting(app);
                        end
                        
                        
                        app.TabGroup.SelectedTab = app.CompartmentthresholdingTab;
                    end
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Use sliders to select desired cell threshold.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';

                end
            end

        end

        % Value changed function: Mitochondriathresholdscale01Slider
        function Mitochondriathresholdscale01SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_1 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);        
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: 
        % Mitochondriathresholdscale001Slider
        function Mitochondriathresholdscale001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_01 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: 
        % Mitochondriathresholdscale0001Slider
        function Mitochondriathresholdscale0001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_001 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end   
        end

        % Value changed function: 
        % Mitochondriathresholdscale00001Slider
        function Mitochondriathresholdscale00001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_0001 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);                
            end
        end

        % Value changed function: Nucleusthresholdscale01Slider
        function Nucleusthresholdscale01SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_1 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                image_input = zeros(size(app.max_int_all_movie_ROI));
                image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.window_counter);
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);                
            end
        end

        % Value changed function: Nucleusthresholdscale001Slider
        function Nucleusthresholdscale001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_01 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: Nucleusthresholdscale0001Slider
        function Nucleusthresholdscale0001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_001 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: Nucleusthresholdscale00001Slider
        function Nucleusthresholdscale00001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_0001 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Button pushed function: AcceptthresholdsButton
        function AcceptthresholdsButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app.input_flag = 1;
                app = error_message_reset(app);
    %             app.ErrorMessagesLabel.BackgroundColor = 'none';
    %             app.error_counter = 0;
                info_label = 'Error Messages';
                if app.mitochondria_threshold_slider_flag == 0 && app.mitochondria_threshold ==0 
                   lsm_type_error = ['Error. Select the mitochondrial threshold using' newline 'the sliders.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                if app.nucleus_threshold_slider_flag == 0 && app.nucleus_threshold ==0
                   lsm_type_error = ['Error. Select the nuclear threshold using the' newline 'sliders.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                nuc_bw(:,:) = app.nucleus_binary_window(:,:,app.window_counter);
                mito_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                if sum(nuc_bw(:)) == 0 && sum(mito_bw(:)) == 0
                   lsm_type_error = ['Error. Both the nuclear and mitochondrial' newline 'compartments are of size zero.' newline 'Adjust thresholds.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                
                
                if app.input_flag ==1
                    app = error_message_reset(app);
                    app = population_intensity_extraction(app);
                    intensity_plotting(app);
                    app.mitochondria_thresholds(app.window_counter) = app.mitochondria_threshold;
                    app.nucleus_thresholds(app.window_counter) = app.nucleus_threshold;
                    input_data = {app.window_splits(app.window_stage) app.window_counter app.whole_cell_percentile app.nuclear_percentile app.mitochondria_percentile app.whole_cell_threshold app.mitochondria_threshold app.nucleus_threshold};
                    %app.segmentation_table_input = [app.segmentation_table_input input_data];
                    app.UITable.Data = [app.segmentation_table_input; input_data];
                    app.segmentation_table_input = app.UITable.Data;
                    app.UITable.BackgroundColor=app.segmentation_table_background_colour;%[1 1 1 ;0.85 0.85 1];
                    app.UITable.ForegroundColor=[0.5,0.5,0.5];
    
                    %app.table_cell_percentile(app.table_counter) = ;
                    if app.window_counter == app.window_splits(app.window_stage)
                        f = uifigure;
                        d = uiprogressdlg(f,'Title','Please wait. Interpolating.','Indeterminate','on');
                        app = interp_rough_region(app);
                        app = interp_thresholds(app);
                        app = interp_intensities(app);
                        clear interp_cytoplasm_population interp_nucleus_population interp_mitochondria_population interp_total_population
                        for J=1:app.total_frames
                            frame_cell = zeros(size(app.max_int_all_movie_ROI));
                            frame_cell(:,:) = app.whole_cell_windows_interp(:,:,J);
    %                        whole_cell_frame_image_bw = cell_threshold_sub_function(frame_cell,app.whole_cell_thresholds_interp(J),app.rough_cell_boundary_interpolated(:,:,J));
                            
                            whole_cell_frame_image_bw=imbinarize(uint16(frame_cell),app.whole_cell_thresholds_interp(J));
                            whole_cell_frame_image_bw = whole_cell_frame_image_bw.*app.rough_cell_boundary_interpolated(:,:,J);
                            whole_cell_frame_image_bw=imfill(whole_cell_frame_image_bw,'holes');
                            SE = strel('disk', 1, 0);
                            whole_cell_frame_image_bw=imopen(whole_cell_frame_image_bw,SE);
                            L = bwlabel(whole_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if(LNo>0)
                                clear SizeI
                                SizeI=zeros(1,LNo);
                                for K=1:LNo
                                    index=find(L==K);
                                    SizeI(K)=length(index);
                                end
                                whole_cell_frame_image_bw=bwareaopen(whole_cell_frame_image_bw,max(SizeI),8);
                            end


                            frame_mito = zeros(size(app.max_int_all_movie_ROI));
                            frame_mito(:,:) = app.mitochondria_windows_interp(:,:,J);
                                
                             mito_cell_frame_image_bw = imbinarize(uint16(frame_mito),app.mitochondria_thresholds_interp(J));
                             mito_cell_frame_image_bw=whole_cell_frame_image_bw.*mito_cell_frame_image_bw;
    
                            frame_nuc = zeros(size(app.max_int_all_movie_ROI));
                            frame_nuc(:,:) = app.nucleus_windows_interp(:,:,J);
                            
                            nuc_cell_frame_image_bw = imbinarize(uint16(frame_nuc),app.nucleus_thresholds_interp(J));
                            nuc_cell_frame_image_bw=whole_cell_frame_image_bw.*nuc_cell_frame_image_bw;
                            nuc_cell_frame_image_bw=imfill(nuc_cell_frame_image_bw,'holes');
                            L = bwlabel(nuc_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if LNo>0
                                nuc_object_area = zeros(1,LNo);
                                for K=1:LNo
                                    index_area=find(L==K);
                                    nuc_object_area(K)=length(index_area);
                                end
                                nuc_cell_frame_image_bw=bwareaopen(nuc_cell_frame_image_bw,max(nuc_object_area),8);
                            end
                            overlap_bw=nuc_cell_frame_image_bw.*mito_cell_frame_image_bw;
                            nuc_cell_frame_image_bw(overlap_bw==1)=0;
                            L = bwlabel(nuc_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if(LNo>0)
                                nuc_object_area = zeros(1,LNo);
                                for K=1:LNo
                                    index=find(L==K);
                                    nuc_object_area(K)=length(index);
                                end
                                nuc_cell_frame_image_bw=bwareaopen(nuc_cell_frame_image_bw,max(nuc_object_area),8);
                            end
                            app.whole_cell_interpolated_region_bw(:,:,J) = whole_cell_frame_image_bw;
                            app.nucleus_interpolated_region_bw(:,:,J) = nuc_cell_frame_image_bw;
                            app.mitochondria_interpolated_region_bw(:,:,J) = mito_cell_frame_image_bw;
                            cytoplasm_cell_frame_image_bw = max(whole_cell_frame_image_bw - nuc_cell_frame_image_bw - mito_cell_frame_image_bw,0);
                            single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,J);
                            protein_input = single_protein_frame(cytoplasm_cell_frame_image_bw > 0);
                            interp_cytoplasm_population(J) = sum(protein_input(:));
                            protein_input = single_protein_frame(nuc_cell_frame_image_bw > 0);
                            interp_nucleus_population(J) = sum(protein_input(:));
                            protein_input = single_protein_frame(mito_cell_frame_image_bw > 0);
                            interp_mitochondria_population(J) = sum(protein_input(:));
                            interp_total_population(J)=interp_cytoplasm_population(J)+interp_nucleus_population(J)+interp_mitochondria_population(J);
                            
                        end
    
                        app.compartment_data(app.window_stage).window_start_points=app.window_start_points;
                        app.compartment_data(app.window_stage).window_mid_points=app.window_mid_points;
                        app.compartment_data(app.window_stage).whole_cell_percentile=app.whole_cell_percentile;
                        app.compartment_data(app.window_stage).nuclear_percentile=app.nuclear_percentile;
                        app.compartment_data(app.window_stage).mitochondria_percentile=app.mitochondria_percentile;
                        app.compartment_data(app.window_stage).whole_cell_interval_image=app.whole_cell_window_percentile_projection;
                        app.compartment_data(app.window_stage).nucleus_window_percentile_projection=app.nucleus_window_percentile_projection;
                        app.compartment_data(app.window_stage).mitochondria_window_percentile_projection=app.mitochondria_window_percentile_projection;
                        app.compartment_data(app.window_stage).rough_cell_boundary_window=app.rough_cell_boundary_window;
                        app.compartment_data(app.window_stage).whole_cell_thresholds=app.whole_cell_thresholds;
                        app.compartment_data(app.window_stage).mitochondria_thresholds=app.mitochondria_thresholds;
                        app.compartment_data(app.window_stage).nucleus_thresholds=app.nucleus_thresholds;
                        app.compartment_data(app.window_stage).whole_cell_binary_window=app.whole_cell_binary_window;
                        app.compartment_data(app.window_stage).mitochondria_binary_window=app.mitochondria_binary_window;
                        app.compartment_data(app.window_stage).nucleus_binary_window=app.nucleus_binary_window;
                        app.compartment_data(app.window_stage).rough_cell_boundary_interpolated=app.rough_cell_boundary_interpolated;
                        app.compartment_data(app.window_stage).interp_whole_cell_threshold=app.whole_cell_thresholds_interp;
                        app.compartment_data(app.window_stage).interp_mito_threshold=app.mitochondria_thresholds_interp;
                        app.compartment_data(app.window_stage).interp_nucleus_threshold=app.nucleus_thresholds_interp;
                        app.compartment_data(app.window_stage).whole_cell_windows_interp=app.whole_cell_windows_interp;
                        app.compartment_data(app.window_stage).nucleus_windows_interp=app.nucleus_windows_interp;
                        app.compartment_data(app.window_stage).mitochondria_windows_interp=app.mitochondria_windows_interp;
                        app.compartment_data(app.window_stage).interp_cytoplasm_population=interp_cytoplasm_population;
                        app.compartment_data(app.window_stage).interp_nucleus_population=interp_nucleus_population;
                        app.compartment_data(app.window_stage).interp_mitochondria_population=interp_mitochondria_population;
                        app.compartment_data(app.window_stage).interp_total_population=interp_total_population;
                        app.compartment_data(app.window_stage).whole_cell_interpolated_region_bw=app.whole_cell_interpolated_region_bw;
                        app.compartment_data(app.window_stage).nucleus_interpolated_region_bw=app.nucleus_interpolated_region_bw;
                        app.compartment_data(app.window_stage).mitochondria_interpolated_region_bw=app.mitochondria_interpolated_region_bw;
                        app.historical_population_cytoplasm(:,app.window_stage) = app.population_cytoplasm;
                        app.historical_population_mitochondria(:,app.window_stage) =app.population_mitochondria;
                        app.historical_population_nucleus(:,app.window_stage) =app.population_nucleus;
                        app.historical_population_total(:,app.window_stage) =app.population_total;
    
                        close(d);
                        close(f);
                        
                        if app.window_stage == length(app.window_splits) 
                            app.app_stage =8;
                            intensity_plotting(app);
                            app.TabGroup.SelectedTab = app.InterpolationTab;
                            
                        else
                            app.window_stage = app.window_stage + 1;
                            app.window_counter = 1;
                            app.whole_cell_threshold_slider_flag=0;
                            app.population_cytoplasm=zeros(app.total_frames,1)+NaN;
                            app.population_mitochondria=zeros(app.total_frames,1)+NaN;
                            app.population_nucleus=zeros(app.total_frames,1)+NaN;
                            app.population_total=zeros(app.total_frames,1)+NaN;
                            cla(app.UIAxes7_3)
                            cla(app.UIAxes7)
                            cla(app.UIAxes7_2)
                            app.app_stage =3;
                            app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                        end
                    elseif app.window_counter < app.window_splits(app.window_stage)    
                   
                        app.window_counter = app.window_counter +1;
                        
                        app.whole_cell_threshold_slider_flag=0;
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                        plot_imagesc(app,app.UIAxes6,image_input1)
                        plot_imagesc(app,app.UIAxes6_2,image_input2)
                        plot_imagesc(app,app.UIAxes6_3,app.max_int_all_movie_ROI)
                        plot_imagesc(app,app.UIAxes6_4,app.max_int_protein_all_movie_ROI)
                        app.app_stage = 5;
                        app.rough_cell_boundary_created = 0;
                        app.NullButton_2.Value = true;
                        cla(app.UIAxes5);
                        app.TabGroup.SelectedTab = app.CellboundarycropTab;
                    end
    
                end
            end
            
        end

        % Button pushed function: SavedataandquitButton
        function SavedataandquitButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,8,app.cropping_stage);
            if stage_error_counter == 0
        
                f = uifigure;
                d = uiprogressdlg(f,'Title','Saving and closing. Goodbye.','Indeterminate','on');

                name = 'opto_analyser_output.mat';
                %filter = {'*.mat';'*.*'};
                [fnm,pth] = uiputfile(name);
                if ischar(fnm)
                    save_function(app);
                end
                close(d);
                close(f);
                reset_function(app);
            end

        end

        % Button pushed function: ProceedtonormalisationButton
        function ProceedtonormalisationButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,8,app.cropping_stage);
            if stage_error_counter == 0
                app.app_stage = 9;
                plot_imagesc(app,app.UIAxes12,app.max_int_all_frames_ROI(:,:,1));
                app.FramenumberSlider.Limits = [1 app.total_frames];
                app.TabGroup.SelectedTab = app.BackgroundsubtractionTab;
            end

        end

        % Value changing function: FramenumberSlider
        function FramenumberSliderValueChanging(app, event)
            stage_error_counter = stage_error(app,app.app_stage,9,app.cropping_stage);
            if stage_error_counter == 0    
                changingValue = uint16(event.Value);
                plot_imagesc(app,app.UIAxes12,app.max_int_all_frames_ROI(:,:,changingValue));
            end
        end

        % Button pushed function: SelectBackgroundRegionButton
        function SelectBackgroundRegionButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,9,app.cropping_stage);
            if stage_error_counter == 0     
                image_input = app.max_int_protein_all_movie_ROI;
                image_input=double(image_input-min(image_input(:)));
                image_input=image_input./max(image_input(:));
                image_input=uint16(round((2^16-1)*image_input));
                rgbcell = ind2rgb(image_input, parula(2^16));
                                            
                background_image = figure;
                set(background_image,'name','Background location','numbertitle','off')
                imshow(rgbcell);
                iptsetpref('ImshowAxesVisible','off')
                axis('equal');
                app.cropping_stage = 3;
                app.background_ROI_created_flag = 1;
                while isvalid(background_image)
                    h = drawrectangle;
                    while isvalid(h)
                       BW = createMask(h);
                       delete(h);    
                    end
                    delete(background_image);
                end
    
                if exist('BW','var')
                    if sum(BW(:)) < 25
                        app = warning_compartment_area(app,5,1,1);
                    end
                    app.background_location = find(BW);
                    app.cropping_stage = 0;
                    app.background_ROI_created_flag = 1;
                else
                    app.cropping_stage = 0;
                    app.background_ROI_created_flag = 0;
                end
            end
        end

        % Button pushed function: AcceptBackgroundRegionButton
        function AcceptBackgroundRegionButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,9,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.background_ROI_created_flag ==0
                    info_label = 'Error Messages';
                    lsm_type_error = [' Error. Select "Select Background Region"' newline 'in Background subtraction tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
                    if isempty(app.background_location)
                        app = warning_compartment_area(app,5,1,2);
                    else
                        for J = 1:length(app.total_frames)
                            single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,J);
                            app.background_mean(J) = mean(double(single_protein_frame(app.background_location)));
                        end
                        min_total = nanmin(app.compartment_data(1).interp_total_population);
                        max_total = nanmax(app.compartment_data(1).interp_total_population);
                        min_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                        max_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                        min_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                        max_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                        for J = 1:length(app.total_frames)
                            min_mito = min(min_mito,nanmin(app.compartment_data(J).interp_mitochondria_population));
                            max_mito = max(max_mito,nanmax(app.compartment_data(J).interp_mitochondria_population));
                            min_total = min(min_total,nanmin(app.compartment_data(J).interp_total_population));
                            max_total = max(max_total,nanmax(app.compartment_data(J).interp_total_population));
                            min_cyto = min(min_cyto,nanmin(app.compartment_data(J).interp_cytoplasm_population));
                            max_cyto = max(max_cyto,nanmax(app.compartment_data(J).interp_cytoplasm_population));
                        end
                        ten_percent_buffer=0.1*(max_total-min_total);
                        %Change limits to be plues minus 10%
                        ylim(app.UIAxes13_3,[min_total-ten_percent_buffer max_total+ten_percent_buffer]);
                        ten_percent_buffer=0.1*(max_mito-min_mito);
                        ylim(app.UIAxes13,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
                        ten_percent_buffer=0.1*(max_cyto-min_cyto);
                        ylim(app.UIAxes13_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
                        xlim(app.UIAxes13,[0 app.total_frames]);
                        xlim(app.UIAxes13_2,[0 app.total_frames]);
                        xlim(app.UIAxes13_3,[0 app.total_frames]);

                        app.app_stage = 10;
                        app = linear_bleach_region_plotting(app);
                        app.TabGroup.SelectedTab = app.NormalisationregionsTab;
                    end
                end
            end
      
        end

        % Value changed function: ReleasestartSlider
        function ReleasestartSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = min(round(app.ReleasestartSlider.Value),app.total_release_frames-1);
                app = error_message_reset(app);
                app.ReleasestartSlider.Value = value;
                if app.ReleaseendSlider.Value <= value
                   app.ReleaseendSlider.Value = value + 1;
                end
                app = linear_bleach_region_plotting(app);
                app.release_start_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: ReleaseendSlider
        function ReleaseendSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = max(round(app.ReleaseendSlider.Value),2);
                app = error_message_reset(app);
                app.ReleaseendSlider.Value = value;
                if app.ReleasestartSlider.Value >= value
                   app.ReleasestartSlider.Value = value - 1;
                end
                app = linear_bleach_region_plotting(app);
                app.release_end_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end 
            
        end

        % Value changed function: RecoverystartSlider
        function RecoverystartSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = min(round(app.RecoverystartSlider.Value),app.total_frames-1);
                app = error_message_reset(app);
                app.RecoverystartSlider.Value = value;
                if app.RecoveryendSlider.Value <= value
                   app.RecoveryendSlider.Value = value + 1;
                end
                app = linear_bleach_region_plotting(app);
                app.recovery_start_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: RecoveryendSlider
        function RecoveryendSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = max(round(app.RecoveryendSlider.Value),app.total_release_frames + 2);
                app = error_message_reset(app);
                app.RecoveryendSlider.Value = value;
                if app.RecoverystartSlider.Value >= value
                   app.RecoverystartSlider.Value = value - 1;
                end
                app = linear_bleach_region_plotting(app);
                app.recovery_end_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: NumberofwindowsSlider
        function NumberofwindowsSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.intensity_plot_window = round(app.NumberofwindowsSlider.Value);
                app.NumberofwindowsSlider.Value = app.intensity_plot_window;
                app = linear_bleach_region_plotting(app);
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
            
        end

        % Button pushed function: AcceptNormalisationRegionsButton
        function AcceptNormalisationRegionsButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.release_start_flag == 0 && app.release_end_flag == 0 && app.recovery_start_flag == 0 && app.recovery_end_flag == 0
                    info_label = 'Error Messages';
                    lsm_type_error = [' Error. Select the linear fitting regions' newline 'avoiding regions of rapid intensity' newline 'change in Normalisation' newline 'regions tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
                    app.app_stage = 11;
                    min_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                    max_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                    min_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                    max_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                    for J = 1:length(app.total_frames)
                        min_mito = min(min_mito,nanmin(app.compartment_data(J).interp_mitochondria_population));
                        max_mito = max(max_mito,nanmax(app.compartment_data(J).interp_mitochondria_population));
                        min_cyto = min(min_cyto,nanmin(app.compartment_data(J).interp_cytoplasm_population));
                        max_cyto = max(max_cyto,nanmax(app.compartment_data(J).interp_cytoplasm_population));
                    end
                    ten_percent_buffer=0.1*(max_mito-min_mito);
                    ylim(app.UIAxes14,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
                    ten_percent_buffer=0.1*(max_cyto-min_cyto);
                    ylim(app.UIAxes14_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
                    xlim(app.UIAxes14,[0 app.total_frames]);
                    xlim(app.UIAxes14_2,[0 app.total_frames]);
                    app = steady_state_end_plotting(app);
                    app.TabGroup.SelectedTab = app.TimeandSSinputTab;
                end
            end
        end

        % Value changed function: NumberofwindowsSlider_2
        function NumberofwindowsSlider_2ValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,11,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = steady_state_end_plotting(app);
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: SteadystateendframeSpinner
        function SteadystateendframeSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,11,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = steady_state_end_plotting(app);
            else
                app.SteadystateendframeSpinner.Value = 1;
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,11,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.input_flag = 1;
                if app.UseframesastimeButton.Value == true
                    app.time = 1:app.total_frames;
                else
                    [time_file,time_file_path] = uigetfile({'*.txt';'*.dat';'*.csv';'*.xls';'*.xlsb';'*.xlsm';'*.xlsx';'*.xltm';'*.xltx';'*.ods'});
                    time_file_full_path=[time_file_path time_file];
                    time_input = readmatrix(time_file_full_path);
                    [u,v] = size(time_input);
                    if min(u,v) ~=1 || max(u,v) ~= app.total_frames
                        lsm_type_error = ['Error. Input data should be a' newline '1 by num2str(app.total_frames) vector.' newline 'Re-select appropriate input file.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    end
                    if max(isnan(time_input)) == 1
                       lsm_type_error = ['Error. Input data should' newline 'include only numerical values.' newline 'Re-select appropriate input file.'];
                       app.input_flag = 0;
                       app.error_counter = app.error_counter +1;
                       app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                       app.ErrorMessagesLabel.BackgroundColor = 'k';
                    end
                    if app.input_flag == 1
                        if v>u
                            time_input = time_input';
                        end
                        app.time = time_input;
                    end
                end
                if app.input_flag == 1
                    linear_release_frames = app.release_linear_start:app.release_linear_end;
                    linear_recovery_frames = app.recovery_linear_start:app.recovery_linear_end;
                    all_frame_data=[linear_release_frames,linear_recovery_frames];
                    for J= 1:length(app.window_splits)
                        clear cell_size mitochondria_size nulceus_size
                        mitochondria_population = double(app.compartment_data(J).interp_mitochondria_population);
                        cytoplasm_population = double(app.compartment_data(J).interp_cytoplasm_population);
                        nucleus_population=double(app.compartment_data(J).interp_nucleus_population);
                        for I = 1:app.total_frames
                            mitochondria_bw(:,:) = app.compartment_data(J).mitochondria_interpolated_region_bw(:,:,I);
                            mitochondria_size(I) = double(sum(mitochondria_bw(:)));
                            nucleus_bw(:,:) = app.compartment_data(J).nucleus_interpolated_region_bw(:,:,I);
                            nucleus_size(I) = double(sum(nucleus_bw(:)));
                            cell_bw(:,:) = app.compartment_data(J).whole_cell_interpolated_region_bw(:,:,I);
                            cyto_bw=max(cell_bw-nucleus_bw-mitochondria_bw,0);
                            cytoplasm_size(I) = double(sum(cyto_bw(:)));
                        end
                        mitochondria_population = mitochondria_population - mitochondria_size.*app.background_mean;
                        nucleus_population = nucleus_population - nucleus_size.*app.background_mean;
                        cytoplasm_population = cytoplasm_population - cytoplasm_size.*app.background_mean;
                        total_population = mitochondria_population + nucleus_population + cytoplasm_population;
                        
                        app.compartment_data(J).mitochondria_background_subtracted=mitochondria_population;
                        app.compartment_data(J).cytoplasm_background_subtracted=cytoplasm_population;
                        app.compartment_data(J).nucleus_background_subtracted=nucleus_population;
                        app.compartment_data(J).total_background_subtracted=total_population;
                       
                        linear_release_total = total_population(app.release_linear_start:app.release_linear_end);           
                        linear_recovery_total = total_population(app.recovery_linear_start:app.recovery_linear_end);
                        
                        all_intensity_data=[linear_release_total,linear_recovery_total];
                        p = polyfit(app.time(all_frame_data),all_intensity_data,1);
                        single_linear_intensity = min(p(1),0).*app.time+p(2)%Doesn't allow positive gradient
                        app.compartment_data(J).single_linear_fit_total = single_linear_intensity;
                        app.compartment_data(J).mitochondria_norm_single_linear=mitochondria_population./single_linear_intensity;
                        app.compartment_data(J).cytoplasm_norm_single_linear=cytoplasm_population./single_linear_intensity;
                        app.compartment_data(J).nucleus_norm_single_linear=nucleus_population./single_linear_intensity;
                        app.compartment_data(J).total_norm_single_linear=total_population./single_linear_intensity;
                        
                        
                        median_total_ss=median(total_population(1:app.steady_state_end));
                        p_release = polyfit(app.time(linear_release_frames),linear_release_total,1);
                        p_recovery = polyfit(app.time(linear_recovery_frames),linear_recovery_total,1);
                        p_release(1)=min(p_release(1),0);
                        p_recovery(1)=min(p_recovery(1),0);
                        if(app.time(app.steady_state_end)*p_release(1)+p_release(2)>median_total_ss)
                            release_linear_intercept=p_release(2);
                        else
                            release_linear_intercept=median_total_ss-app.time(app.steady_state_end)*p_release(1);
                        end
                        recovery_linear_intercept=app.time(app.total_release_frames+1)*(p_release(1)-p_recovery(1))+release_linear_intercept;
                        double_release=p_release(1)*app.time(1:app.total_release_frames)+release_linear_intercept;
                        double_recovery=p_recovery(1)*app.time(app.total_release_frames+1:app.total_frames)+recovery_linear_intercept;
                        double_linear_intensity=[double_release,double_recovery];
        
                        app.compartment_data(J).double_linear_fit_total=double_linear_intensity;
                        app.compartment_data(J).mitochondria_norm_double_linear=mitochondria_population./double_linear_intensity;
                        app.compartment_data(J).cytoplasm_norm_double_linear=cytoplasm_population./double_linear_intensity;
                        app.compartment_data(J).nucleus_norm_double_linear=nucleus_population./double_linear_intensity;
                        app.compartment_data(J).total_norm_double_linear=total_population./double_linear_intensity;  
                    end
                    app.ReleaseendSpinner.Limits = [app.steady_state_end + 1 app.total_release_frames];
                    app.ReleaseendSpinner.Value = app.steady_state_end + 1;
                    app.RecoveryendSpinner_3.Limits = [app.total_release_frames + 1 app.total_frames];
                    app.RecoveryendSpinner_3.Value = app.total_release_frames + 1;
                    app = normalisation_plotting(app);
                    app.app_stage = 12;
                    app.TabGroup.SelectedTab = app.NormalisationplottingTab;
                end

            else
                app.UseframesastimeButton.Value = true;
            end

        end

        % Selection changed function: NormalisationButtonGroup_3
        function NormalisationButtonGroup_3SelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = normalisation_plotting(app);
            else
                app.SinglelinearButton.Value = true;
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = normalisation_plotting(app);
            else
                app.PrenormalisationButton.Value = true;
            end
        end

        % Value changed function: NumberofwindowsSlider_3
        function NumberofwindowsSlider_3ValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.normalisation_plot_windows = round(app.NumberofwindowsSlider_3.Value);
                app.NumberofwindowsSlider_3.Value = app.normalisation_plot_windows;
                app = normalisation_plotting(app);
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end     

        end

        % Button pushed function: AdjustforinflowoutflowButton
        function AdjustforinflowoutflowButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.app_stage = 13;
                min_total = nanmin(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear);
                max_total = nanmax(app.compartment_data(app.non_conserved_intensity_plot_windows).total_norm_single_linear);
                for J = 1:length(app.total_frames)
                    min_total = min(min_total,nanmin(app.compartment_data(J).total_norm_single_linear));
                    max_total = max(max_total,nanmax(app.compartment_data(J).total_norm_single_linear));
                end
                ten_percent_buffer=0.1*(max_total-min_total);
                ylim(app.UIAxes16,[min_total-ten_percent_buffer max_total+ten_percent_buffer]);
                xlim(app.UIAxes16,[min(app.time) max(app.time)]);
                app = release_recovery_non_conserved_smoothing(app);
                app.TabGroup.SelectedTab = app.InflowoutflowTab;
            end
        end

        % Button pushed function: 
        % SkipinflowoutflowProceedtomodelfitButton
        function SkipinflowoutflowProceedtomodelfitButtonPushed(app, event)
            
        end

        % Button pushed function: SaveandquitButton_2
        function SaveandquitButton_2Pushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
        
                f = uifigure;
                d = uiprogressdlg(f,'Title','Saving and closing. Goodbye.','Indeterminate','on');

                name = 'opto_analyser_output.mat';
                %filter = {'*.mat';'*.*'};
                [fnm,pth] = uiputfile(name);
                if ischar(fnm)
                    save_function(app);
                end
                close(d);
                close(f);
                reset_function(app);
            end
        
        end

        % Value changed function: NumberofwindowsSlider_4
        function NumberofwindowsSlider_4ValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Selection changed function: NormalisationButtonGroup_2
        function NormalisationButtonGroup_2SelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.SinglelinearButton_2.Value = true;
            end
        end

        % Value changed function: ReleaseendSpinner
        function ReleaseendSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.ReleaseendSpinner.Value = app.ReleaseendSpinner.Limits(1);
            end
   
        end

        % Value changed function: ReleasesmoothingrangeSpinner
        function ReleasesmoothingrangeSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.ReleasesmoothingrangeSpinner.Value = 1;
            end

        end

        % Value changed function: ReleasepaddingSpinner
        function ReleasepaddingSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.ReleasepaddingSpinner.Value = 0;
            end
        end

        % Value changed function: RecoveryendSpinner_3
        function RecoveryendSpinner_3ValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.RecoveryendSpinner.Value = app.ReleaseendSpinner.Limits(1);
            end
        end

        % Value changed function: RecoverypaddingSpinner
        function RecoverypaddingSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.RecoverypaddingSpinner.Value = 0;
            end
        end

        % Value changed function: RecoverysmoothingrangeSpinner
        function RecoverysmoothingrangeSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.RecoverysmoothingrangeSpinner.Value = 1;
            end
        end

        % Value changed function: PaddedmedianrangeSpinner
        function PaddedmedianrangeSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = release_recovery_non_conserved_smoothing(app);
            else
                app.PaddedmedianrangeSpinner.Value = 5;
            end
        end

        % Button pushed function: 
        % IncludeinflowoutflowandproceedtomodelfittingButton
        function IncludeinflowoutflowandproceedtomodelfittingButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                mean_recovery_time_interval = mean(diff(app.time(1:app.total_recovery_frames)));
                outflow_time=app.time(app.steady_state_end:app.smooth_release_end);
                inflow_time=app.time(app.total_release_frames:app.smooth_recovery_end);
                app.nonconserved_outflow_time = outflow_time;
                app.nonconserved_inflow_time = inflow_time;
                        
                if app.release_padding > 0
                    mean_release_time_interval = mean(diff(app.time(1:app.total_release_frames)));
                    release_padding_start_time = outflow_time(1)-app.release_padding*mean_release_time_interval:mean_release_time_interval:outflow_time(1)-mean_release_time_interval;
                    release_padding_end_time = outflow_time(end) + mean_release_time_interval:mean_release_time_interval:outflow_time(end)+app.release_padding*mean_release_time_interval;
                    outflow_time = [release_padding_start_time, outflow_time, release_padding_end_time];
                end
                if app.recovery_padding > 0
                    mean_recovery_time_interval = mean(diff(app.time(1:app.total_recovery_frames)));
                    recovery_padding_start_time = inflow_time(1)-app.recovery_padding*mean_recovery_time_interval:mean_recovery_time_interval:inflow_time(1)-mean_recovery_time_interval;
                    recovery_padding_end_time = inflow_time(end)+mean_recovery_time_interval:mean_recovery_time_interval:inflow_time(end)+mean_recovery_time_interval*app.recovery_padding;
                    inflow_time = [recovery_padding_start_time, inflow_time, recovery_padding_end_time];
                end
            
                for J=1:length(app.window_splits)
                    outflow=app.compartment_data(J).total_norm_single_linear(app.steady_state_end:app.smooth_release_end);
                    inflow=app.compartment_data(J).total_norm_single_linear(app.total_release_frames:app.smooth_recovery_end);
                    median_total_ss=median(app.compartment_data(J).total_norm_single_linear(1:app.steady_state_end));
    
                    if app.release_padding == 0
                        outflow(1)=median_total_ss;
                    else
                        outflow_end_median = median(app.compartment_data(J).total_norm_single_linear(app.smooth_release_end:min(app.smooth_release_end+app.padded_median_range - 1,app.total_frames)));
                        outflow = [zeros(1,app.release_padding)+median_total_ss, outflow, zeros(1,app.release_padding)+outflow_end_median];
                    end
                    if app.recovery_padding > 0
                        inflow_start_median = median(app.compartment_data(J).total_norm_single_linear(max(app.total_release_frames-app.padded_median_range+1,1):app.total_release_frames));
                        inflow_end_median = median(app.compartment_data(J).total_norm_single_linear(app.smooth_recovery_end:min(app.smooth_recovery_end+app.padded_median_range - 1,app.total_frames)));
                        inflow = [zeros(1,app.recovery_padding)+inflow_start_median, inflow, zeros(1,app.recovery_padding) + inflow_end_median];
                    end
                    smooth_outflow = smoothdata(outflow,'loess',app.release_smooth_range);
                    smooth_inflow = smoothdata(inflow,'loess',app.recovery_smooth_range);
                    nonconserved_outflow_smooth = smooth_outflow(app.release_padding+1:end-app.release_padding);
                    nonconserved_inflow_smooth = smooth_inflow(app.recovery_padding+1:end-app.recovery_padding);
                    app.compartment_data(J).nonconserved_outflow_smooth_single=nonconserved_outflow_smooth;
                    app.compartment_data(J).nonconserved_inflow_smooth_single=nonconserved_inflow_smooth;


                    median_total_ss=median(app.compartment_data(J).total_norm_double_linear(1:app.steady_state_end));
                    outflow=app.compartment_data(J).total_norm_double_linear(app.steady_state_end:app.smooth_release_end);
                    inflow=app.compartment_data(J).total_norm_double_linear(app.total_release_frames:app.smooth_recovery_end);
                    
                    if app.release_padding == 0
                        outflow(1)=median_total_ss;
                    else
                        outflow_end_median = median(app.compartment_data(J).total_norm_double_linear(app.smooth_release_end:min(app.smooth_release_end+app.padded_median_range - 1,app.total_frames)));
                        outflow = [zeros(1,app.release_padding)+median_total_ss, outflow, zeros(1,app.release_padding)+outflow_end_median];
                    end
                    if app.recovery_padding > 0
                        inflow_start_median = median(app.compartment_data(J).total_norm_double_linear(max(app.total_release_frames-app.padded_median_range+1,1):app.total_release_frames));
                        inflow_end_median = median(app.compartment_data(J).total_norm_double_linear(app.smooth_recovery_end:min(app.smooth_recovery_end+app.padded_median_range - 1,app.total_frames)));
                        inflow = [zeros(1,app.recovery_padding)+inflow_start_median, inflow, zeros(1,app.recovery_padding) + inflow_end_median];
                    end
                    smooth_outflow = smoothdata(outflow,'loess',app.release_smooth_range);
                    smooth_inflow = smoothdata(inflow,'loess',app.recovery_smooth_range);
                    nonconserved_outflow_smooth = smooth_outflow(app.release_padding+1:end-app.release_padding);
                    nonconserved_inflow_smooth = smooth_inflow(app.recovery_padding+1:end-app.recovery_padding);
                    app.compartment_data(J).nonconserved_outflow_smooth_double=nonconserved_outflow_smooth;
                    app.compartment_data(J).nonconserved_inflow_smooth_double=nonconserved_inflow_smooth;

                end
                app.nonconserved_inflow_outflow_flag = 1;
                app.app_stage = 14;
                app.TabGroup.SelectedTab = app.ModelfittingTab;
            end
        end

        % Button pushed function: FitmodelButton
        function FitmodelButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,14,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.input_flag = 1;
            
                if app.nonconserved_inflow_outflow_flag == 1
                    number_of_model_fits = length(app.window_splits)*2*2;
                else
                    number_of_model_fits = length(app.window_splits)*2;
                end
                model_fit_counter = 0;
               
                
                if app.model_fitting_flag == 0
                    app.k_rel_illum_guess = zeros(number_of_model_fits,1) + app.IlluminatedreleaseEditField.Value;
                    app.k_rel_ss_guess = zeros(number_of_model_fits,1) + app.NonilluminatedreleaseEditField.Value;
                    app.k_rec_guess = zeros(number_of_model_fits,1) + app.RecoveryEditField.Value;
                    app.k_imp_guess = zeros(number_of_model_fits,1) + app.ImportEditField.Value;
                    app.k_exp_guess = zeros(number_of_model_fits,1) + app.ExportEditField.Value;
                    fit_this_data=ones(number_of_model_fits,1);
                else
                    app.model_fitting_table_input=get(app.UITable2,'Data');
                    refit_include=app.model_fitting_table_input(:,1);
                    for I=1:number_of_model_fits
                        fit_this_data(I)=refit_include{I};
                    end
                    refit_k_rel_illum = app.model_fitting_table_input(:,5);
                    refit_k_rel_ss = app.model_fitting_table_input(:,6);
                    refit_k_rec = app.model_fitting_table_input(:,7);
                    refit_k_imp = app.model_fitting_table_input(:,8);
                    refit_k_exp = app.model_fitting_table_input(:,9);
                    if app.GlobalguessesButton.Value == true
                        for I=1:number_of_model_fits
                            if fit_this_data(I) == 1
                                app.k_rel_illum_guess(I) = app.IlluminatedreleaseEditField.Value;
                                app.k_rel_ss_guess(I) = app.NonilluminatedreleaseEditField.Value;
                                app.k_rec_guess(I) = app.RecoveryEditField.Value;
                                app.k_imp_guess(I) = app.ImportEditField.Value;
                                app.k_exp_guess(I) = app.ExportEditField.Value;
                            end
                        end                    
                    else
                        for I=1:number_of_model_fits
                            if fit_this_data(I) == 1
                                app.k_rel_illum_guess(I) = refit_k_rel_illum{I};
                                app.k_rel_ss_guess(I) = refit_k_rel_ss{I};
                                app.k_rec_guess(I) = refit_k_rec{I};
                                app.k_imp_guess(I) = refit_k_imp{I};
                                app.k_exp_guess(I) = refit_k_exp{I};
                            end
                        end
                    end
                end
                
                if max(isnan(app.k_rel_illum_guess)) == 1 || max(isnan(app.k_rel_ss_guess)) == 1 || max(isnan(app.k_rec_guess)) == 1 || max(isnan(app.k_imp_guess)) == 1 || max(isnan(app.k_exp_guess)) == 1
                   lsm_type_error = ['Error. At least one parameter guess' newline 'is not a numeric value.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';    
                end
                
                if app.input_flag ==1
                    app.UITable2.Data=[];
                    app.model_fitting_table_input =[];
                    app.model_fitting_table_background_colour=[];
                    app.UITable2.ForegroundColor=[0,0,0];
                    
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait. Fitting data.');                
                    d.Value = 0;
                    time_nlinfit = repmat(app.time',3,1);
                    time_nlinfit(end+1)=app.time(app.total_release_frames);
                    time_nlinfit(end+1)=app.time(app.steady_state_end);


                    for J = 1:length(app.window_splits)
                        for I =1:2
                            if app.nonconserved_inflow_outflow_flag == 1
                                time_nonconserved_nlinfit = time_nlinfit;
                                inflow_outflow=nan(app.total_frames,1);
                                if I == 1
                                    inflow_outflow(app.steady_state_end:app.smooth_release_end) = app.compartment_data(J).nonconserved_outflow_smooth_single;
                                    inflow_outflow(app.total_release_frames:app.smooth_recovery_end) = app.compartment_data(J).nonconserved_inflow_smooth_single;
                                else
                                    inflow_outflow(app.steady_state_end:app.smooth_release_end) = app.compartment_data(J).nonconserved_outflow_smooth_double;
                                    inflow_outflow(app.total_release_frames:app.smooth_recovery_end) = app.compartment_data(J).nonconserved_inflow_smooth_double;                                    
                                end
                                time_nonconserved_nlinfit=[time_nonconserved_nlinfit;inflow_outflow];
                                time_nonconserved_nlinfit(end+1)=app.steady_state_end;
                                time_nonconserved_nlinfit(end+1)=app.smooth_release_end;
                                time_nonconserved_nlinfit(end+1)=app.total_release_frames;
                                time_nonconserved_nlinfit(end+1)=app.smooth_recovery_end;
                                time_nonconserved_nlinfit(end+1)=app.total_frames;
                                ode_input_output = diff(inflow_outflow')./diff(app.time);
                                ode_input_output(isnan(ode_input_output))=0;
                                ode_input_output(end+1)=0;
                            end
                            
                            model_fit_counter = model_fit_counter+1;
                            if I == 1
                                mitochondria_model_input = app.compartment_data(J).mitochondria_norm_single_linear;
                                cytoplasm_model_input = app.compartment_data(J).cytoplasm_norm_single_linear;
                                nucleus_model_input = app.compartment_data(J).nucleus_norm_single_linear;
                            else
                                mitochondria_model_input = app.compartment_data(J).mitochondria_norm_double_linear;
                                cytoplasm_model_input = app.compartment_data(J).cytoplasm_norm_double_linear;
                                nucleus_model_input = app.compartment_data(J).nucleus_norm_double_linear;                    
                            end
                            mitochondria_ss_guess = median(mitochondria_model_input(1:app.steady_state_end));
                            cytoplasm_ss_guess = median(cytoplasm_model_input(1:app.steady_state_end));
                            nucleus_ss_guess = median(nucleus_model_input(1:app.steady_state_end));
                            data_nlinfit=[mitochondria_model_input,cytoplasm_model_input,nucleus_model_input];
                            data_nlinfit=data_nlinfit';
                            weight_nlinfit=ones(3*app.total_frames,1);
                            weight_nlinfit(1:app.total_frames)=weight_nlinfit(1:app.total_frames)/(mitochondria_ss_guess);
                            weight_nlinfit(app.total_frames+1:2*app.total_frames)=weight_nlinfit(app.total_frames+1:2*app.total_frames)/(cytoplasm_ss_guess);
                            weight_nlinfit(2*app.total_frames+1:end)=2*weight_nlinfit(2*app.total_frames+1:end)/(nucleus_ss_guess);
                            if fit_this_data(model_fit_counter) == 1
                                parguess = [app.k_rel_ss_guess(model_fit_counter) app.k_rec_guess(model_fit_counter) app.k_rel_illum_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess];
                                opts = statset('MaxIter',100000,'TolX',1e-12,'TolFun',1e-12);
                                try
                                    [beta,Residuals,Jacobian,CovB,MSE,ErrorModelInfo]=nlinfit(time_nlinfit,data_nlinfit,@nlinfit_ODE_conserved_intensity,parguess,'Weights',weight_nlinfit,'options',opts);
                                catch ME
                                    ME;
                                    beta = nan(1,6);
                                    MSE=nan;
                                end
                                [k_rel_ss_fitted,k_rec_fitted,k_rel_illum_fitted,k_imp_fitted,k_exp_fitted,mitochondria_ss_fitted,cytoplasm_ss_fitted,nucleus_ss_fitted] = fitted_values(app,beta);
                                if I ==1
                                    app.compartment_data(J).single_conserved_k_rel_ss = k_rel_ss_fitted;
                                    app.compartment_data(J).single_conserved_k_rec = k_rec_fitted;
                                    app.compartment_data(J).single_conserved_k_rel_illum = k_rel_illum_fitted;
                                    app.compartment_data(J).single_conserved_k_imp = k_imp_fitted;
                                    app.compartment_data(J).single_conserved_k_exp = k_exp_fitted;
                                    app.compartment_data(J).single_conserved_mitochondria_ss = mitochondria_ss_fitted;
                                    app.compartment_data(J).single_conserved_cytoplasm_ss = cytoplasm_ss_fitted;
                                    app.compartment_data(J).single_conserved_nucleus_ss = nucleus_ss_fitted;
                                    app.compartment_data(J).single_conserved_whole_cell_MSE = MSE;
                                else
                                    app.compartment_data(J).double_conserved_k_rel_ss = k_rel_ss_fitted;
                                    app.compartment_data(J).double_conserved_k_rec = k_rec_fitted;
                                    app.compartment_data(J).double_conserved_k_rel_illum = k_rel_illum_fitted;
                                    app.compartment_data(J).double_conserved_k_imp = k_imp_fitted;
                                    app.compartment_data(J).double_conserved_k_exp = k_exp_fitted;
                                    app.compartment_data(J).double_conserved_mitochondria_ss = mitochondria_ss_fitted;
                                    app.compartment_data(J).double_conserved_cytoplasm_ss = cytoplasm_ss_fitted;
                                    app.compartment_data(J).double_conserved_nucleus_ss = nucleus_ss_fitted;
                                    app.compartment_data(J).double_conserved_whole_cell_MSE = MSE;
                                end
                               
                                
                                if isnan(MSE)~=1
        %                             app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                    options = odeset('RelTol',1e-10,'AbsTol',[1e-6,1e-6,1e-6]);
                             
                                    [T1,Y1] = ode15s(@(t,y) conserved_intensity_parameter_pass_ODE(t,y,k_rel_ss_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted),[0 app.time(app.steady_state_end)],[mitochondria_ss_fitted,cytoplasm_ss_fitted,nucleus_ss_fitted]);
                                    [T2,Y2] = ode15s(@(t,y) conserved_intensity_parameter_pass_ODE(t,y,k_rel_illum_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted),[T1(end) app.time(app.total_release_frames)],[Y1(end,1),Y1(end,2),Y1(end,3)]);
                                    [T3,Y3] = ode15s(@(t,y) conserved_intensity_parameter_pass_ODE(t,y,k_rel_ss_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted),[T2(end) ceil(app.time(end))],[Y2(end,1),Y2(end,2),Y2(end,3)]);
                                    T=[T1(1:end-1);T2(1:end-1);T3];
                                    Y=[Y1(1:end-1,:);Y2(1:end-1,:);Y3];
                                    
                                    mitochondria_time_interp = interp1(T,Y(:,1),app.time);
                                    cytoplasm_time_interp = interp1(T,Y(:,2),app.time);
                                    nucleus_time_interp = interp1(T,Y(:,3),app.time);
                                    MSE_mitochondria = mean((mitochondria_time_interp-mitochondria_model_input).^2);
                                    MSE_cytoplasm = mean((cytoplasm_time_interp-cytoplasm_model_input).^2);
                                    MSE_nucleus = mean((nucleus_time_interp-nucleus_model_input).^2);
                                    if I ==1
                                        app.compartment_data(J).single_conserved_model_fit_time = T;
                                        app.compartment_data(J).single_conserved_model_fit_mitochondria = Y(:,1);
                                        app.compartment_data(J).single_conserved_model_fit_cytoplasm = Y(:,2);
                                        app.compartment_data(J).single_conserved_model_fit_nucleus = Y(:,3);
                                        app.compartment_data(J).single_conserved_model_fit_mitochondria_frames = mitochondria_time_interp;
                                        app.compartment_data(J).single_conserved_model_fit_cytoplasm_frames = cytoplasm_time_interp;
                                        app.compartment_data(J).single_conserved_model_fit_nucleus_frames = nucleus_time_interp;
                                        app.compartment_data(J).single_conserved_mitochondria_MSE = MSE_mitochondria;
                                        app.compartment_data(J).single_conserved_cytoplasm_MSE = MSE_cytoplasm;
                                        app.compartment_data(J).single_conserved_nucleus_MSE = MSE_nucleus;
                                    else
                                        app.compartment_data(J).double_conserved_model_fit_time = T;
                                        app.compartment_data(J).double_conserved_model_fit_mitochondria = Y(:,1);
                                        app.compartment_data(J).double_conserved_model_fit_cytoplasm = Y(:,2);
                                        app.compartment_data(J).double_conserved_model_fit_nucleus = Y(:,3);
                                        app.compartment_data(J).double_conserved_model_fit_mitochondria_frames = mitochondria_time_interp;
                                        app.compartment_data(J).double_conserved_model_fit_cytoplasm_frames = cytoplasm_time_interp;
                                        app.compartment_data(J).double_conserved_model_fit_nucleus_frames = nucleus_time_interp;
                                        app.compartment_data(J).double_conserved_mitochondria_MSE = MSE_mitochondria;
                                        app.compartment_data(J).double_conserved_cytoplasm_MSE = MSE_cytoplasm;
                                        app.compartment_data(J).double_conserved_nucleus_MSE = MSE_nucleus;
                                    end
                                else
                                    
                                    if I ==1
                                        app.compartment_data(J).single_conserved_model_fit_time = app.time;
                                        app.compartment_data(J).single_conserved_model_fit_mitochondria = nan(app.total_frames,1);
                                        app.compartment_data(J).single_conserved_model_fit_cytoplasm = nan(app.total_frames,1);
                                        app.compartment_data(J).single_conserved_model_fit_nucleus = nan(app.total_frames,1);
                                        app.compartment_data(J).single_conserved_model_fit_mitochondria_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).single_conserved_model_fit_cytoplasm_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).single_conserved_model_fit_nucleus_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).single_conserved_mitochondria_MSE = NaN;
                                        app.compartment_data(J).single_conserved_cytoplasm_MSE = NaN;
                                        app.compartment_data(J).single_conserved_nucleus_MSE = NaN;
        
                                    else
                                        app.compartment_data(J).double_conserved_model_fit_time = app.time;
                                        app.compartment_data(J).double_conserved_model_fit_mitochondria = nan(app.total_frames,1);
                                        app.compartment_data(J).double_conserved_model_fit_cytoplasm = nan(app.total_frames,1);
                                        app.compartment_data(J).double_conserved_model_fit_nucleus = nan(app.total_frames,1);
                                        app.compartment_data(J).double_conserved_model_fit_mitochondria_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).double_conserved_model_fit_cytoplasm_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).double_conserved_model_fit_nucleus_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).double_conserved_mitochondria_MSE = NaN;
                                        app.compartment_data(J).double_conserved_cytoplasm_MSE = NaN;
                                        app.compartment_data(J).double_conserved_nucleus_MSE = NaN;
                                   
                                    end                        
                                end
                            end
                            if I==1
                                input_data = {true app.window_splits(J) 'single' 'yes' app.k_rel_illum_guess(model_fit_counter) app.k_rel_ss_guess(model_fit_counter) app.k_rec_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess...
                                app.compartment_data(J).single_conserved_k_rel_illum app.compartment_data(J).single_conserved_k_rel_ss app.compartment_data(J).single_conserved_k_rec...
                                app.compartment_data(J).single_conserved_k_imp app.compartment_data(J).single_conserved_k_exp app.compartment_data(J).single_conserved_mitochondria_ss...
                                app.compartment_data(J).single_conserved_cytoplasm_ss app.compartment_data(J).single_conserved_nucleus_ss app.compartment_data(J).single_conserved_whole_cell_MSE...
                                app.compartment_data(J).single_conserved_nucleus_MSE app.compartment_data(J).single_conserved_cytoplasm_MSE app.compartment_data(J).single_conserved_mitochondria_MSE};
                                if isnan(app.compartment_data(J).single_conserved_whole_cell_MSE)==0
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                else
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0.7 0 0]];
                                end
                            else
                                input_data = {true app.window_splits(J) 'double' 'yes' app.k_rel_illum_guess(model_fit_counter) app.k_rel_ss_guess(model_fit_counter) app.k_rec_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess...
                                app.compartment_data(J).double_conserved_k_rel_illum app.compartment_data(J).double_conserved_k_rel_ss app.compartment_data(J).double_conserved_k_rec...
                                app.compartment_data(J).double_conserved_k_imp app.compartment_data(J).double_conserved_k_exp app.compartment_data(J).double_conserved_mitochondria_ss...
                                app.compartment_data(J).double_conserved_cytoplasm_ss app.compartment_data(J).double_conserved_nucleus_ss app.compartment_data(J).double_conserved_whole_cell_MSE...
                                app.compartment_data(J).double_conserved_nucleus_MSE app.compartment_data(J).double_conserved_cytoplasm_MSE app.compartment_data(J).double_conserved_mitochondria_MSE};
                                if isnan(app.compartment_data(J).double_conserved_whole_cell_MSE)==0
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                else
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0.7 0 0]];
                                end
                            end
        
                            app.UITable2.Data = [app.model_fitting_table_input ; input_data];
                            app.model_fitting_table_input  = app.UITable2.Data;
                            app.UITable2.BackgroundColor=app.model_fitting_table_background_colour;%[1 1 1 ;0.85 0.85 1];
                            d.Value = model_fit_counter/number_of_model_fits;
                            
                            if app.nonconserved_inflow_outflow_flag == 1
                                model_fit_counter = model_fit_counter+1;
                                if fit_this_data(model_fit_counter) == 1
                                    parguess = [app.k_rel_ss_guess(model_fit_counter) app.k_rec_guess(model_fit_counter) app.k_rel_illum_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess];
                                    opts = statset('MaxIter',100000,'TolX',1e-12,'TolFun',1e-12);
                                    try
                                        [beta,Residuals,Jacobian,CovB,MSE,ErrorModelInfo]=nlinfit(time_nonconserved_nlinfit,data_nlinfit,@nlinfit_ODE_nonconserved_intensity,parguess,'Weights',weight_nlinfit,'options',opts);
                                    catch ME
                                        ME;
                                        beta = nan(1,6);
                                        MSE=nan;
                                    end
                                    [k_rel_ss_fitted,k_rec_fitted,k_rel_illum_fitted,k_imp_fitted,k_exp_fitted,mitochondria_ss_fitted,cytoplasm_ss_fitted,nucleus_ss_fitted] = fitted_values(app,beta);
                                    if I ==1
                                        app.compartment_data(J).single_nonconserved_k_rel_ss = k_rel_ss_fitted;
                                        app.compartment_data(J).single_nonconserved_k_rec = k_rec_fitted;
                                        app.compartment_data(J).single_nonconserved_k_rel_illum = k_rel_illum_fitted;
                                        app.compartment_data(J).single_nonconserved_k_imp = k_imp_fitted;
                                        app.compartment_data(J).single_nonconserved_k_exp = k_exp_fitted;
                                        app.compartment_data(J).single_nonconserved_mitochondria_ss = mitochondria_ss_fitted;
                                        app.compartment_data(J).single_nonconserved_cytoplasm_ss = cytoplasm_ss_fitted;
                                        app.compartment_data(J).single_nonconserved_nucleus_ss = nucleus_ss_fitted;
                                        app.compartment_data(J).single_nonconserved_whole_cell_MSE = MSE;
                                    else
                                        app.compartment_data(J).double_nonconserved_k_rel_ss = k_rel_ss_fitted;
                                        app.compartment_data(J).double_nonconserved_k_rec = k_rec_fitted;
                                        app.compartment_data(J).double_nonconserved_k_rel_illum = k_rel_illum_fitted;
                                        app.compartment_data(J).double_nonconserved_k_imp = k_imp_fitted;
                                        app.compartment_data(J).double_nonconserved_k_exp = k_exp_fitted;
                                        app.compartment_data(J).double_nonconserved_mitochondria_ss = mitochondria_ss_fitted;
                                        app.compartment_data(J).double_nonconserved_cytoplasm_ss = cytoplasm_ss_fitted;
                                        app.compartment_data(J).double_nonconserved_nucleus_ss = nucleus_ss_fitted;
                                        app.compartment_data(J).double_nonconserved_whole_cell_MSE = MSE;
                                    end
                                    if isnan(MSE)~=1
                                        app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                        options = odeset('RelTol',1e-10,'AbsTol',[1e-6,1e-6,1e-6]);
                                        [t,y] = ode15s(@(t,y) conserved_intensity_parameter_pass_ODE(t,y,k_rel_ss_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted),[0 app.time(app.steady_state_end)],[mitochondria_ss_fitted,cytoplasm_ss_fitted,nucleus_ss_fitted]);
                                        T=t;
                                        Y=y;
                                        for time_iterator = app.steady_state_end:app.smooth_release_end
                                            [t,y] = ode15s(@(t,y) non_conserved_intensity_parameter_pass_ODE(t,y,k_rel_illum_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted,ode_input_output(time_iterator)),[t(end) app.time(time_iterator+1)],[y(end,1),y(end,2),y(end,3)]);       
                                            T=[T(1:end-1);t];
                                            Y=[Y(1:end-1,:);y];
                                        end
                                        [t,y] = ode15s(@(t,y) conserved_intensity_parameter_pass_ODE(t,y,k_rel_illum_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted),[t(end) app.time(app.total_release_frames)],[y(end,1),y(end,2),y(end,3)]);
                                        T=[T(1:end-1);t];
                                        Y=[Y(1:end-1,:);y];
                                        for time_iterator = app.total_release_frames:app.smooth_recovery_end
                                            [t,y] = ode15s(@(t,y) non_conserved_intensity_parameter_pass_ODE(t,y,k_rel_ss_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted,ode_input_output(time_iterator)),[t(end) app.time(time_iterator+1)],[y(end,1),y(end,2),y(end,3)]);       
                                            T=[T(1:end-1);t];
                                            Y=[Y(1:end-1,:);y];
                                        end
                                        [t,y] = ode15s(@(t,y) conserved_intensity_parameter_pass_ODE(t,y,k_rel_ss_fitted,k_rec_fitted,k_imp_fitted,k_exp_fitted),[t(end) ceil(app.time(end))],[y(end,1),y(end,2),y(end,3)]);
                                        T=[T(1:end-1);t];
                                        Y=[Y(1:end-1,:);y];
                                        mitochondria_time_interp = interp1(T,Y(:,1),app.time);
                                        cytoplasm_time_interp = interp1(T,Y(:,2),app.time);
                                        nucleus_time_interp = interp1(T,Y(:,3),app.time);
                                        MSE_mitochondria = mean((mitochondria_time_interp-mitochondria_model_input).^2);
                                        MSE_cytoplasm = mean((cytoplasm_time_interp-cytoplasm_model_input).^2);
                                        MSE_nucleus = mean((nucleus_time_interp-nucleus_model_input).^2);
                                        if I ==1
                                            app.compartment_data(J).single_nonconserved_model_fit_time = T;
                                            app.compartment_data(J).single_nonconserved_model_fit_mitochondria = Y(:,1);
                                            app.compartment_data(J).single_nonconserved_model_fit_cytoplasm = Y(:,2);
                                            app.compartment_data(J).single_nonconserved_model_fit_nucleus = Y(:,3);
                                            app.compartment_data(J).single_nonconserved_model_fit_mitochondria_frames = mitochondria_time_interp;
                                            app.compartment_data(J).single_nonconserved_model_fit_cytoplasm_frames = cytoplasm_time_interp;
                                            app.compartment_data(J).single_nonconserved_model_fit_nucleus_frames = nucleus_time_interp;
                                            app.compartment_data(J).single_nonconserved_mitochondria_MSE = MSE_mitochondria;
                                            app.compartment_data(J).single_nonconserved_cytoplasm_MSE = MSE_cytoplasm;
                                            app.compartment_data(J).single_nonconserved_nucleus_MSE = MSE_nucleus;
                                        else
                                            app.compartment_data(J).double_nonconserved_model_fit_time = T;
                                            app.compartment_data(J).double_nonconserved_model_fit_mitochondria = Y(:,1);
                                            app.compartment_data(J).double_nonconserved_model_fit_cytoplasm = Y(:,2);
                                            app.compartment_data(J).double_nonconserved_model_fit_nucleus = Y(:,3);
                                            app.compartment_data(J).double_nonconserved_model_fit_mitochondria_frames = mitochondria_time_interp;
                                            app.compartment_data(J).double_nonconserved_model_fit_cytoplasm_frames = cytoplasm_time_interp;
                                            app.compartment_data(J).double_nonconserved_model_fit_nucleus_frames = nucleus_time_interp;
                                            app.compartment_data(J).double_nonconserved_mitochondria_MSE = MSE_mitochondria;
                                            app.compartment_data(J).double_nonconserved_cytoplasm_MSE = MSE_cytoplasm;
                                            app.compartment_data(J).double_nonconserved_nucleus_MSE = MSE_nucleus;
                                        end
                                    else
                                        app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0.7 0 0]];
                                        if I ==1
                                            app.compartment_data(J).single_nonconserved_model_fit_time = app.time;
                                            app.compartment_data(J).single_nonconserved_model_fit_mitochondria = nan(app.total_frames,1);
                                            app.compartment_data(J).single_nonconserved_model_fit_cytoplasm = nan(app.total_frames,1);
                                            app.compartment_data(J).single_nonconserved_model_fit_nucleus = nan(app.total_frames,1);
                                            app.compartment_data(J).single_nonconserved_model_fit_mitochondria_frames = nan(app.total_frames,1);
                                            app.compartment_data(J).single_nonconserved_model_fit_cytoplasm_frames = nan(app.total_frames,1);
                                            app.compartment_data(J).single_nonconserved_model_fit_nucleus_frames = nan(app.total_frames,1);
                                            app.compartment_data(J).single_nonconserved_mitochondria_MSE = NaN;
                                            app.compartment_data(J).single_nonconserved_cytoplasm_MSE = NaN;
                                            app.compartment_data(J).single_nonconserved_nucleus_MSE = NaN;
                                        else
                                            app.compartment_data(J).double_nonconserved_model_fit_time = app.time;
                                            app.compartment_data(J).double_nonconserved_model_fit_mitochondria = nan(app.total_frames,1);
                                            app.compartment_data(J).double_nonconserved_model_fit_cytoplasm = nan(app.total_frames,1);
                                            app.compartment_data(J).double_nonconserved_model_fit_nucleus = nan(app.total_frames,1);
                                            app.compartment_data(J).double_nonconserved_model_fit_mitochondria_frames = nan(app.total_frames,1);
                                            app.compartment_data(J).double_nonconserved_model_fit_cytoplasm_frames = nan(app.total_frames,1);
                                            app.compartment_data(J).double_nonconserved_model_fit_nucleus_frames = nan(app.total_frames,1);
                                            app.compartment_data(J).double_nonconserved_mitochondria_MSE = NaN;
                                            app.compartment_data(J).double_nonconserved_cytoplasm_MSE = NaN;
                                            app.compartment_data(J).double_nonconserved_nucleus_MSE = NaN;
                                        end                           
                                    end
                                end
                            if I==1
                                input_data = {true app.window_splits(J) 'single' 'no' app.k_rel_illum_guess(model_fit_counter) app.k_rel_ss_guess(model_fit_counter) app.k_rec_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess...
                                app.compartment_data(J).single_nonconserved_k_rel_illum app.compartment_data(J).single_nonconserved_k_rel_ss app.compartment_data(J).single_nonconserved_k_rec...
                                app.compartment_data(J).single_nonconserved_k_imp app.compartment_data(J).single_nonconserved_k_exp app.compartment_data(J).single_nonconserved_mitochondria_ss...
                                app.compartment_data(J).single_nonconserved_cytoplasm_ss app.compartment_data(J).single_nonconserved_nucleus_ss app.compartment_data(J).single_nonconserved_whole_cell_MSE...
                                app.compartment_data(J).single_nonconserved_nucleus_MSE app.compartment_data(J).single_nonconserved_cytoplasm_MSE app.compartment_data(J).single_nonconserved_mitochondria_MSE};
                                if isnan(app.compartment_data(J).single_nonconserved_whole_cell_MSE)==0
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                else
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0.7 0 0]];
                                end
                            else
                                input_data = {true app.window_splits(J) 'double' 'no' app.k_rel_illum_guess(model_fit_counter) app.k_rel_ss_guess(model_fit_counter) app.k_rec_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess...
                                app.compartment_data(J).double_nonconserved_k_rel_illum app.compartment_data(J).double_nonconserved_k_rel_ss app.compartment_data(J).double_nonconserved_k_rec...
                                app.compartment_data(J).double_nonconserved_k_imp app.compartment_data(J).double_nonconserved_k_exp app.compartment_data(J).double_nonconserved_mitochondria_ss...
                                app.compartment_data(J).double_nonconserved_cytoplasm_ss app.compartment_data(J).double_nonconserved_nucleus_ss app.compartment_data(J).double_nonconserved_whole_cell_MSE...
                                app.compartment_data(J).double_nonconserved_nucleus_MSE app.compartment_data(J).double_nonconserved_cytoplasm_MSE app.compartment_data(J).double_nonconserved_mitochondria_MSE};
                                if isnan(app.compartment_data(J).double_nonconserved_whole_cell_MSE)==0
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                else
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0.7 0 0]];
                                end
                            end                        
        
                            app.UITable2.Data = [app.model_fitting_table_input ; input_data];
                            app.model_fitting_table_input  = app.UITable2.Data;
                            app.UITable2.BackgroundColor=app.model_fitting_table_background_colour;%[1 1 1 ;0.85 0.85 1];
                            d.Value = model_fit_counter/number_of_model_fits;
                            end
                        end
                    end
                    app.model_fitting_flag = 1;
                    close(d)
                    close(f) 
                    app = model_fitting_plotting(app);                    
                end

            end
        

        end

        % Selection changed function: InflowoutflowButtonGroup
        function InflowoutflowButtonGroupSelectionChanged(app, event)
          
            stage_error_counter = stage_error(app,app.app_stage,14,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.model_fitting_flag == 1
                    app = model_fitting_plotting(app);
                else
                    lsm_type_error = ['Error. Carry out model fitting' newline 'in Model fitting tab' newline 'prior to visualisation.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.ConservedButton.Value = true;
                end
                
            else
                app.ConservedButton.Value;
            end
        end

        % Value changed function: 
        % IgnoreinflowoutflowandproceedtomodelfittingButton
        function IgnoreinflowoutflowandproceedtomodelfittingButtonValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nonconserved_inflow_outflow_flag = 0;
                app.app_stage = 14;
                app.TabGroup.SelectedTab = app.ModelfittingTab;
            end            
        end

        % Selection changed function: NormalisationButtonGroup_4
        function NormalisationButtonGroup_4SelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,14,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.model_fitting_flag == 1
                    app = model_fitting_plotting(app);
                else
                    lsm_type_error = ['Error. Carry out model fitting' newline 'in Model fitting tab' newline 'prior to visualisation.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.SinglelinearButton_3.Value = true;
                end
                
            else
                app.SinglelinearButton_3.Value = true;
            end
        end

        % Value changed function: NumberofwindowsSlider_5
        function NumberofwindowsSlider_5ValueChanged(app, event)
            %value = app.NumberofwindowsSlider_5.Value;
            stage_error_counter = stage_error(app,app.app_stage,14,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.model_fitting_flag == 1
                    app = model_fitting_plotting(app);
                else
                    lsm_type_error = ['Error. Carry out model fitting' newline 'in Model fitting tab' newline 'prior to visualisation.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    event.Source.Value = event.Source.MajorTicks(1);
                end
                
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData
            if isnumeric(newData) == 0 || isnan(newData) == 1
                app.UITable2.Data = app.model_fitting_table_input;
                lsm_type_error = ['Warning! Value must be numeric. ' newline 'Reverted to previous value.'];
                app.error_counter = app.error_counter +1;
                app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.ErrorMessagesLabel.BackgroundColor = 'y';

            else
                app = error_message_reset(app);
                app.model_fitting_table_input=app.UITable2.Data;
            end
        end

        % Button pushed function: SaveandquitButton_3
        function SaveandquitButton_3Pushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,14,app.cropping_stage);
            if stage_error_counter == 0
%         
%                 f = uifigure;
%                 d = uiprogressdlg(f,'Title','Saving and closing. Goodbye.','Indeterminate','on');
% 
%                 name = 'opto_analyser_output.mat';
%                 %filter = {'*.mat';'*.*'};
%                 [fnm,pth] = uiputfile(name);
%                 if ischar(fnm)
%                     save_function(app);
%                 end
%                 close(d);
%                 close(f);
                
                
                Data=app.model_fitting_table_input;
                Data=get(app.UITable2,'Data');
                ColumnName=get(app.UITable2,'ColumnName');
                %NewData=mat2cell(Data,ones(size(Data,1),1),ones(size(Data,2),1));
                CombData=[ColumnName';Data]
                %xlswrite(FileName,CombData);
                %writecell(C,filename,'Sheet','Temperatures','Range','B2');
                
                %reset_function(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.opto_analyserUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {567, 567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x', 414};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.opto_analyserUIFigure = uifigure('Visible', 'off');
            app.opto_analyserUIFigure.Name = 'opto_analyser';
            app.opto_analyserUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.opto_analyserUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageIOLabel = uilabel(app.LeftPanel);
            app.ImageIOLabel.Text = 'Image I/O';
            app.NoofintervalsLabel = uilabel(app.LeftPanel);
            app.NoofintervalsLabel.Text = 'No. of intervals';
            app.ConfirmSelectionLabel = uilabel(app.LeftPanel);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.ErrorMessagesLabel = uilabel(app.LeftPanel);
            app.ErrorMessagesLabel.Text = 'Error Messages';
            app.TextArea = uitextarea(app.LeftPanel);
            app.IntervalsselectedLabel = uilabel(app.LeftPanel);
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.LoadReleaseDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadReleaseDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReleaseDataButtonPushed, true);
            app.LoadReleaseDataButton.Text = 'Load Release Data';
            app.LoadRecoveryDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadRecoveryDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRecoveryDataButtonPushed, true);
            app.LoadRecoveryDataButton.Text = 'Load Recovery Data';
            app.OKButton = uibutton(app.LeftPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.ChannelselectionTab = uitab(app.TabGroup);
            app.ChannelselectionTab.Title = 'Channel selection';
            app.Channel1Label = uilabel(app.ChannelselectionTab);
            app.Channel1Label.Text = '  Channel 1';
            app.FrameSliderLabel = uilabel(app.ChannelselectionTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ChannelselectionTab);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 1;
            app.Channel1ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NoneButton_5 = uiradiobutton(app.Channel1ButtonGroup);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.MitochondriaButton = uiradiobutton(app.Channel1ButtonGroup);
            app.MitochondriaButton.Text = 'Mitochondria';
            app.CytoplasmButton = uiradiobutton(app.Channel1ButtonGroup);
            app.CytoplasmButton.Text = 'Cytoplasm';
            app.NucleusButton = uiradiobutton(app.Channel1ButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.Channel2ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.NoneButton_6 = uiradiobutton(app.Channel2ButtonGroup);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.MitochondriaButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.MitochondriaButton_2.Text = 'Mitochondria';
            app.CytoplasmButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.CytoplasmButton_2.Text = 'Cytoplasm';
            app.NucleusButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.NucleusButton_2.Text = 'Nucleus';
            app.Channel3ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.NoneButton_7 = uiradiobutton(app.Channel3ButtonGroup);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.MitochondriaButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.MitochondriaButton_3.Text = 'Mitochondria';
            app.CytoplasmButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.CytoplasmButton_3.Text = 'Cytoplasm';
            app.NucleusButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.NucleusButton_3.Text = 'Nucleus';
            app.Channel4ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.NoneButton_8 = uiradiobutton(app.Channel4ButtonGroup);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.MitochondriaButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.MitochondriaButton_4.Text = 'Mitochondria';
            app.CytoplasmButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.CytoplasmButton_4.Text = 'Cytoplasm';
            app.NucleusButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.NucleusButton_4.Text = 'Nucleus';
            app.UIAxes = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Channel2Label = uilabel(app.ChannelselectionTab);
            app.Channel2Label.Text = '  Channel 2';
            app.Channel3Label = uilabel(app.ChannelselectionTab);
            app.Channel3Label.Text = '  Channel 3';
            app.Channel4Label = uilabel(app.ChannelselectionTab);
            app.Channel4Label.Text = '  Channel 4';
            app.AcceptChannelsandCropImageButton = uibutton(app.ChannelselectionTab, 'push');
            app.AcceptChannelsandCropImageButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptChannelsandCropImageButtonPushed, true);
            app.AcceptChannelsandCropImageButton.Text = 'Accept Channels and Crop Image';
            app.AcceptCropButton = uibutton(app.ChannelselectionTab, 'push');
            app.AcceptCropButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptCropButtonPushed, true);
            app.AcceptCropButton.Text = 'Accept Crop';
            app.CompartmentviewingpercentilesTab = uitab(app.TabGroup);
            app.CompartmentviewingpercentilesTab.Title = 'Compartment viewing percentiles';
            app.UIAxes7 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes7_2 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_2, '')
            xlabel(app.UIAxes7_2, '')
            ylabel(app.UIAxes7_2, '')
            app.UIAxes7_2.XTick = [];
            app.UIAxes7_2.YTick = [];
            app.PercentageSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSliderLabel.Text = 'Percentage';
            app.PercentageSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider.ValueChangingFcn = createCallbackFcn(app, @PercentageSliderValueChanging, true);
            app.PercentageSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2Label.Text = 'Percentage';
            app.PercentageSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2.ValueChangingFcn = createCallbackFcn(app, @PercentageSlider_2ValueChanging, true);
            app.NucleusLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.NucleusLabel.Text = 'Nucleus';
            app.MitochondriaLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.MitochondriaLabel.Text = 'Mitochondria';
            app.FramewindowSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSliderLabel.Text = 'Frame window';
            app.FramewindowSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider.Limits = [1 10];
            app.FramewindowSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider.ValueChangedFcn = createCallbackFcn(app, @FramewindowSliderValueChanged, true);
            app.FramewindowSlider.MinorTicks = [];
            app.FramewindowSlider.Value = 1;
            app.FramewindowSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2Label.Text = 'Frame window';
            app.FramewindowSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2.Limits = [1 10];
            app.FramewindowSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider_2.ValueChangedFcn = createCallbackFcn(app, @FramewindowSlider_2ValueChanged, true);
            app.FramewindowSlider_2.MinorTicks = [];
            app.FramewindowSlider_2.Value = 1;
            app.UIAxes7_3 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_3, '')
            xlabel(app.UIAxes7_3, '')
            ylabel(app.UIAxes7_3, '')
            app.UIAxes7_3.XTick = [];
            app.UIAxes7_3.YTick = [];
            app.PercentageSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_3Label.Text = 'Percentage';
            app.PercentageSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_3.ValueChangingFcn = createCallbackFcn(app, @PercentageSlider_3ValueChanging, true);
            app.FramewindowSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_3Label.Text = 'Frame window';
            app.FramewindowSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_3.Limits = [1 10];
            app.FramewindowSlider_3.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider_3.ValueChangedFcn = createCallbackFcn(app, @FramewindowSlider_3ValueChanged, true);
            app.FramewindowSlider_3.MinorTicks = [];
            app.FramewindowSlider_3.Value = 1;
            app.AllcompartmentsLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.AllcompartmentsLabel.Text = 'All compartments';
            app.LoadNextIterationButton = uibutton(app.CompartmentviewingpercentilesTab, 'push');
            app.LoadNextIterationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNextIterationButtonPushed, true);
            app.LoadNextIterationButton.Text = 'Load Next Iteration';
            app.AcceptPercentilesButton = uibutton(app.CompartmentviewingpercentilesTab, 'push');
            app.AcceptPercentilesButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptPercentilesButtonPushed, true);
            app.AcceptPercentilesButton.Text = 'Accept Percentiles';
            app.CellboundarycropTab = uitab(app.TabGroup);
            app.CellboundarycropTab.Title = 'Cell boundary crop';
            app.UIAxes5 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes6_2 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_2, '')
            xlabel(app.UIAxes6_2, '')
            ylabel(app.UIAxes6_2, '')
            app.UIAxes6_2.XTick = [];
            app.UIAxes6_2.YTick = [];
            app.UIAxes6_3 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_3, '')
            xlabel(app.UIAxes6_3, '')
            ylabel(app.UIAxes6_3, '')
            app.UIAxes6_3.XTick = [];
            app.UIAxes6_3.YTick = [];
            app.ImageSelectionButtonGroup = uibuttongroup(app.CellboundarycropTab);
            app.ImageSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageSelectionButtonGroupSelectionChanged, true);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.NullButton_2 = uitogglebutton(app.ImageSelectionButtonGroup);
            app.NullButton_2.Text = 'Null';
            app.NullButton_2.Value = true;
            app.Image1Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image1Button.Text = 'Image 1';
            app.Image2Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image2Button.Text = 'Image 2';
            app.Image3Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image3Button.Text = 'Image 3';
            app.Image4Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image4Button.Text = 'Image 4';
            app.UIAxes6_4 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_4, '')
            xlabel(app.UIAxes6_4, '')
            ylabel(app.UIAxes6_4, '')
            app.UIAxes6_4.XTick = [];
            app.UIAxes6_4.YTick = [];
            app.AllframesLabel = uilabel(app.CellboundarycropTab);
            app.AllframesLabel.Text = 'All frames';
            app.RegionframesLabel = uilabel(app.CellboundarycropTab);
            app.RegionframesLabel.Text = 'Region frames';
            app.MaxintensityallchannelsLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityallchannelsLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel_2.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityproteinchannelLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel.Text = {'Max intensity '; '- protein channel'};
            app.MaxintensityproteinchannelLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel_2.Text = {'Max intensity '; '- protein channel'};
            app.RoughCropButton = uibutton(app.CellboundarycropTab, 'push');
            app.RoughCropButton.ButtonPushedFcn = createCallbackFcn(app, @RoughCropButtonPushed, true);
            app.RoughCropButton.Text = 'Rough Crop';
            app.ConfirmRoughCropButton = uibutton(app.CellboundarycropTab, 'push');
            app.ConfirmRoughCropButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmRoughCropButtonPushed, true);
            app.ConfirmRoughCropButton.Text = 'Confirm Rough Crop';
            app.CellthresholdingTab = uitab(app.TabGroup);
            app.CellthresholdingTab.Title = 'Cell thresholding';
            app.UIAxes8 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8, '')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8_2 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_2, '')
            xlabel(app.UIAxes8_2, '')
            ylabel(app.UIAxes8_2, '')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.UIAxes8_3 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_3, '')
            xlabel(app.UIAxes8_3, '')
            ylabel(app.UIAxes8_3, '')
            app.UIAxes8_3.XTick = [];
            app.UIAxes8_3.YTick = [];
            app.Thresholdscale01SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale01SliderLabel.Text = 'Threshold scale 0.1';
            app.Thresholdscale01Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale01Slider.Limits = [0 0.9];
            app.Thresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Thresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale01SliderValueChanged, true);
            app.Thresholdscale01Slider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.7 0.8];
            app.Thresholdscale001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale001SliderLabel.Text = 'Threshold scale 0.01';
            app.Thresholdscale001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale001Slider.Limits = [0 0.09];
            app.Thresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Thresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale001SliderValueChanged, true);
            app.Thresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Thresholdscale0001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale0001SliderLabel.Text = 'Threshold scale 0.001';
            app.Thresholdscale0001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale0001Slider.Limits = [0 0.009];
            app.Thresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Thresholdscale0001Slider.MajorTickLabels = {'0', '0.003', '0.006', '0.009', ''};
            app.Thresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale0001SliderValueChanged, true);
            app.Thresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Thresholdscale00001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale00001SliderLabel.Text = 'Threshold scale 0.0001';
            app.Thresholdscale00001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale00001Slider.Limits = [0 0.0009];
            app.Thresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Thresholdscale00001Slider.MajorTickLabels = {'0', '0.0003', '0.0006', '0.0009', ''};
            app.Thresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale00001SliderValueChanged, true);
            app.Thresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Thresholdvalue000000Label = uilabel(app.CellthresholdingTab);
            app.Thresholdvalue000000Label.Text = {'Threshold value:'; '0.00000'};
            app.AcceptthresholdButton = uibutton(app.CellthresholdingTab, 'push');
            app.AcceptthresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptthresholdButtonPushed, true);
            app.AcceptthresholdButton.Text = 'Accept threshold';
            app.CompartmentthresholdingTab = uitab(app.TabGroup);
            app.CompartmentthresholdingTab.Title = 'Compartment thresholding';
            app.UIAxes9 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes9, '')
            xlabel(app.UIAxes9, '')
            ylabel(app.UIAxes9, '')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes10 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes10_2 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_2, '')
            xlabel(app.UIAxes10_2, '')
            ylabel(app.UIAxes10_2, '')
            app.UIAxes10_2.XTick = [];
            app.UIAxes10_2.YTick = [];
            app.UIAxes10_3 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_3, '')
            xlabel(app.UIAxes10_3, '')
            ylabel(app.UIAxes10_3, '')
            app.UIAxes10_3.XTick = [];
            app.UIAxes10_3.YTick = [];
            app.UIAxes10_4 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_4, '')
            xlabel(app.UIAxes10_4, '')
            ylabel(app.UIAxes10_4, '')
            app.UIAxes10_4.XTick = [];
            app.UIAxes10_4.YTick = [];
            app.UIAxes10_5 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_5, '')
            xlabel(app.UIAxes10_5, '')
            ylabel(app.UIAxes10_5, '')
            app.UIAxes10_5.XTick = [];
            app.UIAxes10_5.YTick = [];
            app.UIAxes10_6 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_6, '')
            xlabel(app.UIAxes10_6, '')
            ylabel(app.UIAxes10_6, '')
            app.UIAxes10_6.XTick = [];
            app.UIAxes10_6.YTick = [];
            app.Mitochondriathresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.1'};
            app.Mitochondriathresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01Slider.Limits = [0 0.9];
            app.Mitochondriathresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Mitochondriathresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale01SliderValueChanged, true);
            app.Mitochondriathresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Mitochondriathresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001SliderLabel.Text = {'Mitochondria '; 'threshold scale 0.01'};
            app.Mitochondriathresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001Slider.Limits = [0 0.09];
            app.Mitochondriathresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Mitochondriathresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale001SliderValueChanged, true);
            app.Mitochondriathresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Mitochondriathresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.001'};
            app.Mitochondriathresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001Slider.Limits = [0 0.009];
            app.Mitochondriathresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Mitochondriathresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale0001SliderValueChanged, true);
            app.Mitochondriathresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Mitochondriathresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.0001'};
            app.Mitochondriathresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001Slider.Limits = [0 0.0009];
            app.Mitochondriathresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Mitochondriathresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale00001SliderValueChanged, true);
            app.Mitochondriathresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Nucleusthresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01SliderLabel.Text = {'Nucleus'; 'threshold scale 0.1'};
            app.Nucleusthresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01Slider.Limits = [0 0.9];
            app.Nucleusthresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Nucleusthresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale01SliderValueChanged, true);
            app.Nucleusthresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Nucleusthresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.01'};
            app.Nucleusthresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001Slider.Limits = [0 0.09];
            app.Nucleusthresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Nucleusthresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale001SliderValueChanged, true);
            app.Nucleusthresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Nucleusthresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.001'};
            app.Nucleusthresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001Slider.Limits = [0 0.009];
            app.Nucleusthresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Nucleusthresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale0001SliderValueChanged, true);
            app.Nucleusthresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Nucleusthresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001SliderLabel.Text = {'Nucleus '; 'threshold scale 0.0001'};
            app.Nucleusthresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001Slider.Limits = [0 0.0009];
            app.Nucleusthresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Nucleusthresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale00001SliderValueChanged, true);
            app.Nucleusthresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Mitochondrialthresholdvalue000000Label = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondrialthresholdvalue000000Label.Text = {'Mitochondrial threshold value: 0.00000'; 'Nucleus threshold value: 0.00000'};
            app.AcceptthresholdsButton = uibutton(app.CompartmentthresholdingTab, 'push');
            app.AcceptthresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptthresholdsButtonPushed, true);
            app.AcceptthresholdsButton.Text = 'Accept thresholds';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.InterpolatedOutputLabel = uilabel(app.InterpolationTab);
            app.InterpolatedOutputLabel.Text = 'Interpolated Output';
            app.UIAxes11_5 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_5, 'Mitochondria')
            xlabel(app.UIAxes11_5, 'Frame number')
            ylabel(app.UIAxes11_5, 'Intensity')
            app.UIAxes11_5.XLim = [0 100];
            app.UIAxes11_5.YLim = [0 2];
            app.UIAxes11_5.XTick = [0 50 100];
            app.UIAxes11_6 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_6, 'Cytoplasm')
            xlabel(app.UIAxes11_6, 'Frame number')
            ylabel(app.UIAxes11_6, 'Intensity')
            app.UIAxes11_6.XLim = [0 100];
            app.UIAxes11_6.YLim = [0 2];
            app.UIAxes11_6.XTick = [0 50 100];
            app.UIAxes11_7 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_7, 'Nucleus')
            xlabel(app.UIAxes11_7, 'Frame number')
            ylabel(app.UIAxes11_7, 'Intensity')
            app.UIAxes11_7.XLim = [0 100];
            app.UIAxes11_7.YLim = [0 2];
            app.UIAxes11_7.XTick = [0 50 100];
            app.UIAxes11_8 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_8, 'Total')
            xlabel(app.UIAxes11_8, 'Frame number')
            ylabel(app.UIAxes11_8, 'Intensity')
            app.UIAxes11_8.XLim = [0 100];
            app.UIAxes11_8.YLim = [0 2];
            app.UIAxes11_8.XTick = [0 50 100];
            app.SavedataandquitButton = uibutton(app.InterpolationTab, 'push');
            app.SavedataandquitButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataandquitButtonPushed, true);
            app.SavedataandquitButton.Text = 'Save data and quit';
            app.ProceedtonormalisationButton = uibutton(app.InterpolationTab, 'push');
            app.ProceedtonormalisationButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtonormalisationButtonPushed, true);
            app.ProceedtonormalisationButton.Text = 'Proceed to normalisation';
            app.BackgroundsubtractionTab = uitab(app.TabGroup);
            app.BackgroundsubtractionTab.Title = 'Background subtraction';
            app.UIAxes12 = uiaxes(app.BackgroundsubtractionTab);
            title(app.UIAxes12, '')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.ProteinchannelLabel = uilabel(app.BackgroundsubtractionTab);
            app.ProteinchannelLabel.Text = 'Protein channel';
            app.FramenumberSliderLabel = uilabel(app.BackgroundsubtractionTab);
            app.FramenumberSliderLabel.Text = 'Frame number';
            app.FramenumberSlider = uislider(app.BackgroundsubtractionTab);
            app.FramenumberSlider.ValueChangingFcn = createCallbackFcn(app, @FramenumberSliderValueChanging, true);
            app.SelectBackgroundRegionButton = uibutton(app.BackgroundsubtractionTab, 'push');
            app.SelectBackgroundRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBackgroundRegionButtonPushed, true);
            app.SelectBackgroundRegionButton.Text = 'Select Background Region';
            app.AcceptBackgroundRegionButton = uibutton(app.BackgroundsubtractionTab, 'push');
            app.AcceptBackgroundRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptBackgroundRegionButtonPushed, true);
            app.AcceptBackgroundRegionButton.Text = 'Accept Background Region';
            app.NormalisationregionsTab = uitab(app.TabGroup);
            app.NormalisationregionsTab.Title = 'Normalisation regions';
            app.UIAxes13 = uiaxes(app.NormalisationregionsTab);
            title(app.UIAxes13, 'Mitochondria')
            xlabel(app.UIAxes13, 'Frame number')
            ylabel(app.UIAxes13, 'Intensity')
            app.UIAxes13.XLim = [0 100];
            app.UIAxes13.YLim = [0 2];
            app.UIAxes13_2 = uiaxes(app.NormalisationregionsTab);
            title(app.UIAxes13_2, 'Cytoplasm')
            xlabel(app.UIAxes13_2, 'Frame number')
            ylabel(app.UIAxes13_2, 'Intensity')
            app.UIAxes13_2.XLim = [0 100];
            app.UIAxes13_2.YLim = [0 2];
            app.ReleasestartLabel = uilabel(app.NormalisationregionsTab);
            app.ReleasestartLabel.Text = {'Release '; 'start'};
            app.ReleasestartSlider = uislider(app.NormalisationregionsTab);
            app.ReleasestartSlider.Limits = [1 100];
            app.ReleasestartSlider.ValueChangedFcn = createCallbackFcn(app, @ReleasestartSliderValueChanged, true);
            app.ReleasestartSlider.Value = 1;
            app.ReleaseendLabel = uilabel(app.NormalisationregionsTab);
            app.ReleaseendLabel.Text = {'Release '; 'end'};
            app.ReleaseendSlider = uislider(app.NormalisationregionsTab);
            app.ReleaseendSlider.Limits = [1 100];
            app.ReleaseendSlider.ValueChangedFcn = createCallbackFcn(app, @ReleaseendSliderValueChanged, true);
            app.ReleaseendSlider.Value = 100;
            app.RecoverystartLabel = uilabel(app.NormalisationregionsTab);
            app.RecoverystartLabel.Text = {'Recovery '; 'start'};
            app.RecoverystartSlider = uislider(app.NormalisationregionsTab);
            app.RecoverystartSlider.Limits = [101 200];
            app.RecoverystartSlider.ValueChangedFcn = createCallbackFcn(app, @RecoverystartSliderValueChanged, true);
            app.RecoverystartSlider.Value = 101;
            app.RecoveryendLabel = uilabel(app.NormalisationregionsTab);
            app.RecoveryendLabel.Text = {'Recovery '; 'end'};
            app.RecoveryendSlider = uislider(app.NormalisationregionsTab);
            app.RecoveryendSlider.Limits = [101 200];
            app.RecoveryendSlider.ValueChangedFcn = createCallbackFcn(app, @RecoveryendSliderValueChanged, true);
            app.RecoveryendSlider.Value = 101;
            app.AcceptNormalisationRegionsButton = uibutton(app.NormalisationregionsTab, 'push');
            app.AcceptNormalisationRegionsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptNormalisationRegionsButtonPushed, true);
            app.AcceptNormalisationRegionsButton.Text = 'Accept Normalisation Regions';
            app.NumberofwindowsLabel = uilabel(app.NormalisationregionsTab);
            app.NumberofwindowsLabel.Text = 'Number of windows';
            app.NumberofwindowsSlider = uislider(app.NormalisationregionsTab);
            app.NumberofwindowsSlider.Limits = [1 10];
            app.NumberofwindowsSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSliderValueChanged, true);
            app.NumberofwindowsSlider.MinorTicks = [];
            app.NumberofwindowsSlider.Value = 1;
            app.UIAxes13_3 = uiaxes(app.NormalisationregionsTab);
            title(app.UIAxes13_3, 'Total')
            xlabel(app.UIAxes13_3, 'Frame number')
            ylabel(app.UIAxes13_3, 'Intensity')
            app.UIAxes13_3.XLim = [0 100];
            app.UIAxes13_3.YLim = [0 2];
            app.TimeandSSinputTab = uitab(app.TabGroup);
            app.TimeandSSinputTab.Title = 'Time and SS input';
            app.UIAxes14 = uiaxes(app.TimeandSSinputTab);
            title(app.UIAxes14, 'Mitochondria')
            xlabel(app.UIAxes14, 'Frame number')
            ylabel(app.UIAxes14, 'Intensity')
            app.UIAxes14.XLim = [0 100];
            app.UIAxes14.YLim = [0 2];
            app.SteadystateendframeSpinnerLabel = uilabel(app.TimeandSSinputTab);
            app.SteadystateendframeSpinnerLabel.Text = 'Steady-state end frame';
            app.SteadystateendframeSpinner = uispinner(app.TimeandSSinputTab);
            app.SteadystateendframeSpinner.Limits = [1 Inf];
            app.SteadystateendframeSpinner.ValueChangedFcn = createCallbackFcn(app, @SteadystateendframeSpinnerValueChanged, true);
            app.SteadystateendframeSpinner.Value = 1;
            app.TimeinformationButtonGroup = uibuttongroup(app.TimeandSSinputTab);
            app.TimeinformationButtonGroup.Title = 'Time information';
            app.UseframesastimeButton = uitogglebutton(app.TimeinformationButtonGroup);
            app.UseframesastimeButton.Text = 'Use frames as time';
            app.UseframesastimeButton.Value = true;
            app.LoadtimedataButton = uitogglebutton(app.TimeinformationButtonGroup);
            app.LoadtimedataButton.Text = 'Load time data';
            app.ConfirmButton = uibutton(app.TimeandSSinputTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.UIAxes14_2 = uiaxes(app.TimeandSSinputTab);
            title(app.UIAxes14_2, 'Cytoplasm')
            xlabel(app.UIAxes14_2, 'Frame number')
            ylabel(app.UIAxes14_2, 'Intensity')
            app.UIAxes14_2.XLim = [0 100];
            app.UIAxes14_2.YLim = [0 2];
            app.NumberofwindowsSlider_2Label = uilabel(app.TimeandSSinputTab);
            app.NumberofwindowsSlider_2Label.Text = 'Number of windows';
            app.NumberofwindowsSlider_2 = uislider(app.TimeandSSinputTab);
            app.NumberofwindowsSlider_2.Limits = [1 10];
            app.NumberofwindowsSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider_2.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSlider_2ValueChanged, true);
            app.NumberofwindowsSlider_2.MinorTicks = [];
            app.NumberofwindowsSlider_2.Value = 1;
            app.NormalisationplottingTab = uitab(app.TabGroup);
            app.NormalisationplottingTab.Title = 'Normalisation plotting';
            app.UIAxes15 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15, 'Mitochondria')
            xlabel(app.UIAxes15, 'Time')
            ylabel(app.UIAxes15, 'Intensity')
            app.UIAxes15.XLim = [0 100];
            app.UIAxes15.YLim = [0 2];
            app.UIAxes15_2 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15_2, 'Cytoplasm')
            xlabel(app.UIAxes15_2, 'Time')
            ylabel(app.UIAxes15_2, 'Intensity')
            app.UIAxes15_2.XLim = [0 100];
            app.UIAxes15_2.YLim = [0 2];
            app.UIAxes15_3 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15_3, 'Nucleus')
            xlabel(app.UIAxes15_3, 'Time')
            ylabel(app.UIAxes15_3, 'Intensity')
            app.UIAxes15_3.XLim = [0 100];
            app.UIAxes15_3.YLim = [0 2];
            app.UIAxes15_4 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15_4, 'Total')
            xlabel(app.UIAxes15_4, 'Time')
            ylabel(app.UIAxes15_4, 'Intensity')
            app.UIAxes15_4.XLim = [0 100];
            app.UIAxes15_4.YLim = [0 2];
            app.NormalisationButtonGroup_3 = uibuttongroup(app.NormalisationplottingTab);
            app.NormalisationButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @NormalisationButtonGroup_3SelectionChanged, true);
            app.NormalisationButtonGroup_3.Title = 'Normalisation';
            app.SinglelinearButton = uiradiobutton(app.NormalisationButtonGroup_3);
            app.SinglelinearButton.Text = 'Single linear';
            app.SinglelinearButton.Value = true;
            app.DoublelinearButton = uiradiobutton(app.NormalisationButtonGroup_3);
            app.DoublelinearButton.Text = 'Double linear';
            app.NumberofwindowsSlider_3Label = uilabel(app.NormalisationplottingTab);
            app.NumberofwindowsSlider_3Label.Text = {'Number of '; 'windows'};
            app.NumberofwindowsSlider_3 = uislider(app.NormalisationplottingTab);
            app.NumberofwindowsSlider_3.Limits = [1 10];
            app.NumberofwindowsSlider_3.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider_3.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSlider_3ValueChanged, true);
            app.NumberofwindowsSlider_3.MinorTicks = [];
            app.NumberofwindowsSlider_3.Value = 1;
            app.AdjustforinflowoutflowButton = uibutton(app.NormalisationplottingTab, 'push');
            app.AdjustforinflowoutflowButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustforinflowoutflowButtonPushed, true);
            app.AdjustforinflowoutflowButton.Text = {'Adjust for '; 'inflow/outflow'};
            app.SkipinflowoutflowProceedtomodelfitButton = uibutton(app.NormalisationplottingTab, 'push');
            app.SkipinflowoutflowProceedtomodelfitButton.ButtonPushedFcn = createCallbackFcn(app, @SkipinflowoutflowProceedtomodelfitButtonPushed, true);
            app.SkipinflowoutflowProceedtomodelfitButton.Text = {'Skip inflow/outflow'; 'Proceed to model fit'};
            app.ButtonGroup = uibuttongroup(app.NormalisationplottingTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PrenormalisationButton = uiradiobutton(app.ButtonGroup);
            app.PrenormalisationButton.Text = 'Pre-normalisation';
            app.PrenormalisationButton.Value = true;
            app.PostnormalisationButton = uiradiobutton(app.ButtonGroup);
            app.PostnormalisationButton.Text = 'Post-normalisation';
            app.SaveandquitButton_2 = uibutton(app.NormalisationplottingTab, 'push');
            app.SaveandquitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveandquitButton_2Pushed, true);
            app.SaveandquitButton_2.Text = 'Save and quit';
            app.InflowoutflowTab = uitab(app.TabGroup);
            app.InflowoutflowTab.Title = 'Inflow-outflow';
            app.NormalisationButtonGroup_2 = uibuttongroup(app.InflowoutflowTab);
            app.NormalisationButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @NormalisationButtonGroup_2SelectionChanged, true);
            app.NormalisationButtonGroup_2.Title = 'Normalisation';
            app.SinglelinearButton_2 = uiradiobutton(app.NormalisationButtonGroup_2);
            app.SinglelinearButton_2.Text = 'Single linear';
            app.SinglelinearButton_2.Value = true;
            app.DoublelinearButton_2 = uiradiobutton(app.NormalisationButtonGroup_2);
            app.DoublelinearButton_2.Text = 'Double linear';
            app.NumberofwindowsSlider_4Label = uilabel(app.InflowoutflowTab);
            app.NumberofwindowsSlider_4Label.Text = {'Number'; 'of windows'};
            app.NumberofwindowsSlider_4 = uislider(app.InflowoutflowTab);
            app.NumberofwindowsSlider_4.Limits = [1 10];
            app.NumberofwindowsSlider_4.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider_4.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSlider_4ValueChanged, true);
            app.NumberofwindowsSlider_4.MinorTicks = [];
            app.NumberofwindowsSlider_4.Value = 1;
            app.UIAxes16 = uiaxes(app.InflowoutflowTab);
            title(app.UIAxes16, 'Total')
            xlabel(app.UIAxes16, 'Time')
            ylabel(app.UIAxes16, 'Intensity')
            app.UIAxes16.XLim = [0 100];
            app.UIAxes16.YLim = [0 2];
            app.ReleaseendLabel_2 = uilabel(app.InflowoutflowTab);
            app.ReleaseendLabel_2.Text = {'Release'; ' end'};
            app.ReleaseendSpinner = uispinner(app.InflowoutflowTab);
            app.ReleaseendSpinner.Limits = [1 Inf];
            app.ReleaseendSpinner.ValueChangedFcn = createCallbackFcn(app, @ReleaseendSpinnerValueChanged, true);
            app.ReleaseendSpinner.Value = 1;
            app.RecoveryendSpinner_3Label = uilabel(app.InflowoutflowTab);
            app.RecoveryendSpinner_3Label.Text = {'Recovery'; 'end'};
            app.RecoveryendSpinner_3 = uispinner(app.InflowoutflowTab);
            app.RecoveryendSpinner_3.Limits = [1 Inf];
            app.RecoveryendSpinner_3.ValueChangedFcn = createCallbackFcn(app, @RecoveryendSpinner_3ValueChanged, true);
            app.RecoveryendSpinner_3.Value = 1;
            app.SaveandquitButton = uibutton(app.InflowoutflowTab, 'push');
            app.SaveandquitButton.Text = 'Save and quit';
            app.IncludeinflowoutflowandproceedtomodelfittingButton = uibutton(app.InflowoutflowTab, 'push');
            app.IncludeinflowoutflowandproceedtomodelfittingButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeinflowoutflowandproceedtomodelfittingButtonPushed, true);
            app.IncludeinflowoutflowandproceedtomodelfittingButton.Text = {'Include inflow/outflow '; 'and proceed '; 'to model fitting'};
            app.ReleasesmoothingrangeLabel = uilabel(app.InflowoutflowTab);
            app.ReleasesmoothingrangeLabel.Text = {'Release'; 'smoothing'; 'range'};
            app.ReleasesmoothingrangeSpinner = uispinner(app.InflowoutflowTab);
            app.ReleasesmoothingrangeSpinner.Limits = [1 Inf];
            app.ReleasesmoothingrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @ReleasesmoothingrangeSpinnerValueChanged, true);
            app.ReleasesmoothingrangeSpinner.Value = 1;
            app.RecoverysmoothingrangeLabel = uilabel(app.InflowoutflowTab);
            app.RecoverysmoothingrangeLabel.Text = {'Recovery'; 'smoothing'; 'range'};
            app.RecoverysmoothingrangeSpinner = uispinner(app.InflowoutflowTab);
            app.RecoverysmoothingrangeSpinner.Limits = [1 Inf];
            app.RecoverysmoothingrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @RecoverysmoothingrangeSpinnerValueChanged, true);
            app.RecoverysmoothingrangeSpinner.Value = 1;
            app.IgnoreinflowoutflowandproceedtomodelfittingButton = uibutton(app.InflowoutflowTab, 'state');
            app.IgnoreinflowoutflowandproceedtomodelfittingButton.ValueChangedFcn = createCallbackFcn(app, @IgnoreinflowoutflowandproceedtomodelfittingButtonValueChanged, true);
            app.IgnoreinflowoutflowandproceedtomodelfittingButton.Text = {'Ignore inflow/outflow '; 'and proceed '; 'to model fitting'};
            app.ReleasepaddingLabel = uilabel(app.InflowoutflowTab);
            app.ReleasepaddingLabel.Text = {'Release'; 'padding'};
            app.ReleasepaddingSpinner = uispinner(app.InflowoutflowTab);
            app.ReleasepaddingSpinner.Limits = [0 Inf];
            app.ReleasepaddingSpinner.ValueChangedFcn = createCallbackFcn(app, @ReleasepaddingSpinnerValueChanged, true);
            app.RecoverypaddingSpinnerLabel = uilabel(app.InflowoutflowTab);
            app.RecoverypaddingSpinnerLabel.Text = {'Recovery'; 'padding'};
            app.RecoverypaddingSpinner = uispinner(app.InflowoutflowTab);
            app.RecoverypaddingSpinner.Limits = [0 Inf];
            app.RecoverypaddingSpinner.ValueChangedFcn = createCallbackFcn(app, @RecoverypaddingSpinnerValueChanged, true);
            app.PaddedmedianrangeSpinnerLabel = uilabel(app.InflowoutflowTab);
            app.PaddedmedianrangeSpinnerLabel.Text = 'Padded median range';
            app.PaddedmedianrangeSpinner = uispinner(app.InflowoutflowTab);
            app.PaddedmedianrangeSpinner.Limits = [1 Inf];
            app.PaddedmedianrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @PaddedmedianrangeSpinnerValueChanged, true);
            app.PaddedmedianrangeSpinner.Value = 5;
            app.ModelfittingTab = uitab(app.TabGroup);
            app.ModelfittingTab.Title = 'Model fitting';
            app.IlluminatedreleaseEditFieldLabel = uilabel(app.ModelfittingTab);
            app.IlluminatedreleaseEditFieldLabel.Text = 'Illuminated release';
            app.IlluminatedreleaseEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.IlluminatedreleaseEditField.Limits = [0 Inf];
            app.IlluminatedreleaseEditField.Value = 0.1;
            app.NonilluminatedreleaseEditFieldLabel = uilabel(app.ModelfittingTab);
            app.NonilluminatedreleaseEditFieldLabel.Text = 'Non-illuminated release';
            app.NonilluminatedreleaseEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.NonilluminatedreleaseEditField.Limits = [0 Inf];
            app.NonilluminatedreleaseEditField.Value = 0.05;
            app.RecoveryEditFieldLabel = uilabel(app.ModelfittingTab);
            app.RecoveryEditFieldLabel.Text = 'Recovery';
            app.RecoveryEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.RecoveryEditField.Limits = [0 Inf];
            app.RecoveryEditField.Value = 0.05;
            app.ImportEditFieldLabel = uilabel(app.ModelfittingTab);
            app.ImportEditFieldLabel.Text = 'Import';
            app.ImportEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.ImportEditField.Limits = [0 Inf];
            app.ImportEditField.Value = 0.005;
            app.ExportEditFieldLabel = uilabel(app.ModelfittingTab);
            app.ExportEditFieldLabel.Text = 'Export';
            app.ExportEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.ExportEditField.Limits = [0 Inf];
            app.ExportEditField.Value = 0.005;
            app.UITable2 = uitable(app.ModelfittingTab);
            app.UITable2.ColumnName = {'Re-fit'; 'No. windows'; 'Normalisation'; 'Assume intensity conserved'; 'GUESS: Illuminated release rate'; 'GUESS: Non-illuminated release rate'; 'GUESS: Recovery Rate'; 'GUESS: Import rate'; 'GUESS: Export rate'; 'GUESS: Mitochondria SS'; 'FITTED: Illuminated release rate'; 'FITTED: Non-illuminated release rate'; 'FITTED: Recovery Rate'; 'FITTED: Import rate'; 'FITTED: Export rate'; 'FITTED: Mitochondria SS'; 'FITTED: Cytoplasm SS'; 'FITTED: Nucleus SS'; 'Whole cell MSE'; 'Nucleus MSE'; 'Cytoplasm MSE'; 'Mitochondria MSE'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [true false false false true true true true true true false false false false false false false false false false false false];
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.FitmodelButton = uibutton(app.ModelfittingTab, 'push');
            app.FitmodelButton.ButtonPushedFcn = createCallbackFcn(app, @FitmodelButtonPushed, true);
            app.FitmodelButton.Text = 'Fit model';
            app.SaveandquitButton_3 = uibutton(app.ModelfittingTab, 'push');
            app.SaveandquitButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveandquitButton_3Pushed, true);
            app.SaveandquitButton_3.Text = 'Save and quit';
            app.ParameterguesstypeButtonGroup = uibuttongroup(app.ModelfittingTab);
            app.ParameterguesstypeButtonGroup.Title = 'Parameter guess type';
            app.GlobalguessesButton = uitogglebutton(app.ParameterguesstypeButtonGroup);
            app.GlobalguessesButton.Text = 'Global guesses';
            app.GlobalguessesButton.Value = true;
            app.TableguessesButton = uitogglebutton(app.ParameterguesstypeButtonGroup);
            app.TableguessesButton.Text = 'Table guesses';
            app.ModelfittingoutputTab = uitab(app.TabGroup);
            app.ModelfittingoutputTab.Title = 'Model fitting output';
            app.UIAxes17 = uiaxes(app.ModelfittingoutputTab);
            title(app.UIAxes17, 'Mitochondria')
            xlabel(app.UIAxes17, 'Time')
            ylabel(app.UIAxes17, 'Intensity')
            app.UIAxes17.XLim = [0 100];
            app.UIAxes17.YLim = [0 2];
            app.UIAxes17_2 = uiaxes(app.ModelfittingoutputTab);
            title(app.UIAxes17_2, 'Cytoplasm')
            xlabel(app.UIAxes17_2, 'Time')
            ylabel(app.UIAxes17_2, 'Intensity')
            app.UIAxes17_2.XLim = [0 100];
            app.UIAxes17_2.YLim = [0 2];
            app.UIAxes17_3 = uiaxes(app.ModelfittingoutputTab);
            title(app.UIAxes17_3, 'Nucleus')
            xlabel(app.UIAxes17_3, 'Time')
            ylabel(app.UIAxes17_3, 'Intensity')
            app.UIAxes17_3.XLim = [0 100];
            app.UIAxes17_3.YLim = [0 2];
            app.NormalisationButtonGroup_4 = uibuttongroup(app.ModelfittingoutputTab);
            app.NormalisationButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @NormalisationButtonGroup_4SelectionChanged, true);
            app.NormalisationButtonGroup_4.Title = 'Normalisation';
            app.SinglelinearButton_3 = uiradiobutton(app.NormalisationButtonGroup_4);
            app.SinglelinearButton_3.Text = 'Single linear';
            app.SinglelinearButton_3.Value = true;
            app.DoublelinearButton_3 = uiradiobutton(app.NormalisationButtonGroup_4);
            app.DoublelinearButton_3.Text = 'Double linear';
            app.InflowoutflowButtonGroup = uibuttongroup(app.ModelfittingoutputTab);
            app.InflowoutflowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InflowoutflowButtonGroupSelectionChanged, true);
            app.InflowoutflowButtonGroup.Title = 'Inflow-outflow';
            app.ConservedButton = uiradiobutton(app.InflowoutflowButtonGroup);
            app.ConservedButton.Text = 'Conserved';
            app.ConservedButton.Value = true;
            app.NonconservedButton = uiradiobutton(app.InflowoutflowButtonGroup);
            app.NonconservedButton.Text = 'Non-conserved';
            app.NumberofwindowsLabel_2 = uilabel(app.ModelfittingoutputTab);
            app.NumberofwindowsLabel_2.Text = 'Number of windows';
            app.NumberofwindowsSlider_5 = uislider(app.ModelfittingoutputTab);
            app.NumberofwindowsSlider_5.Limits = [1 10];
            app.NumberofwindowsSlider_5.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider_5.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSlider_5ValueChanged, true);
            app.NumberofwindowsSlider_5.MinorTicks = [];
            app.NumberofwindowsSlider_5.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.OutputLabel = uilabel(app.RightPanel);
            app.OutputLabel.Text = 'Output';
            app.UIAxes11 = uiaxes(app.RightPanel);
            title(app.UIAxes11, 'Mitochondria')
            xlabel(app.UIAxes11, 'Frame number')
            ylabel(app.UIAxes11, 'Intensity')
            app.UIAxes11.XLim = [0 100];
            app.UIAxes11.YLim = [0 2];
            app.UIAxes11.XTick = [0 50 100];
            app.UIAxes11_2 = uiaxes(app.RightPanel);
            title(app.UIAxes11_2, 'Cytoplasm')

            xlabel(app.UIAxes11_2, 'Frame number')
            ylabel(app.UIAxes11_2, 'Intensity')
            app.UIAxes11_2.XLim = [0 100];
            app.UIAxes11_2.YLim = [0 2];
            app.UIAxes11_2.XTick = [0 50 100];
            app.UIAxes11_3 = uiaxes(app.RightPanel);
            title(app.UIAxes11_3, 'Nucleus')
            xlabel(app.UIAxes11_3, 'Frame number')
            ylabel(app.UIAxes11_3, 'Intensity')
            app.UIAxes11_3.XLim = [0 100];
            app.UIAxes11_3.YLim = [0 2];
            app.UIAxes11_3.XTick = [0 50 100];
            app.UIAxes11_4 = uiaxes(app.RightPanel);
            title(app.UIAxes11_4, 'Total')
            xlabel(app.UIAxes11_4, 'Frame number')
            ylabel(app.UIAxes11_4, 'Intensity')
            app.UIAxes11_4.XLim = [0 100];
            app.UIAxes11_4.YLim = [0 2];
            app.UIAxes11_4.XTick = [0 50 100];
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'No. of intervals'; 'Interval'; 'Cell percentile'; 'Mitochondria percentile'; 'Nucleus Percentile'; 'Cell threshold'; 'Mitochondria threshold'; 'Nucleus threshold'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.opto_analyserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_analyser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.opto_analyserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.opto_analyserUIFigure)
        end
    end
end